classdef seeVieweR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SpawnViewerButton              matlab.ui.control.Button
        EXPORTPanel                    matlab.ui.container.Panel
        ExportNIfTIButton              matlab.ui.control.Button
        SaveStateButton                matlab.ui.control.Button
        LoadStateButton                matlab.ui.control.Button
        ExportMovieButton              matlab.ui.control.Button
        ExportFrameButton              matlab.ui.control.Button
        GenerateFigureButton           matlab.ui.control.Button
        OverlayDropDown                matlab.ui.control.DropDown
        OverlayDropDownLabel           matlab.ui.control.Label
        AddOverlayButton               matlab.ui.control.Button
        APPEARANCEPanel                matlab.ui.container.Panel
        LightingSwitch                 matlab.ui.control.Switch
        LightingSwitchLabel            matlab.ui.control.Label
        DenoisingSwitch                matlab.ui.control.Switch
        DenoisingSwitchLabel           matlab.ui.control.Label
        ApplyOverlayMaskSwitch         matlab.ui.control.Switch
        ApplyOverlayMaskSwitchLabel    matlab.ui.control.Label
        ApplyBaseMaskSwitch            matlab.ui.control.Switch
        FlipMapSwitch_2Label_2         matlab.ui.control.Label
        VolumeSlider                   matlab.ui.control.Slider
        VolumeSliderLabel              matlab.ui.control.Label
        ApplySliceMaskDropDown         matlab.ui.control.DropDown
        ApplySliceMaskDropDownLabel    matlab.ui.control.Label
        ORI3Slider                     matlab.ui.control.RangeSlider
        ORI3SliderLabel                matlab.ui.control.Label
        ORI2Slider                     matlab.ui.control.RangeSlider
        ORI2SliderLabel                matlab.ui.control.Label
        ORI1Slider                     matlab.ui.control.RangeSlider
        ORI1SliderLabel                matlab.ui.control.Label
        ProtectionThresholdSpinner     matlab.ui.control.Spinner
        ProtectionThresholdSpinnerLabel  matlab.ui.control.Label
        ErosionStrengthSpinner         matlab.ui.control.Spinner
        ErosionStrengthSpinnerLabel    matlab.ui.control.Label
        TerritoryDropDown              matlab.ui.control.DropDown
        TerritoryDropDownLabel         matlab.ui.control.Label
        BaseCOLORMAPALPHAOverlayPanel  matlab.ui.container.Panel
        ColormapDropDownOverlay        matlab.ui.control.DropDown
        OverlayMapLabel                matlab.ui.control.Label
        OverlayContrastSpinner         matlab.ui.control.Spinner
        OverlayContrastSpinnerLabel    matlab.ui.control.Label
        BaseContrastSpinner            matlab.ui.control.Spinner
        BaseContrastLabel              matlab.ui.control.Label
        OverlayAlphaSpinner            matlab.ui.control.Spinner
        OverlayAlphaSpinnerLabel       matlab.ui.control.Label
        BaseAlphaSpinner               matlab.ui.control.Spinner
        BaseAlphaSpinnerLabel          matlab.ui.control.Label
        FlattenOverlaySwitch           matlab.ui.control.Switch
        FlattenOverlaySwitchLabel      matlab.ui.control.Label
        FlattenBaseSwitch              matlab.ui.control.Switch
        FlattenBaseSwitchLabel         matlab.ui.control.Label
        FlipMapSwitchOverlay           matlab.ui.control.Switch
        FlipMapSwitch_2Label           matlab.ui.control.Label
        FlipMapSwitch                  matlab.ui.control.Switch
        FlipMapSwitchLabel             matlab.ui.control.Label
        ColormapDropDown               matlab.ui.control.DropDown
        BaseMapLabel                   matlab.ui.control.Label
        BaseRENDERINGOverlayPanel      matlab.ui.container.Panel
        InterpolationDropDown          matlab.ui.control.DropDown
        RenderingDropDown_2Label_2     matlab.ui.control.Label
        FlipORI1Switch                 matlab.ui.control.Switch
        FlipDimSwitchLabel             matlab.ui.control.Label
        FlipORI2Switch                 matlab.ui.control.Switch
        FlipORI3Switch                 matlab.ui.control.Switch
        QualityDropDown                matlab.ui.control.DropDown
        QualityDropDownLabel           matlab.ui.control.Label
        RenderingDropDownOverlay       matlab.ui.control.DropDown
        RenderingDropDown_2Label       matlab.ui.control.Label
        RenderingDropDown              matlab.ui.control.DropDown
        RenderingDropDownLabel         matlab.ui.control.Label
        BackgroundDropDown             matlab.ui.control.DropDown
        BackgroundDropDownLabel        matlab.ui.control.Label
        LoadOverlayMaskButton          matlab.ui.control.Button
        LoadBaseMaskButton             matlab.ui.control.Button
        ResetSlidersButton             matlab.ui.control.Button
        VolumeVal                      matlab.ui.control.NumericEditField
        OverlayHighVal                 matlab.ui.control.NumericEditField
        OverlayLowVal                  matlab.ui.control.NumericEditField
        BaseLowVal                     matlab.ui.control.NumericEditField
        BaseHighVal                    matlab.ui.control.NumericEditField
        PctHighOverlay                 matlab.ui.control.Label
        PctLowBase                     matlab.ui.control.Label
        PctLowOverlay                  matlab.ui.control.Label
        PctHighEditField_Overlay       matlab.ui.control.NumericEditField
        PctHighEditField_Base          matlab.ui.control.NumericEditField
        PctHighBase                    matlab.ui.control.Label
        PctLowEditField_Overlay        matlab.ui.control.NumericEditField
        PctLowEditField_Base           matlab.ui.control.NumericEditField
        TrimOverlayCheckBox            matlab.ui.control.CheckBox
        TrimBaseCheckBox               matlab.ui.control.CheckBox
        Overlay_high                   matlab.ui.control.NumericEditField
        Base_high                      matlab.ui.control.NumericEditField
        Overlay_low                    matlab.ui.control.NumericEditField
        Base_low                       matlab.ui.control.NumericEditField
        ORI3_high                      matlab.ui.control.NumericEditField
        ORI2_high                      matlab.ui.control.NumericEditField
        ORI1_high                      matlab.ui.control.NumericEditField
        ORI3_low                       matlab.ui.control.NumericEditField
        ORI2_low                       matlab.ui.control.NumericEditField
        ORI1_low                       matlab.ui.control.NumericEditField
        OverlayThresholdLabel          matlab.ui.control.Label
        OverlaySlider                  matlab.ui.control.RangeSlider
        BaseSlider                     matlab.ui.control.RangeSlider
        BaseThresholdLabel             matlab.ui.control.Label
        ThresholdSliderOverlay         matlab.ui.control.RangeSlider
        ColorbarPanelOverlay           matlab.ui.container.Panel
        LoadOverlayButton              matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        ErodeBaseImageCheckBox         matlab.ui.control.CheckBox
        ColorbarPanel                  matlab.ui.container.Panel
        VisualizationPanel             matlab.ui.container.Panel
        ThresholdSlider                matlab.ui.control.RangeSlider
        LoadBaseButton                 matlab.ui.control.Button
    end

    % Copyright (C) Alex A. Bhogal, 2025, University Medical Center Utrecht,
    % a.bhogal@umcutrecht.nl
    %
    % This program is free software: you can redistribute it and/or modify
    % it under the terms of the GNU General Public License as published by
    % the Free Software Foundation, either version 3 of the License, or
    % (at your option) any later version.
    %
    % This program is distributed in the hope that it will be useful,
    % but WITHOUT ANY WARRANTY; without even the implied warranty of
    % MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    % GNU General Public License for more details.
    %
    % You should have received a copy of the GNU General Public License
    % along with this program.  If not, see <https://www.gnu.org/licenses/>.

    properties (Access = private)
        dataImage  = []   % Stores the loaded volume data
        sourceDataImage = [] % keep the original
        voxelSize  = []   % Stores voxel size information
        viewer     = []   % Stores viewer3d object
        volHandle  = []   % Stores volshow object handle
        path       = []   % Path to data
        atlasImage = []   % full atlas
        masks      = []   % Terriory masks
        WBmask     = []   % Base mask
        OverlayMask = []  % Overlay mask
        vesselSkeleton = []   %Vessel Skeleton
        existMasks = 0    % bolean
        overlayImage = [] % Overlay Image
        sourceOverlayImage = [] % keep the original
        overlay = 0;      % bolean
        hfig       = []   % Viewer Object
        tform      = []   % Transform matrix
        niftiInfoBase = []% Info for base image
        basePath   = []   % Path for base image
        overlayPath = [] % Path for overlays
        baseHistCounts = []  % 1×N   counts  (base volume)
        baseHistEdges = []   % 1×N+1 bin-edges
        overlayHistCounts= []
        overlayHistEdges= []
        currentBaseFrame   = 1    % which t-volume is displayed
        nBaseFrames        = 1   % 1 for 3-D, >1 for 4-D
        overlayInfo = []
        BaseIsMask = 0;
        OverlayIsMask = 0;
        version = []        % is Matlab version before of after 2024a where significant change was made to overlay
        colmapOverlay = []
        colmap = []
        alphaMap = []
        hBaseCB_Axes  = []
        hBaseCB_Image = []
        hOvCB_Axes    = []
        hOvCB_Image   = []
        hBaseHistAxes = []
        hOvHistAxes   = []
        BaseVisibilityMask    = []
        OverlayVisibilityMask = []
        overlayBank = repmat(struct( ...
            'name','', 'path','', 'data',[], 'source',[], 'info',[], ...
            'isMask',false, 'rescaled',false, 'histCounts',[], 'histEdges',[], ...
            'sliderUserData',[0 1], 'sliderValue',[0 1], 'thrLimits',[0 1], 'thrValue',[0 1], ...
            'uiState',struct('alpha',1,'contrast',1,'cmap','','flip','off','flatten','off','trimOn',false,'pctLow',0,'pctHigh',100), ...
            'interpMethod','nearest','rawData',[], 'rawInfo',[] ...
            ), 0,1)
        currentOverlayName = ''
        spawnedViewerFigs = []             % array of spawned windows
        spawnedViewers = []                    % array of viewer3d handles
        spawnedVolHandles = {}                         % corresponding volshow handles
        cameraSyncTimer = []                       % timer for camera syncing
        LightingDialog = []
        DenoiseDialog = []
    end

    methods (Access = private)

        function updateVisualization(app)
            if ~app.overlay
                app.OverlayVisibilityMask = [];
            end

            %% --- Fixed global intensity range
            baseRange = app.BaseSlider.UserData;
            fixedMin = baseRange(1) + app.BaseSlider.Value(1) * (baseRange(2) - baseRange(1));
            fixedMax = baseRange(1) + app.BaseSlider.Value(2) * (baseRange(2) - baseRange(1));

            %% --- Base image normalization
            imageData = app.dataImage;
            normImage = (imageData - fixedMin) / (fixedMax - fixedMin);
            normImage = max(min(normImage, 1), 0);

            %% --- Threshold mask (controls visibility)
            thrRange = app.ThresholdSlider.Value;
            thrMin = thrRange(1);
            thrMax = thrRange(2);
            thresholdMask = (imageData >= thrMin) & (imageData <= thrMax);

            %% --- Region mask
            if app.existMasks
                switch app.TerritoryDropDown.Value
                    case 'ALL',          regionMask = true(size(imageData));
                    case 'Anterior_L',   regionMask = app.masks.Anterior_L;
                    case 'Anterior_R',   regionMask = app.masks.Anterior_R;
                    case 'PCA',          regionMask = app.masks.PCA;
                    case 'VB',           regionMask = app.masks.VB;
                    case 'Ventricles',   regionMask = app.masks.Ventricles;
                    otherwise,           regionMask = true(size(imageData));
                end
                if ~isequal(size(regionMask), size(imageData))
                    regionMask = true(size(imageData));
                end
            else
                regionMask = true(size(imageData));
            end

            %% --- Combine masks
            visibilityMask = thresholdMask & regionMask;
            if strcmp(app.ApplyBaseMaskSwitch.Value,"On") && ~isempty(app.WBmask) ...
                    && isequal(size(app.WBmask), size(imageData))
                visibilityMask = visibilityMask & logical(app.WBmask);
            end

            %% --- Optional trimming on original frame
            if app.TrimBaseCheckBox.Value
                origFrame = app.sourceDataImage;
                if ndims(origFrame)==4
                    origFrame = origFrame(:,:,:,max(1,app.currentBaseFrame));
                end
                valid = isfinite(origFrame);
                if any(valid(:))
                    pr = prctile(origFrame(valid), ...
                        [app.PctLowEditField_Base.Value, app.PctHighEditField_Base.Value]);
                    trimMask = (origFrame >= pr(1)) & (origFrame <= pr(2));
                    visibilityMask = visibilityMask & trimMask;
                end
            end

            %% --- Erosion
            if app.ErodeBaseImageCheckBox.Value
                vesselMask = visibilityMask;
                vesselSkel = app.vesselSkeleton;
                if ~isempty(vesselSkel)
                    skelProtection = imdilate(vesselSkel, strel('sphere', app.ProtectionThresholdSpinner.Value));
                else
                    skelProtection = false(size(vesselMask));
                end
                if any(vesselMask(:))
                    distMap = bwdist(~vesselMask);
                    maxD = max(distMap(:));
                    if maxD > 0, distNorm = distMap / maxD; else, distNorm = zeros(size(distMap),'like',distMap); end
                else
                    distNorm = zeros(size(vesselMask),'like',imageData);
                end
                erosionLevel = app.ErosionStrengthSpinner.Value;
                erodeMask = distNorm > erosionLevel;
                visibilityMask = (erodeMask | skelProtection) & vesselMask;
            end

            %% --- Slice mask
            xLim = app.ORI1Slider.Value;   yLim = app.ORI2Slider.Value;   zLim = app.ORI3Slider.Value;
            xSize = app.ORI1Slider.UserData;  ySize = app.ORI2Slider.UserData;  zSize = app.ORI3Slider.UserData;
            xIdx = max(1,min(round([xLim(1) xLim(2)]),xSize));
            yIdx = max(1,min(round([yLim(1) yLim(2)]),ySize));
            zIdx = max(1,min(round([zLim(1) zLim(2)]),zSize));

            sliceMask = false(size(app.dataImage));
            sliceMask(yIdx(1):yIdx(2), xIdx(1):xIdx(2), zIdx(1):zIdx(2)) = true;

            switch app.ApplySliceMaskDropDown.Value
                case 'Base'
                    sliceMaskBase    = sliceMask;
                    sliceMaskOverlay = true(size(sliceMask));
                case 'Overlay'
                    sliceMaskBase    = true(size(sliceMask));
                    sliceMaskOverlay = sliceMask;
                otherwise % 'All'
                    sliceMaskBase    = sliceMask;
                    sliceMaskOverlay = sliceMask;
            end

            visibilityMask = visibilityMask & sliceMaskBase;

            %% --- Final masked base image (defer lock voxels until after flips)
            maskedImage = normImage;
            maskedImage(~visibilityMask) = 0;
            app.BaseVisibilityMask = visibilityMask;

            %% --- Overlay preparation
            hasOverlay = app.overlay && ~isempty(app.overlayImage);
            if hasOverlay
                ovRaw = app.overlayImage; % keep raw for intensity normalization

                % (a) overlay mask
                wbMaskOv = true(size(ovRaw));
                if strcmp(app.ApplyOverlayMaskSwitch.Value,"On")
                    if ~isempty(app.OverlayMask) && isequal(size(app.OverlayMask), size(ovRaw))
                        wbMaskOv = wbMaskOv & (app.OverlayMask > 0);
                    elseif ~isempty(app.WBmask) && isequal(size(app.WBmask), size(ovRaw))
                        wbMaskOv = wbMaskOv & (app.WBmask > 0);
                    end
                end

                % (b) threshold mask
                thrOverlay = app.ThresholdSliderOverlay.Value;
                thrMaskOv  = (ovRaw >= thrOverlay(1)) & (ovRaw <= thrOverlay(2));

                % (c) optional overlay trimming (uses sourceOverlayImage)
                trimMaskOv = true(size(ovRaw));
                if app.TrimOverlayCheckBox.Value
                    ovOrig = app.sourceOverlayImage;
                    if ndims(ovOrig)==4, ovOrig = ovOrig(:,:,:,1); end
                    validOv = isfinite(ovOrig);
                    if any(validOv(:))
                        prOv = prctile(ovOrig(validOv), ...
                            [app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value]);
                        trimMaskOv = (ovOrig >= prOv(1)) & (ovOrig <= prOv(2));
                    end
                end

                % (d) slice mask for overlay
                maskOverlay = wbMaskOv & thrMaskOv & trimMaskOv & sliceMaskOverlay;

                % Normalize overlay with current slider range
                trueMin = app.OverlaySlider.UserData(1);
                trueMax = app.OverlaySlider.UserData(2);
                oMin = trueMin + app.OverlaySlider.Value(1) * (trueMax - trueMin);
                oMax = trueMin + app.OverlaySlider.Value(2) * (trueMax - trueMin);
                normOverlay = (ovRaw - oMin) / (oMax - oMin);
                normOverlay = max(min(normOverlay,1),0);
                normOverlay(~isfinite(normOverlay)) = 0;

                % Apply visibility
                normOverlay(~maskOverlay) = 0;

                % Expose for Quick Plot
                app.OverlayVisibilityMask = maskOverlay;
            end

            %% --- Optional axis flips (apply to data arrays only)
            if strcmp(app.FlipORI1Switch.Value,'on')
                maskedImage = flip(maskedImage, 1);
                if hasOverlay, normOverlay = flip(normOverlay, 1); end
            end
            if strcmp(app.FlipORI2Switch.Value,'on')
                maskedImage = flip(maskedImage, 2);
                if hasOverlay, normOverlay = flip(normOverlay, 2); end
            end
            if strcmp(app.FlipORI3Switch.Value,'on')
                maskedImage = flip(maskedImage, 3);
                if hasOverlay, normOverlay = flip(normOverlay, 3); end
            end

            %% --- Lock LUT endpoints AFTER flips (colorbar stability)
            maskedImage(1,1,1) = 0;  maskedImage(1,1,2) = 1;
            if hasOverlay
                normOverlay(1,1,1) = 0;  normOverlay(1,1,2) = 1;
            end

            %% --- Build CURRENT colormaps and alphamaps (respect contrast/flatten + flip-map)
            % Base colormap
            cmapBase = app.getCustomColormap(app.ColormapDropDown.Value);
            if strcmp(app.FlipMapSwitch.Value,'yes'), cmapBase = flipud(cmapBase); end
            cmapBase = app.warpColormap(cmapBase, app.BaseContrastSpinner.Value);

            % Base alpha
            alphaBase = app.buildAlphaMap( ...
                app.BaseAlphaSpinner.Value, ...
                app.BaseContrastSpinner.Value, ...
                app.FlattenBaseSwitch.Value);

            % Overlay colormap + alpha (if present)
            if hasOverlay
                cmapOv = app.getCustomColormap(app.ColormapDropDownOverlay.Value);
                if strcmp(app.FlipMapSwitchOverlay.Value,'yes'), cmapOv = flipud(cmapOv); end
                cmapOv = app.warpColormap(cmapOv, app.OverlayContrastSpinner.Value);

                alphaOv = app.buildAlphaMap( ...
                    app.OverlayAlphaSpinner.Value, ...
                    app.OverlayContrastSpinner.Value, ...
                    app.FlattenOverlaySwitch.Value);
                % Ensure zeros are transparent
                if ~isempty(alphaOv), alphaOv(1) = 0; end
            end

            %% --- Render base + optional overlay
            if ~app.overlay
                app.volHandle.Data           = maskedImage;
                app.volHandle.Colormap       = cmapBase;
                app.volHandle.Alphamap       = alphaBase;
                app.volHandle.Transformation = app.tform;
                app.volHandle.RenderingStyle = app.RenderingDropDown.Value;
            else
                app.volHandle.Data           = maskedImage;
                app.volHandle.Colormap       = cmapBase;
                app.volHandle.Alphamap       = alphaBase;
                app.volHandle.Transformation = app.tform;
                app.volHandle.RenderingStyle = app.RenderingDropDown.Value;

                app.volHandle.OverlayData            = normOverlay;
                app.volHandle.OverlayColormap        = cmapOv;
                app.volHandle.OverlayRenderingStyle  = app.RenderingDropDownOverlay.Value;
                app.volHandle.OverlayAlphamap        = alphaOv;

                if app.version
                    app.volHandle.OverlayDisplayRangeMode = "data-range";
                end
            end

            % ---------------- BASE histogram ----------------
            function drawStaticHist(ax, counts256)
                counts256 = double(counts256);
                clip      = prctile(counts256, 99);
                counts256 = min(counts256, clip);
                counts256 = sqrt(counts256);
                if max(counts256)==0, counts256(:) = 0;
                else, counts256 = counts256 ./ max(counts256);
                end
                cla(ax,'reset');
                set(ax,'Color','none','XColor','none','YColor','none','YDir','normal');
                hold(ax,'on');
                barW = 0.85;  nRows = numel(counts256);
                for r = 1:nRows
                    if counts256(r) > 0
                        line(ax,[0 counts256(r)*barW],[r r],'Color','w','LineWidth',1,'HitTest','off');
                    end
                end
                hold(ax,'off');
            end

            if isempty(app.hBaseHistAxes) || ~isgraphics(app.hBaseHistAxes) || ...
                    app.hBaseHistAxes.Parent ~= app.ColorbarPanel
                app.hBaseHistAxes = axes('Parent',app.ColorbarPanel, ...
                    'Units','normalized','Position',[0 0 1 1], ...
                    'Color','none','XColor','none','YColor','none', ...
                    'YDir','normal','HitTest','off');
            end
            cla(app.hBaseHistAxes);
            if ~isempty(app.baseHistCounts)
                drawStaticHist(app.hBaseHistAxes, app.baseHistCounts);
            end
            uistack(app.hBaseHistAxes,'top');

            % ---------------- OVERLAY histogram ----------------
            if app.overlay
                if isempty(app.hOvHistAxes) || ~isgraphics(app.hOvHistAxes) || ...
                        app.hOvHistAxes.Parent ~= app.ColorbarPanelOverlay
                    app.hOvHistAxes = axes('Parent',app.ColorbarPanelOverlay, ...
                        'Units','normalized','Position',[0 0 1 1], ...
                        'Color','none','XColor','none','YColor','none', ...
                        'YDir','normal','HitTest','off');
                end
                cla(app.hOvHistAxes);
                if ~isempty(app.overlayHistCounts)
                    drawStaticHist(app.hOvHistAxes, app.overlayHistCounts);
                end
                uistack(app.hOvHistAxes,'top');
            end
        end


        function cmap = getCustomColormap(app, cmapName)
            % low_val = round(0.2 * 256);
            % high_val = 256 - low_val;  % remaining entries

            switch cmapName
                case 'RedYellowlBlue'
                    cmap = brewermap(256, 'RdYlBu');
                case 'RedYellowlGreen'
                    cmap = brewermap(256, 'RdYlGn');
                case 'BlueWhiteRed'
                    cmap = BWR2(256);
                case 'YellowGreenBlue'
                    cmap = brewermap(256, 'YlGnBu');
                case 'RedGray'
                    cmap = brewermap(256, 'RdGy');
                case 'Spectral'
                    cmap = brewermap(256, 'Spectral');
                case 'Jet'
                    cmap = jet(256);
                case 'Turbo'
                    cmap = turbo(256);
                case 'Bright'
                    cmap = bright(256);
                case 'Whed'
                    cmap = whed(256);
                case 'Devon'
                    cmap = crameri('devon', 256);
                case 'grayC'
                    cmap = crameri('grayC', 256);
                case 'Bone'
                    cmap = bone(256);
                case 'Sepia'
                    cmap = sepia(256);
                case 'Copper'
                    cmap = copper(256);
                case 'Viridis'
                    cmap = viridis(256);
                case 'Inferno'
                    cmap = inferno(256);
                case 'Magma'
                    cmap = magma(256);
                case 'Plasma'
                    cmap = plasma(256);
                case 'Thermal'
                    cmap = thermal(256);
                case 'Lajolla'
                    cmap = crameri('lajolla', 256);
                case 'Buda'
                    cmap = crameri('buda', 256);
                case 'Nuuk'
                    cmap = crameri('nuuk', 256);
                case 'Roma'
                    cmap = crameri('roma', 256);
                case 'Hawaii'
                    cmap = crameri('hawaii', 256);
                case 'Bukavu'
                    cmap = crameri('bukavu', 256);
                case 'Imola'
                    cmap = crameri('imola', 256);
                case 'Parula'
                    cmap = parula(256);
            end
        end

        %-------------------------------------------------------
        function s = val2slider(app, v, rng)
            % real-value → slider-fraction   (clamped 0…1)
            s = (v - rng(1)) ./ diff(rng);
            s = max(0, min(1, s));
        end
        %-------------------------------------------------------
        function v = slider2val(app, s, rng)
            % slider-fraction → real-value
            v = rng(1) + s .* diff(rng);
        end

        function [counts256, edges] = computeHistogram(~, volume, nBins)
            % Robust histogram helper – works for 3-D or 4-D volumes.
            %
            %  • Ignores NaN / Inf voxels
            %  • If *all* voxels are non-finite, returns zeros rather than erroring.

            if nargin < 3,  nBins = 256;  end
            valid = isfinite(volume);

            if ~any(valid(:))                         % ------- all NaN / Inf
                counts256 = zeros(nBins,1,'uint32');  %   flat histogram
                edges     = linspace(0,1,nBins+1);    %   dummy edges
                return
            end

            lo = min(volume(valid),[],'all');         % scalar min
            hi = max(volume(valid),[],'all');         % scalar max
            if lo == hi                               % constant frame → widen a bit
                lo = lo - 1;  hi = hi + 1;
            end

            edges     = linspace(lo, hi, nBins+1);
            counts256 = histcounts(volume(valid),edges).';   % nBins×1 column
        end

        function UIFigureCloseRequest(app,~,~)
            % ← place any clean-up you want here
            delete(app);              % closes the figure and disposes the app
        end

        % -------------------------------------------------------------------------
        %  Build high-res movie (GIF + MP4) that matches the live seeVieweR panel
        function makeBaseMovieHiRes(app,~)

            % ─────────────── 0) Dark, quick-plot-style dialog ───────────────
            if isprop(app,'basePath') && ~isempty(app.basePath)
                try cd(fileparts(app.basePath)); catch, end
            end
            initDir = pwd;

            dlg = uifigure('Name','Export Movie','Color','k', ...
                'WindowStyle','modal','Resize','off');
            dlg.Position(3:4) = [820 380];

            gl = uigridlayout(dlg,[8 6]);
            gl.BackgroundColor = [0 0 0];
            gl.RowHeight    = {34,34,34,34,34,34,34,44};
            gl.ColumnWidth  = {90,'1x',90,80,90,90};
            gl.ColumnSpacing= 8;  gl.RowSpacing = 8;  gl.Padding = [12 12 12 12];

            white = [1 1 1];  dk = [0 0 0];

            % ---- Save to (path) -------------------------------------------------
            lPath = uilabel(gl,'Text','Save to','FontColor',white);
            lPath.Layout.Row = 1; lPath.Layout.Column = 1;

            edPath = uieditfield(gl,'text','Value',initDir, ...
                'BackgroundColor',dk,'FontColor',white);
            edPath.Layout.Row = 1; edPath.Layout.Column = [2 5];

            btBrowse = uibutton(gl,'Text','Browse...','ButtonPushedFcn',@onBrowse, ...
                'BackgroundColor',[0.85 0.45 0.05], ...   % orange
                'FontColor',white);
            btBrowse.Layout.Row = 1; btBrowse.Layout.Column = 6;

            % ---- Filename -------------------------------------------------------
            lName = uilabel(gl,'Text','Filename','FontColor',white);
            lName.Layout.Row = 2; lName.Layout.Column = 1;

            edName = uieditfield(gl,'text','Value','movie', ...
                'BackgroundColor',dk,'FontColor',white);
            edName.Layout.Row = 2; edName.Layout.Column = [2 6];

            % ---- GIF delay ------------------------------------------------------
            lGIF = uilabel(gl,'Text','GIF delay [s]','FontColor',white);
            lGIF.Layout.Row = 3; lGIF.Layout.Column = 1;

            edGIF = uieditfield(gl,'numeric','Limits',[0 Inf], ...
                'Value',0.10,'ValueDisplayFormat','%.2f', ...
                'BackgroundColor',dk,'FontColor',white);
            edGIF.Layout.Row = 3; edGIF.Layout.Column = [2 3];

            % ---- MP4 fps --------------------------------------------------------
            lFPS = uilabel(gl,'Text','MP4 fps','FontColor',white);
            lFPS.Layout.Row = 4; lFPS.Layout.Column = 1;

            edFPS = uieditfield(gl,'numeric','Limits',[1 240], ...
                'Value',20,'RoundFractionalValues','on', ...
                'BackgroundColor',dk,'FontColor',white);
            edFPS.Layout.Row = 4; edFPS.Layout.Column = [2 3];

            % ---- Rotation toggle ------------------------------------------------
            lRot = uilabel(gl,'Text','Rotation','FontColor',white);
            lRot.Layout.Row = 5; lRot.Layout.Column = 1;

            swRot = uiswitch(gl,'slider','Items',{'off','on'},'Value','on');
            swRot.Layout.Row = 5; swRot.Layout.Column = [2 3];
            swRot.ValueChangedFcn = @toggleRotControls;

            % ---- # frames -------------------------------------------------------
            lN   = uilabel(gl,'Text','# rot frames','FontColor',white);
            lN.Layout.Row = 6; lN.Layout.Column = 1;

            edN  = uieditfield(gl,'numeric','Limits',[1 2000], ...
                'Value',60,'RoundFractionalValues','on', ...
                'BackgroundColor',dk,'FontColor',white);
            edN.Layout.Row = 6; edN.Layout.Column = [2 3];

            % ---- Range (deg) ----------------------------------------------------
            lDeg = uilabel(gl,'Text','Range [deg]','FontColor',white);
            lDeg.Layout.Row = 6; lDeg.Layout.Column = 4;

            edDeg = uieditfield(gl,'numeric','Limits',[0 3600], ...
                'Value',360,'RoundFractionalValues','on', ...
                'BackgroundColor',dk,'FontColor',white);
            edDeg.Layout.Row = 6; edDeg.Layout.Column = [5 6];

            % ---- Buttons --------------------------------------------------------
            btnCancel = uibutton(gl,'Text','Cancel','FontColor',white, ...
                'BackgroundColor',[0.70 0.15 0.15], ...   % red
                'ButtonPushedFcn',@(s,e) close(dlg));
            btnCancel.Layout.Row = 8; btnCancel.Layout.Column = 5;

            btnGen = uibutton(gl,'Text','Generate','FontColor',white, ...
                'BackgroundColor',[0.20 0.60 0.20], ...   % green
                'ButtonPushedFcn',@onGenerate);
            btnGen.Layout.Row = 8; btnGen.Layout.Column = 6;

            toggleRotControls(); % set initial enabled/disabled

            % ─────────────── Callbacks ───────────────
            function onBrowse(~,~)
                startDir = edPath.Value;
                if ~isfolder(startDir), startDir = initDir; end
                p = uigetdir(startDir,'Choose folder for movie files');
                if isequal(p,0), return; end
                edPath.Value = p;
            end

            function toggleRotControls(~,~)
                on = strcmp(swRot.Value,'on');
                edN.Enable   = matlab.lang.OnOffSwitchState(on);
                edDeg.Enable = matlab.lang.OnOffSwitchState(on);
            end

            function onGenerate(~,~)
                saveDir  = edPath.Value;
                if ~isfolder(saveDir)
                    uialert(dlg,'Please choose a valid folder.','Invalid path','Icon','warning');
                    return
                end
                baseName = strtrim(edName.Value);
                if isempty(baseName), baseName = 'movie'; end

                gifDelay  = max(0, edGIF.Value);
                mp4FPS    = max(1, round(edFPS.Value));
                doRot     = strcmp(swRot.Value,'on');
                rotFrames = max(1, round(edN.Value));
                rotDeg    = max(0, edDeg.Value);

                % save current viewer state alongside outputs (as before)
                try
                    stateFile = fullfile(saveDir,'viewer_state_movie.mat');
                    app.saveViewerState(stateFile);
                catch, end

                gifFile = fullfile(saveDir,[baseName '.gif']);
                mp4File = fullfile(saveDir,[baseName '.mp4']);
                if exist(gifFile,'file'), delete(gifFile); end
                if exist(mp4File,'file'), delete(mp4File); end

                close(dlg);   % close UI before heavy work

                % ─────────────── 1) Set up writers ───────────────
                vObj = VideoWriter(mp4File,'MPEG-4');
                vObj.Quality   = 95;
                vObj.FrameRate = mp4FPS;
                open(vObj);

                firstGif   = true;
                pixelScale = 2;

                function writeBoth(rgb)
                    writeVideo(vObj,rgb);
                    [ind,cm] = rgb2ind(rgb,256);
                    if firstGif
                        imwrite(ind,cm,gifFile,'gif','LoopCount',inf,'DelayTime',gifDelay);
                        firstGif = false;
                    else
                        imwrite(ind,cm,gifFile,'gif','WriteMode','append','DelayTime',gifDelay);
                    end
                end

                % ─────────────── 2) Copy viewer props (force high quality) ───────────────
                liveV = app.viewer;
                H     = app.volHandle;  % live handle for maps & transform
                vProp = {'CameraPosition','CameraTarget','CameraUpVector','CameraZoom', ...
                    'BackgroundColor','GradientColor','ScaleBar'};

                % ─────────────── 2b) Build first frame + one reusable viewer ─────────────
                [vol1, ovVol1] = buildFrame(1);  % already flipped & masked

                mFig  = uifigure('Color',liveV.BackgroundColor,'Visible','on');
                mView = viewer3d('Parent',mFig);
                mView.RenderingQuality = "high";
                for p = 1:numel(vProp)
                    if isprop(liveV,vProp{p})
                        mView.(vProp{p}) = liveV.(vProp{p});
                    end
                end

                %Apply Lighting and Denoising
                app.applyAppearanceToViewer(mView);

                if isprop(mView,'Projection') && isprop(liveV,'Projection')
                    mView.Projection = liveV.Projection;
                end

                vArgs = {'Parent',mView, ...
                    'Colormap',H.Colormap, ...
                    'Alphamap',H.Alphamap, ...
                    'Transformation',H.Transformation, ...
                    'RenderingStyle',app.RenderingDropDown.Value};

                if app.overlay
                    if app.version
                        vArgs = [vArgs,{'OverlayData',ovVol1, ...
                            'OverlayColormap',H.OverlayColormap, ...
                            'OverlayRenderingStyle',app.RenderingDropDownOverlay.Value, ...
                            'OverlayAlphamap',H.OverlayAlphamap, ...
                            'OverlayDisplayRangeMode','data-range'}];
                    else
                        vArgs = [vArgs,{'OverlayData',ovVol1, ...
                            'OverlayColormap',H.OverlayColormap, ...
                            'OverlayRenderingStyle',app.RenderingDropDownOverlay.Value, ...
                            'OverlayAlphamap',H.OverlayAlphamap}];
                    end
                end

                mH = volshow(vol1, vArgs{:});  % REUSABLE handle

                % Lock camera manual modes for programmatic control
                safeSetManual(mView,'CameraPositionMode');
                safeSetManual(mView,'CameraTargetMode');
                safeSetManual(mView,'CameraUpVectorMode');
                safeSetManual(mView,'CameraViewAngleMode');
                safeSetManual(mView,'CameraZoomMode');

                hWait = waitbar(0,'Building movie …','WindowStyle','modal');

                % ─────────────── 3) Dynamic volumes (t-series) ───────────────
                nDyn = app.nBaseFrames;   % 1 for 3-D

                for f = 1:nDyn
                    if f > 1
                        [volF, ovVolF] = buildFrame(f);
                        % Update volumes only (no recreation)
                        if isprop(mH,'Volume')
                            mH.Volume = volF;
                        elseif isprop(mH,'VolumeData')
                            mH.VolumeData = volF;
                        else
                            mH.Data = volF;
                        end
                        if app.overlay && isprop(mH,'OverlayData')
                            mH.OverlayData = ovVolF;
                        end
                    end

                    drawnow limitrate
                    rgb = imresize(getframe(mFig).cdata,pixelScale);
                    writeBoth(rgb);
                    waitbar(f/(nDyn + (doRot*rotFrames)), hWait);
                end

                % Last frame volumes for rotation stage
                if nDyn == 1
                    lastVol   = vol1;
                    lastOvVol = ovVol1;
                else
                    [lastVol,lastOvVol] = buildFrame(nDyn);
                end

                % ─────────────── 4) Optional rotation (camera only) ───────────────
                if doRot
                    camT  = liveV.CameraTarget;
                    camUp = liveV.CameraUpVector./max(norm(liveV.CameraUpVector),eps);
                    cam0  = liveV.CameraPosition;

                    theta = linspace(0,deg2rad(rotDeg),rotFrames+1);

                    % Ensure last data is set before rotating
                    if isprop(mH,'Volume')
                        mH.Volume = lastVol;
                    elseif isprop(mH,'VolumeData')
                        mH.VolumeData = lastVol;
                    else
                        mH.Data = lastVol;
                    end
                    if app.overlay && isprop(mH,'OverlayData')
                        mH.OverlayData = lastOvVol;
                    end

                    for k = 2:numel(theta)
                        R      = rotAxisAngle(camUp, theta(k));
                        camPos = camT + (R*(cam0 - camT)')';

                        if isprop(mView,'CameraTarget'),   mView.CameraTarget   = camT;   end
                        if isprop(mView,'CameraPosition'), mView.CameraPosition = camPos; end
                        if isprop(mView,'CameraUpVector'), mView.CameraUpVector = camUp;  end
                        if isprop(mView,'CameraViewAngle') && isprop(liveV,'CameraViewAngle')
                            mView.CameraViewAngle = liveV.CameraViewAngle;
                        end
                        if isprop(mView,'CameraZoom') && isprop(liveV,'CameraZoom')
                            mView.CameraZoom = liveV.CameraZoom;
                        end

                        drawnow limitrate
                        rgb = imresize(getframe(mFig).cdata,pixelScale);
                        writeBoth(rgb);
                        waitbar((nDyn + k - 1) / (nDyn + rotFrames), hWait);
                    end
                end

                % Cleanup
                close(vObj); close(hWait);
                if isvalid(mFig), delete(mFig); end
                uialert(app.UIFigure,'Movie saved (GIF + MP4).','Done');

                % ─────────────── helpers ───────────────
                function [vol,ovVol] = buildFrame(idx)
                    % ----- choose frame & optional trim -----------------------------------
                    if app.nBaseFrames == 1, idx = 1; end
                    frame = app.sourceDataImage(:,:,:,idx);
                    if app.TrimBaseCheckBox.Value
                        frame = clipOutliers(frame, ...
                            app.PctLowEditField_Base.Value, ...
                            app.PctHighEditField_Base.Value);
                    end

                    % ----- normalization for display (fixed slider range) ------------------
                    rng  = app.BaseSlider.UserData;
                    vMin = rng(1)+app.BaseSlider.Value(1)*diff(rng);
                    vMax = rng(1)+app.BaseSlider.Value(2)*diff(rng);
                    vol  = (frame-vMin)/(vMax-vMin);  vol = max(min(vol,1),0);

                    % ----- base visibility: threshold -------------------------------------
                    thr   = app.ThresholdSlider.Value;
                    mBase = frame>=thr(1) & frame<=thr(2);

                    % ----- region/territory (shared setting, as before) -------------------
                    if app.existMasks
                        switch app.TerritoryDropDown.Value
                            case 'ALL',  mReg = true(size(frame));
                            otherwise,   mReg = app.masks.(app.TerritoryDropDown.Value);
                        end
                    else
                        mReg = true(size(frame));
                    end

                    mAll = mBase & mReg;

                    % ----- mask (BASE ONLY) -----------------------------------
                    % Base channel uses its own WB mask toggle; overlay will NOT use this.
                    if strcmp(app.ApplyBaseMaskSwitch.Value,"On") && ~isempty(app.WBmask)
                        mAll = mAll & logical(app.WBmask);
                    end

                    % ----- optional erosion (BASE ONLY; matches live update) ---------------
                    if app.ErodeBaseImageCheckBox.Value
                        vesselMask = mAll;
                        vesselSkel = app.vesselSkeleton;
                        if ~isempty(vesselSkel)
                            skelProtection = imdilate(vesselSkel, strel('sphere', app.ProtectionThresholdSpinner.Value));
                        else
                            skelProtection = false(size(vesselMask));
                        end
                        if any(vesselMask(:))
                            distMap = bwdist(~vesselMask);
                            maxD = max(distMap(:));
                            if maxD > 0
                                distNorm = distMap / maxD;
                            else
                                distNorm = zeros(size(distMap),'like',distMap);
                            end
                        else
                            distNorm = zeros(size(vesselMask),'like',frame);
                        end
                        erosionLevel = app.ErosionStrengthSpinner.Value;
                        erodeMask = distNorm > erosionLevel;
                        mAll = (erodeMask | skelProtection) & vesselMask;
                    end

                    % ----- slice mask (Base/All) ------------------------------------------
                    xL=app.ORI1Slider.Value; yL=app.ORI2Slider.Value; zL=app.ORI3Slider.Value;
                    xS=app.ORI1Slider.UserData; yS=app.ORI2Slider.UserData; zS=app.ORI3Slider.UserData;
                    xI=max(1,min(round([xL(1) xL(2)]),xS));
                    yI=max(1,min(round([yL(1) yL(2)]),yS));
                    zI=max(1,min(round([zL(1) zL(2)]),zS));
                    mSlice = false(size(frame));
                    mSlice(yI(1):yI(2),xI(1):xI(2),zI(1):zI(2)) = true;

                    if any(strcmp(app.ApplySliceMaskDropDown.Value,{'Base','All'}))
                        mAll = mAll & mSlice;
                    end

                    % ----- apply final base visibility mask --------------------------------
                    vol(~mAll)=0;

                    % ====================== OVERLAY (independent) ==========================
                    ovVol = [];
                    if app.overlay
                        % Work from the raw overlay (no pre-multiplication)
                        ovRaw = app.overlayImage;

                        % Normalize for display (using overlay slider range)
                        oRng = app.OverlaySlider.UserData;
                        oMin = oRng(1) + app.OverlaySlider.Value(1) * diff(oRng);
                        oMax = oRng(1) + app.OverlaySlider.Value(2) * diff(oRng);
                        ovVol = (ovRaw - oMin) / (oMax - oMin);
                        ovVol = max(min(ovVol,1),0);   % keep in [0,1]; NaNs retained for now

                        % 1) Threshold on RAW overlay (own thresholds)
                        thrOv  = app.ThresholdSliderOverlay.Value;
                        mOvThr = (ovRaw >= thrOv(1)) & (ovRaw <= thrOv(2));

                        % 2) Overlay-specific explicit mask ONLY
                        if strcmp(app.ApplyOverlayMaskSwitch.Value,"On") && ~isempty(app.OverlayMask)
                            mOvMask = app.OverlayMask > 0;
                        else
                            mOvMask = true(size(ovRaw));
                        end

                        % 3) Region/territory (shared UI, same as base)
                        if app.existMasks
                            switch app.TerritoryDropDown.Value
                                case 'ALL'
                                    mRegOv = true(size(ovRaw));
                                otherwise
                                    mRegOv = app.masks.(app.TerritoryDropDown.Value);
                            end
                        else
                            mRegOv = true(size(ovRaw));
                        end

                        % 4) Slice mask when requested (Overlay or All)
                        if any(strcmp(app.ApplySliceMaskDropDown.Value, {'Overlay','All'}))
                            mSliceOv = mSlice;    % reuse computed slab
                        else
                            mSliceOv = true(size(ovRaw));
                        end

                        % IMPORTANT: Do NOT apply base WB mask to overlay.
                        mAllOv = mOvThr & mOvMask & mRegOv & mSliceOv;

                        % Apply overlay visibility
                        ovVol(~mAllOv) = 0;

                        % Robust export behavior:
                        %  - No NaNs (map to 0 so OverlayAlphamap(1)=0 makes them transparent)
                        %  - Lock overlay scale indices like base (ensures stable bins)
                        ovVol(isnan(ovVol)) = 0;
                        ovVol(1,1,1) = 0;   % lowest bin → transparent
                        ovVol(1,1,2) = 1;   % highest bin → opaque end
                    end

                    % ----- orientation flips (apply BEFORE lock) ---------------------------
                    if strcmp(app.FlipORI1Switch.Value,'on')
                        vol = flip(vol,1); if ~isempty(ovVol), ovVol = flip(ovVol,1); end
                    end
                    if strcmp(app.FlipORI2Switch.Value,'on')
                        vol = flip(vol,2); if ~isempty(ovVol), ovVol = flip(ovVol,2); end
                    end
                    if strcmp(app.FlipORI3Switch.Value,'on')
                        vol = flip(vol,3); if ~isempty(ovVol), ovVol = flip(ovVol,3); end
                    end

                    % ----- lock colour-scale indices AFTER flips ---------------------------
                    vol(1,1,1)=0; vol(1,1,2)=1;
                    % overlay already locked above (if present)
                end


                function safeSetManual(obj,prop)
                    if isprop(obj,prop), obj.(prop) = 'manual'; end
                end

                function R = rotAxisAngle(axis,angleRad)
                    axis = axis(:)./max(norm(axis),eps);
                    x=axis(1); y=axis(2); z=axis(3);
                    K = [0 -z  y;  z 0 -x;  -y x 0];
                    R = eye(3) + sin(angleRad)*K + (1-cos(angleRad))*(K*K);
                end
            end

            figure(app.UIFigure);

        end

        function saveViewerState(app, stateFile)

            state = struct();

            % === Base image controls ===
            state.BaseAlpha       = app.BaseAlphaSpinner.Value;
            state.BaseContrast    = app.BaseContrastSpinner.Value;
            state.BaseColormap    = app.ColormapDropDown.Value;
            state.BaseFlip        = app.FlipMapSwitch.Value;
            state.BaseRange       = app.BaseSlider.Value;
            if ~isempty(app.BaseSlider.UserData)
                state.BaseSliderRange = app.BaseSlider.UserData;   % [trueMin trueMax]
            end
            state.BaseThresh      = app.ThresholdSlider.Value;
            state.FlattenBase     = app.FlattenBaseSwitch.Value;

            % Trim (base)
            state.TrimBaseOn      = app.TrimBaseCheckBox.Value;
            state.TrimBaseLowPct  = app.PctLowEditField_Base.Value;
            state.TrimBaseHighPct = app.PctHighEditField_Base.Value;

            % Erosion (base)
            state.ErodeBase           = app.ErodeBaseImageCheckBox.Value;
            state.ErosionStrength     = app.ErosionStrengthSpinner.Value;
            state.ProtectionThreshold = app.ProtectionThresholdSpinner.Value;

            % === Overlay controls ===
            state.OverlayOn         = logical(app.overlay);
            state.OverlayAlpha      = app.OverlayAlphaSpinner.Value;
            state.OverlayContrast   = app.OverlayContrastSpinner.Value;
            state.OverlayColormap   = app.ColormapDropDownOverlay.Value;
            state.OverlayFlip       = app.FlipMapSwitchOverlay.Value;
            state.OverlayRange      = app.OverlaySlider.Value;
            if ~isempty(app.OverlaySlider.UserData)
                state.OverlaySliderRange = app.OverlaySlider.UserData; % [trueMin trueMax]
            end
            state.OverlayThresh     = app.ThresholdSliderOverlay.Value;
            state.FlattenOverlay    = app.FlattenOverlaySwitch.Value;

            % Trim (overlay)
            state.TrimOverlayOn      = app.TrimOverlayCheckBox.Value;
            state.TrimOverlayLowPct  = app.PctLowEditField_Overlay.Value;
            state.TrimOverlayHighPct = app.PctHighEditField_Overlay.Value;

            % === Mask + region controls ===
            state.ApplyBaseMask    = app.ApplyBaseMaskSwitch.Value;
            state.ApplyOverlayMask = app.ApplyOverlayMaskSwitch.Value;
            state.ApplySliceMask   = app.ApplySliceMaskDropDown.Value;
            state.Region           = app.TerritoryDropDown.Value;

            % ORI slice ranges (geometry)
            state.ORI1 = app.ORI1Slider.Value;
            state.ORI2 = app.ORI2Slider.Value;
            state.ORI3 = app.ORI3Slider.Value;

            % === Rendering and viewer settings ===
            state.RenderingBase     = app.RenderingDropDown.Value;
            state.RenderingOverlay  = app.RenderingDropDownOverlay.Value;
            state.Background        = app.BackgroundDropDown.Value;
            state.Quality           = app.QualityDropDown.Value;
            state.Lighting          = app.LightingSwitch.Value;
            state.Denoising         = app.DenoisingSwitch.Value;

            % Also record the *actual* viewer quality in case it differs from UI
            if ~isempty(app.viewer) && isprop(app.viewer,'RenderingQuality')
                state.RenderingQuality = app.viewer.RenderingQuality;
            end

            % --- Appearance: switch states ---
            state.DenoisingSwitch = app.DenoisingSwitch.Value;  % 'on'/'off'
            state.LightingSwitch  = app.LightingSwitch.Value;   % 'on'/'off'

            % --- Denoising params ---
            if isprop(app.viewer,'DenoisingSigma')
                state.DenoisingSigma = app.viewer.DenoisingSigma;
            end
            if isprop(app.viewer,'DenoisingDegreeOfSmoothing')
                state.DenoisingDegreeOfSmoothing = app.viewer.DenoisingDegreeOfSmoothing;
            end

            % --- Lighting params ---
            if isprop(app.viewer,'AmbientLight')
                state.AmbientLight = app.viewer.AmbientLight;
            end
            if isprop(app.viewer,'DiffuseLight')
                state.DiffuseLight = app.viewer.DiffuseLight;
            end
            if isprop(app.viewer,'LightColor')
                state.LightColor = app.viewer.LightColor;
            end
            if isprop(app.viewer,'LightPositionMode')
                state.LightPositionMode = app.viewer.LightPositionMode;
            end

            % === Camera settings ===
            if ~isempty(app.viewer)
                state.CameraPosition = app.viewer.CameraPosition;
                state.CameraTarget   = app.viewer.CameraTarget;
                state.CameraUpVector = app.viewer.CameraUpVector;
                state.CameraZoom     = app.viewer.CameraZoom;
            else
                state.CameraPosition = [];
                state.CameraTarget   = [];
                state.CameraUpVector = [];
                state.CameraZoom     = [];
            end
            state.FlipORI1 = app.FlipORI1Switch.Value;
            state.FlipORI2 = app.FlipORI2Switch.Value;
            state.FlipORI3 = app.FlipORI3Switch.Value;

            % === Persist current maps (optional, used on load if present) ===
            if ~isempty(app.colmap),        state.colmap        = app.colmap;        end
            if ~isempty(app.alphaMap),      state.alphaMap      = app.alphaMap;      end
            if ~isempty(app.colmapOverlay), state.colmapOverlay = app.colmapOverlay; end

            try
                save(stateFile, 'state');
            catch ME
                warning('Failed to save viewer state to "%s": %s', stateFile, ME.message);
            end
        end

        function loadViewerState(app, varargin)
            % loadViewerState — robustly restore GUI + viewer state from a MAT file.
            % Rules:
            % 1) Recompute slider Limits/UserData from CURRENT data (like your loaders).
            % 2) If saved values are inside those limits → apply; else use the new limits.
            % 3) Only enable Apply*Mask if mask exists and dimensions match.
            % 4) Keep try/catch simple; no ME usage.

            % ── 0) Choose file ────────────────────────────────────────────────────
            if nargin < 2 || isempty(varargin{1})
                [f,p] = uigetfile('*.mat','Load viewer state');
                if isequal(f,0),  return;  end
                stateFile = fullfile(p,f);
            else
                stateFile = varargin{1};
            end

            try
                S = load(stateFile);
            catch
                uialert(app.UIFigure,'Could not load state file.','Load error','Icon','warning');
                return
            end
            if ~isfield(S,'state')
                uialert(app.UIFigure,'Selected file has no variable "state".','Load error','Icon','warning');
                return
            end
            state = S.state;

            % ── 1) BASE: recompute ranges from CURRENT data, then apply saved if valid ─────
            if isempty(app.dataImage)
                disp('Base data not loaded: applying only non-range controls.');
            end

            % BaseSlider.UserData (true data range) + Limits/Value (normalized)
            if ~isempty(app.dataImage)
                baseData = app.dataImage(:);
                baseData = baseData(isfinite(baseData));
                if isempty(baseData), baseData = 0; end
                minBase = min(baseData);
                maxBase = max(baseData);
                if minBase==maxBase, minBase = minBase-1; end
                try
                    app.BaseSlider.UserData = [minBase, maxBase];
                    app.BaseSlider.Limits   = [0 1];
                    if isfield(state,'BaseRange') && isnumeric(state.BaseRange) && numel(state.BaseRange)==2
                        v = double(state.BaseRange(:).');
                        v(1) = max(0, min(1, v(1)));
                        v(2) = max(0, min(1, v(2)));
                        if v(2) < v(1), v = sort(v); end
                        app.BaseSlider.Value = v;
                    else
                        % default like loader (10%–80%)
                        v = [(0.1) (0.8)];
                        app.BaseSlider.Value = v;
                    end
                catch
                    disp('Could not restore BaseSlider (limits/value).');
                end
            end

            % ThresholdSlider (absolute units from current data)
            if ~isempty(app.dataImage)
                validVox = isfinite(app.dataImage);
                trueMin  = double(round(min(app.dataImage(validVox)),2));
                trueMax  = double(round(max(app.dataImage(validVox)),2));
                try
                    if ~(trueMax-trueMin)
                        app.ThresholdSlider.Limits = [0 1];
                        app.ThresholdSlider.Value  = [0 1];
                        app.Base_low.Limits  = [0 1];
                        app.Base_high.Limits = [0 1];
                    else
                        app.ThresholdSlider.Limits = [trueMin trueMax];
                        if isfield(state,'BaseThresh') && isnumeric(state.BaseThresh) && numel(state.BaseThresh)==2
                            v = double(state.BaseThresh(:).');
                            v(1) = max(trueMin, min(trueMax, v(1)));
                            v(2) = max(trueMin, min(trueMax, v(2)));
                            if v(2) < v(1), v = sort(v); end
                            app.ThresholdSlider.Value = v;
                        else
                            % same default as loader
                            v = [trueMin + abs(0.001*trueMin), trueMax - 0.001*trueMax];
                            if v(2) <= v(1), v = [trueMin trueMax]; end
                            app.ThresholdSlider.Value = v;
                        end
                        app.Base_low.Limits  = [trueMin, trueMax-abs(0.001*trueMax)];
                        app.Base_high.Limits = [trueMin+abs(0.001*trueMin), trueMax];
                        app.Base_low.Value   = round(app.ThresholdSlider.Value(1),2);
                        app.Base_high.Value  = round(app.ThresholdSlider.Value(2),2);
                    end
                catch
                    disp('Could not restore Base threshold slider.');
                end
            end

            % Sync Base numeric real-value readouts from BaseSlider
            try
                if ~isempty(app.BaseSlider.UserData)
                    rng  = app.BaseSlider.UserData;
                    sVal = app.BaseSlider.Value;
                    vals = app.slider2val(sVal, rng);
                    app.BaseLowVal.Value  = round(vals(1),2);
                    app.BaseHighVal.Value = round(vals(2),2);
                end
            catch
                disp('Could not sync base numeric readouts.');
            end

            % Base simple controls (no range logic)
            try if isfield(state,'BaseAlpha'),       app.BaseAlphaSpinner.Value    = state.BaseAlpha;    end, catch, disp('BaseAlpha failed'); end
            try if isfield(state,'BaseContrast'),    app.BaseContrastSpinner.Value = state.BaseContrast; end, catch, disp('BaseContrast failed'); end
            try
                if isfield(state,'BaseColormap')
                    items = string(app.ColormapDropDown.Items);
                    if any(items == string(state.BaseColormap)), app.ColormapDropDown.Value = state.BaseColormap; end
                end
            catch, disp('Base colormap failed'); end
            try if isfield(state,'BaseFlip'),        app.FlipMapSwitch.Value = state.BaseFlip; end, catch, disp('BaseFlip failed'); end
            try if isfield(state,'FlattenBase'),     app.FlattenBaseSwitch.Value = state.FlattenBase; end, catch, disp('FlattenBase failed'); end

            % Trim (base)
            try if isfield(state,'TrimBaseOn'),      app.TrimBaseCheckBox.Value        = state.TrimBaseOn;      end, catch, disp('TrimBaseOn failed'); end
            try if isfield(state,'TrimBaseLowPct'),  app.PctLowEditField_Base.Value    = state.TrimBaseLowPct;  end, catch, disp('TrimBaseLowPct failed'); end
            try if isfield(state,'TrimBaseHighPct'), app.PctHighEditField_Base.Value   = state.TrimBaseHighPct; end, catch, disp('TrimBaseHighPct failed'); end

            % Erosion (base)
            try if isfield(state,'ErodeBase'),           app.ErodeBaseImageCheckBox.Value = state.ErodeBase; end, catch, disp('ErodeBase failed'); end
            try if isfield(state,'ErosionStrength'),     app.ErosionStrengthSpinner.Value = state.ErosionStrength; end, catch, disp('ErosionStrength failed'); end
            try if isfield(state,'ProtectionThreshold'), app.ProtectionThresholdSpinner.Value = state.ProtectionThreshold; end, catch, disp('ProtectionThreshold failed'); end

            % ── 2) OVERLAY: recompute ranges from CURRENT overlay, then apply saved if valid ─
            if ~isempty(app.overlayImage)
                try if isfield(state,'OverlayOn'), app.overlay = logical(state.OverlayOn); end, catch, end

                % Overlay slider (normalized) from current overlay image
                overlayData = app.overlayImage(:);
                overlayData = overlayData(isfinite(overlayData));
                if isempty(overlayData), overlayData = 0; end
                oMinTrue = double(round(min(overlayData),2));
                oMaxTrue = double(round(max(overlayData),2));
                try
                    app.OverlaySlider.UserData = [oMinTrue, oMaxTrue];
                    app.OverlaySlider.Limits   = [0 1];
                    if isfield(state,'OverlayRange') && isnumeric(state.OverlayRange) && numel(state.OverlayRange)==2
                        s = double(state.OverlayRange(:).');
                        s(1) = max(0, min(1, s(1)));
                        s(2) = max(0, min(1, s(2)));
                        if s(2) < s(1), s = sort(s); end
                        app.OverlaySlider.Value = s;
                    else
                        % default 10%–80% (or [0 1] if degenerate)
                        if ~(oMaxTrue - oMinTrue)
                            app.OverlaySlider.Value = [0 1];
                        else
                            app.OverlaySlider.Value = [0.01 0.8];
                        end
                    end
                catch
                    disp('Could not restore OverlaySlider (limits/value).');
                end

                % Threshold overlay (absolute)
                try
                    if ~(oMaxTrue - oMinTrue)
                        app.ThresholdSliderOverlay.Limits = [0 1];
                        app.ThresholdSliderOverlay.Value  = [0 1];
                        app.Overlay_low.Limits  = [0 1];
                        app.Overlay_high.Limits = [0 1];
                    else
                        app.ThresholdSliderOverlay.Limits = [oMinTrue, oMaxTrue];

                        if isfield(state,'OverlayThresh') && isnumeric(state.OverlayThresh) && numel(state.OverlayThresh)==2
                            v = double(state.OverlayThresh(:).');
                            v(1) = max(oMinTrue, min(oMaxTrue, v(1)));
                            v(2) = max(oMinTrue, min(oMaxTrue, v(2)));
                            if v(2) < v(1), v = sort(v); end
                            app.ThresholdSliderOverlay.Value = v;
                        else
                            % same logic as loader: if bipolar, symmetric; else full range
                            if oMinTrue < 0 && oMaxTrue > 0
                                mA = max(abs(oMinTrue), abs(oMaxTrue));
                                v  = [max(-mA,oMinTrue), min(mA,oMaxTrue)];
                                if v(2) <= v(1), v = [oMinTrue oMaxTrue]; end
                                app.ThresholdSliderOverlay.Value = v;
                            else
                                app.ThresholdSliderOverlay.Value = [oMinTrue, oMaxTrue];
                            end
                        end

                        app.Overlay_low.Limits  = [oMinTrue, oMaxTrue-abs(0.001*oMaxTrue)];
                        app.Overlay_high.Limits = [oMinTrue+abs(0.001*oMinTrue), oMaxTrue];
                    end

                    % Sync numeric boxes
                    app.Overlay_low.Value  = round(app.ThresholdSliderOverlay.Value(1),2);
                    app.Overlay_high.Value = round(app.ThresholdSliderOverlay.Value(2),2);

                    % Sync real-value readouts from normalized overlay slider
                    rngO  = app.OverlaySlider.UserData;
                    sO    = app.OverlaySlider.Value;
                    valsO = app.slider2val(sO, rngO);
                    app.OverlayLowVal.Value  = round(valsO(1),2);
                    app.OverlayHighVal.Value = round(valsO(2),2);

                    app.FlipORI1Switch.Value = state.FlipORI1;
                    app.FlipORI2Switch.Value = state.FlipORI2;
                    app.FlipORI3Switch.Value = state.FlipORI3;
                    app.applyFlipTransform();
                catch
                    disp('Could not restore Overlay threshold/values.');
                end

                % Overlay simple controls
                try if isfield(state,'OverlayAlpha'),    app.OverlayAlphaSpinner.Value    = state.OverlayAlpha;    end, catch, disp('OverlayAlpha failed'); end
                try if isfield(state,'OverlayContrast'), app.OverlayContrastSpinner.Value = state.OverlayContrast; end, catch, disp('OverlayContrast failed'); end
                try
                    if isfield(state,'OverlayColormap')
                        items = string(app.ColormapDropDownOverlay.Items);
                        if any(items == string(state.OverlayColormap)), app.ColormapDropDownOverlay.Value = state.OverlayColormap; end
                    end
                catch, disp('Overlay colormap failed'); end
                try if isfield(state,'OverlayFlip'),     app.FlipMapSwitchOverlay.Value = state.OverlayFlip; end, catch, disp('OverlayFlip failed'); end
                try if isfield(state,'FlattenOverlay'),  app.FlattenOverlaySwitch.Value = state.FlattenOverlay; end, catch, disp('FlattenOverlay failed'); end
                try if isfield(state,'TrimOverlayOn'),      app.TrimOverlayCheckBox.Value      = state.TrimOverlayOn;      end, catch, disp('TrimOverlayOn failed'); end
                try if isfield(state,'TrimOverlayLowPct'),  app.PctLowEditField_Overlay.Value  = state.TrimOverlayLowPct;  end, catch, disp('TrimOverlayLowPct failed'); end
                try if isfield(state,'TrimOverlayHighPct'), app.PctHighEditField_Overlay.Value = state.TrimOverlayHighPct; end, catch, disp('TrimOverlayHighPct failed'); end
            else
                % No overlay data loaded; force overlay off and ignore overlay state safely
                app.overlay = 0;
                disp('Overlay not loaded: skipping overlay-dependent settings.');
            end

            % ── 3) Slice masks / region / ORI sliders (recompute from CURRENT volume) ──────
            if ~isempty(app.dataImage)
                volSize = size(app.dataImage);
                try
                    app.ORI1Slider.Limits   = [1 volSize(2)];
                    app.ORI2Slider.Limits   = [1 volSize(1)];
                    app.ORI3Slider.Limits   = [1 volSize(3)];
                    app.ORI1Slider.UserData = volSize(2);
                    app.ORI2Slider.UserData = volSize(1);
                    app.ORI3Slider.UserData = volSize(3);

                    % Apply saved ORI ranges if valid, else full
                    if isfield(state,'ORI1') && isnumeric(state.ORI1) && numel(state.ORI1)==2
                        v = double(state.ORI1(:).');
                        v(1) = max(1, min(volSize(2), v(1)));
                        v(2) = max(1, min(volSize(2), v(2)));
                        if v(2) < v(1), v = sort(v); end
                        app.ORI1Slider.Value = v;
                    else
                        app.ORI1Slider.Value = [1 volSize(2)];
                    end

                    if isfield(state,'ORI2') && isnumeric(state.ORI2) && numel(state.ORI2)==2
                        v = double(state.ORI2(:).');
                        v(1) = max(1, min(volSize(1), v(1)));
                        v(2) = max(1, min(volSize(1), v(2)));
                        if v(2) < v(1), v = sort(v); end
                        app.ORI2Slider.Value = v;
                    else
                        app.ORI2Slider.Value = [1 volSize(1)];
                    end

                    if isfield(state,'ORI3') && isnumeric(state.ORI3) && numel(state.ORI3)==2
                        v = double(state.ORI3(:).');
                        v(1) = max(1, min(volSize(3), v(1)));
                        v(2) = max(1, min(volSize(3), v(2)));
                        if v(2) < v(1), v = sort(v); end
                        app.ORI3Slider.Value = v;
                    else
                        app.ORI3Slider.Value = [1 volSize(3)];
                    end

                    % Sync ORI numeric fields
                    app.ORI1_low.Value  = round(app.ORI1Slider.Value(1));
                    app.ORI1_high.Value = round(app.ORI1Slider.Value(2));
                    app.ORI2_low.Value  = round(app.ORI2Slider.Value(1));
                    app.ORI2_high.Value = round(app.ORI2Slider.Value(2));
                    app.ORI3_low.Value  = round(app.ORI3Slider.Value(1));
                    app.ORI3_high.Value = round(app.ORI3Slider.Value(2));
                catch
                    disp('Could not restore ORI sliders.');
                end
            end

            % ApplySliceMaskDropDown & Region
            try
                if isfield(state,'ApplySliceMask')
                    % Items may be {'All'} or {'All','Base','Overlay'} depending on overlay presence
                    items = string(app.ApplySliceMaskDropDown.Items);
                    val   = string(state.ApplySliceMask);
                    if any(items == val)
                        app.ApplySliceMaskDropDown.Value = char(val);
                    else
                        app.ApplySliceMaskDropDown.Value = 'All';
                    end
                end
            catch
                disp('ApplySliceMaskDropDown failed');
            end
            try
                if isfield(state,'Region')
                    items = string(app.TerritoryDropDown.Items);
                    if any(items == string(state.Region))
                        app.TerritoryDropDown.Value = state.Region;
                    end
                end
            catch
                disp('TerritoryDropDown failed');
            end

            % ── 4) Mask switches: only enable if mask exists & matches base grid ───────────
            % Base mask
            if isfield(state,'ApplyBaseMask')
                try
                    if ~isempty(app.WBmask) && ~isempty(app.dataImage) && isequal(size(app.WBmask), size(app.dataImage(:,:,:,1)))
                        app.ApplyBaseMaskSwitch.Value = state.ApplyBaseMask;
                    else
                        app.ApplyBaseMaskSwitch.Value = 'off';
                        disp('Base mask missing or size mismatch → ApplyBaseMask=off.');
                    end
                catch
                    disp('ApplyBaseMaskSwitch failed');
                end
            end
            % Overlay mask
            if isfield(state,'ApplyOverlayMask')
                try
                    if app.overlay && ~isempty(app.OverlayMask) && ~isempty(app.dataImage) ...
                            && isequal(size(app.OverlayMask), size(app.dataImage(:,:,:,1)))
                        app.ApplyOverlayMaskSwitch.Value = state.ApplyOverlayMask;
                    else
                        app.ApplyOverlayMaskSwitch.Value = 'off';
                        if app.overlay, disp('Overlay mask missing or size mismatch → ApplyOverlayMask=off.'); end
                    end
                catch
                    disp('ApplyOverlayMaskSwitch failed');
                end
            end

            % ── 5) Rendering / background / quality / denoising toggles ────────────────────
            try
                if isfield(state,'RenderingBase')
                    items = string(app.RenderingDropDown.Items);
                    if any(items == string(state.RenderingBase)), app.RenderingDropDown.Value = state.RenderingBase; end
                end
            catch, disp('RenderingDropDown failed'); end

            try
                if app.overlay && isfield(state,'RenderingOverlay')
                    items = string(app.RenderingDropDownOverlay.Items);
                    if any(items == string(state.RenderingOverlay)), app.RenderingDropDownOverlay.Value = state.RenderingOverlay; end
                end
            catch, disp('RenderingDropDownOverlay failed'); end

            try
                if isfield(state,'Background')
                    items = string(app.BackgroundDropDown.Items);
                    if any(items == string(state.Background))
                        app.BackgroundDropDown.Value = state.Background;
                        BackgroundDropDownValueChanged(app);
                    end
                end
            catch, disp('BackgroundDropDown failed'); end

            try if isfield(state,'Quality'),   app.QualityDropDown.Value = state.Quality; end, catch, end
            try if isfield(state,'Lighting'),  app.LightingSwitch.Value  = state.Lighting;  app.viewer.Lighting = app.LightingSwitch.Value; end, catch, end
            try if isfield(state,'Denoising'), app.DenoisingSwitch.Value = state.Denoising; app.viewer.Denoising = app.DenoisingSwitch.Value; end, catch, end

            % Persisted maps (optional)
            try if isfield(state,'colmap'),        app.colmap        = state.colmap;        end, catch, end
            try if isfield(state,'alphaMap'),      app.alphaMap      = state.alphaMap;      end, catch, end
            try if isfield(state,'colmapOverlay'), app.colmapOverlay = state.colmapOverlay; end, catch, end


            % ── 6) Recompute viz + push colormaps/alphamaps ────────────────────────────────
            try app.updateVisualization(); catch, disp('updateVisualization failed'); end
            try app.applyBaseColormap();   catch, disp('applyBaseColormap failed');   end
            try app.updateBaseAlphaMap();  catch, disp('updateBaseAlphaMap failed');  end
            if app.overlay
                try app.applyOverlayColormap();  catch, disp('applyOverlayColormap failed');  end
                try app.updateOverlayAlphaMap(); catch, disp('updateOverlayAlphaMap failed'); end
            end

            % ── 7) Camera & viewer globals (after viz is stable) ───────────────────────────
            try if isfield(state,'CameraPosition') && ~isempty(state.CameraPosition), app.viewer.CameraPosition = state.CameraPosition; end, catch, disp('CameraPosition failed'); end
            try if isfield(state,'CameraTarget')   && ~isempty(state.CameraTarget),   app.viewer.CameraTarget   = state.CameraTarget;   end, catch, disp('CameraTarget failed');   end
            try if isfield(state,'CameraUpVector') && ~isempty(state.CameraUpVector), app.viewer.CameraUpVector = state.CameraUpVector; end, catch, disp('CameraUpVector failed'); end
            try if isfield(state,'CameraZoom')     && ~isempty(state.CameraZoom),     app.viewer.CameraZoom     = state.CameraZoom;     end, catch, disp('CameraZoom failed');     end

            try
                if isprop(app.viewer,'RenderingQuality')
                    if isfield(state,'RenderingQuality') && ~isempty(state.RenderingQuality)
                        app.viewer.RenderingQuality = state.RenderingQuality;
                    else
                        app.viewer.RenderingQuality = app.QualityDropDown.Value;
                    end
                end
            catch
                disp('RenderingQuality failed');
            end

            % ── 8) Done ────────────────────────────────────────────────────────────────────
            try
                uialert(app.UIFigure,'Viewer state loaded.','Done');
            catch
                % UIFigure might be mid-refresh; ok to skip alert
            end
        end


        function openQuickPlotDialog(app)
            % Dark-themed dialog to run quickPlot for Base and (optionally) Overlay,
            % with Orientation (Axial/Coronal/Sagittal), mask application (pre-permutation),
            % rotation, scale (rounded to 2 d.p.), rows/cols, slice start/end per orientation,
            % and export via exportCurrentFig(saveDir, filename).

            % ---------- theme ----------
            bg = [0 0 0]; fg = [1 1 1];
            try
                accent = app.GenerateFigureButton.BackgroundColor;
            catch
                accent = [0.22 0.26 0.88];
            end

            % Try to start in the base image folder
            try
                if ~isempty(app.basePath)
                    if isfile(app.basePath), cd(fileparts(app.basePath));
                    else,                   cd(app.basePath);
                    end
                end
            catch
                % ignore
            end

            hasOverlay = ~isempty(app.overlayImage);

            % ---------- dialog ----------
            dlg = uifigure('Name','Quick Plot', 'Color',bg, 'WindowStyle','modal', ...
                'Position',[100 100 480 920]);

            root = uigridlayout(dlg,[4 2], ...
                'RowHeight',{'fit','1x','1x','fit'}, ...
                'ColumnWidth',{'1x','1x'}, ...
                'Padding',[16 16 16 16], ...
                'BackgroundColor',bg);

            % ---- Save location (shared) ----
            savePanel = uipanel(root,'BorderType','none','BackgroundColor',bg);
            savePanel.Layout.Row = 1; savePanel.Layout.Column = [1 2];

            sg = uigridlayout(savePanel,[1 3], ...
                'ColumnWidth',{'fit','1x','fit'}, ...
                'RowHeight',{'fit'}, ...
                'Padding',[0 0 0 0], ...
                'BackgroundColor',bg);

            uilabel(sg,'Text','Save to','FontColor',fg,'BackgroundColor',bg);

            edDir = uieditfield(sg,'text','Editable','off', ...
                'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg, ...
                'Value',pwd);

            uibutton(sg,'Text','Browse…', ...
                'BackgroundColor',[0.85 0.45 0.05], ...  % orange
                'FontColor',fg, ...
                'ButtonPushedFcn',@(s,e) onBrowseDir());
            function onBrowseDir()
                p = uigetdir(edDir.Value,'Choose output folder');
                if isequal(p,0), return; end
                edDir.Value = p;
            end

            % ---- BASE panel (row 2) ----
            [~, CB] = buildPanel(root,'Base image', ...
                app.ColormapDropDown.Items, app.ColormapDropDown.Value, ...
                @() maskedVolume('base'), ...
                initScale('base'), ...
                [4 4], 90, ...
                'quickplot_base', 'base');

            % ---- OVERLAY panel (row 3) ----
            if hasOverlay
                [~, CO] = buildPanel(root,'Overlay image', ...
                    app.ColormapDropDownOverlay.Items, app.ColormapDropDownOverlay.Value, ...
                    @() maskedVolume('overlay'), ...
                    initScale('overlay'), ...
                    [4 4], 90, ...
                    'quickplot_overlay', 'overlay');
            else
                dummy = uipanel(root,'BorderType','none','BackgroundColor',bg);
                dummy.Layout.Row = 3; dummy.Layout.Column = [1 2];
                CO = struct(); %#ok<STRNU>
            end

            if hasOverlay
                % --- initial sync: overlay shows the same view/grid/rot + slices as base ---
                CO.ddOrient.Value = CB.ddOrient.Value;
                CO.edRows.Value   = CB.edRows.Value;
                CO.edCols.Value   = CB.edCols.Value;
                CO.edRot.Value    = CB.edRot.Value;
                CO.edStart.Value  = CB.edStart.Value;
                CO.edEnd.Value    = CB.edEnd.Value;

                % --- when base orientation or rotation changes, recompute base slices and mirror all ---
                CB.ddOrient.ValueChangedFcn = @(s,e) onBaseOrientOrRotChanged('orient');
                CB.edRot.ValueChangedFcn    = @(s,e) onBaseOrientOrRotChanged('rot');

                % --- when base grid changes, mirror rows/cols only (not scales/maps) ---
                CB.edRows.ValueChangedFcn   = @(s,e) set(CO.edRows,'Value', s.Value);
                CB.edCols.ValueChangedFcn   = @(s,e) set(CO.edCols,'Value', s.Value);

                % --- keep overlay slice fields equal to base if user edits base range directly ---
                CB.edStart.ValueChangedFcn  = @(s,e) set(CO.edStart,'Value', s.Value);
                CB.edEnd.ValueChangedFcn    = @(s,e) set(CO.edEnd,  'Value', s.Value);
            end

            % ---- footer ----
            foot = uipanel(root,'BorderType','none','BackgroundColor',bg);
            foot.Layout.Row = 4; foot.Layout.Column = [1 2];

            fgGrid = uigridlayout(foot,[1 3], ...
                'ColumnWidth',{'1x','fit','1x'}, ...
                'Padding',[0 0 0 0], 'BackgroundColor',bg);

            % ---- new unified Generate button ----
            btnGenerateAll = uibutton(fgGrid,'Text','Generate', ...
                'BackgroundColor',[0.20 0.60 0.20], ... % green
                'FontColor',[1 1 1], ...
                'FontWeight','bold', ...
                'ButtonPushedFcn',@(s,e) doGenerateAll());
            btnGenerateAll.Layout.Column = [1 3];

            btnDone = uibutton(fgGrid,'Text','Done', ...
                'BackgroundColor',[0.70 0.15 0.15], ...   % red
                'FontColor',[1 1 1], ...                  % white text
                'FontWeight','bold', ...                  % bold text
                'ButtonPushedFcn',@(s,e) close(dlg));

            % Make the button span across all 3 columns to appear larger + centered
            btnDone.Layout.Column = [1 3];

            % ====================== PANEL BUILDER ======================
            function [P, C] = buildPanel(parent, titleText, cmapItems, cmapVal, volFcn, ...
                    climInit, gridRC, rotDeg, defaultName, which)

                P = uipanel(parent,'Title',titleText,'BackgroundColor',bg);
                P.Layout.Column = [1 2];
                if strcmp(which,'base'), P.Layout.Row = 2; else, P.Layout.Row = 3; end

                g = uigridlayout(P,[10 2], ...
                    'RowHeight',{'fit','fit','fit','fit','fit','fit','fit','fit','fit','fit'}, ...
                    'ColumnWidth',{'fit','1x'}, ...
                    'Padding',[10 8 10 8], ...
                    'BackgroundColor',bg);

                % 0) Orientation
                uilabel(g,'Text','Orientation','FontColor',fg,'BackgroundColor',bg);
                ddOrient = uidropdown(g, ...
                    'Items', {'Orientation 1','Orientation 2','Orientation 3'}, ...
                    'Value', 'Orientation 1', ...
                    'BackgroundColor',bg,'FontColor',fg);

                % 1) Colormap
                uilabel(g,'Text','Colormap','FontColor',fg,'BackgroundColor',bg);
                dd = uidropdown(g,'Items',cmapItems,'Value',cmapVal, ...
                    'BackgroundColor',bg,'FontColor',fg);

                % 2) Scale [min  max] (rounded to 2 d.p.)
                uilabel(g,'Text','Scale [min  max]','FontColor',fg,'BackgroundColor',bg);
                hScale = uigridlayout(g,[1 2], 'ColumnWidth',{'1x','1x'}, ...
                    'Padding',[0 0 0 0], 'BackgroundColor',bg);
                edMin = uieditfield(hScale,'numeric','Value',round(climInit(1),2), ...
                    'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);
                edMax = uieditfield(hScale,'numeric','Value',round(climInit(2),2), ...
                    'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);

                % 3) Slices [start  end]
                uilabel(g,'Text','Slices [start  end]','FontColor',fg,'BackgroundColor',bg);
                hSlices = uigridlayout(g,[1 2], 'ColumnWidth',{'1x','1x'}, ...
                    'Padding',[0 0 0 0], 'BackgroundColor',bg);
                edStart = uieditfield(hSlices,'numeric', ...
                    'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);
                edEnd   = uieditfield(hSlices,'numeric', ...
                    'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);

                % 4) Rows
                uilabel(g,'Text','Rows','FontColor',fg,'BackgroundColor',bg);
                edRows = uieditfield(g,'numeric','Value',gridRC(1), ...
                    'RoundFractionalValues','on','Limits',[1 Inf], ...
                    'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);

                % 5) Cols
                uilabel(g,'Text','Cols','FontColor',fg,'BackgroundColor',bg);
                edCols = uieditfield(g,'numeric','Value',gridRC(2), ...
                    'RoundFractionalValues','on','Limits',[1 Inf], ...
                    'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);

                % 6) Rotation (deg)
                uilabel(g,'Text','Rotation (deg)','FontColor',fg,'BackgroundColor',bg);
                edRot = uieditfield(g,'numeric','Value',rotDeg, ...
                    'RoundFractionalValues','off', ...
                    'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);
                if strcmp(which,'overlay')
                    uilabel(g,'Text','Alpha (0–1)','FontColor',fg,'BackgroundColor',bg);
                    edAlpha = uieditfield(g,'numeric','Limits',[0 1],'Value',1, ...
                        'RoundFractionalValues','off', ...
                        'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);
                else
                    edAlpha = [];
                end

                % 7) Filename
                if strcmp(which,'base')
                    uilabel(g,'Text','Filename Base','FontColor',fg,'BackgroundColor',bg);
                elseif strcmp(which,'overlay')
                    uilabel(g,'Text','Filename Overlay','FontColor',fg,'BackgroundColor',bg);
                else
                    uilabel(g,'Text','Filename','FontColor',fg,'BackgroundColor',bg);
                end

                edName = uieditfield(g,'text','Value',defaultName, ...
                    'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);

                % --- add extra field only for overlay ---
                if strcmp(which,'overlay')
                    uilabel(g,'Text','Filename Combined','FontColor',fg,'BackgroundColor',bg);
                    edNameCombined = uieditfield(g,'text','Value','combined_quickplot', ...
                        'BackgroundColor',[0.10 0.10 0.10],'FontColor',fg);
                else
                    edNameCombined = [];
                end

                % 8) spacer
                uilabel(g,'Text',' ','BackgroundColor',bg);

                % 9) Generate
                uilabel(g,'Text',' ','BackgroundColor',bg);
                btnGen = [];

                % initialize slices from current orientation + masks
                initSliceFields();

                % update slices whenever the orientation changes
                ddOrient.ValueChangedFcn = @(s,e) initSliceFields();
                edRot.ValueChangedFcn    = @(s,e) initSliceFields();

                C = struct('dd',dd,'ddOrient',ddOrient, ...
                    'edMin',edMin,'edMax',edMax, ...
                    'edStart',edStart,'edEnd',edEnd, ...
                    'edRows',edRows,'edCols',edCols, ...
                    'edRot',edRot,'edName',edName, ...
                    'edNameCombined',edNameCombined, ...   % <--- new field
                    'edAlpha',edAlpha, ...
                    'btnGen',btnGen,'which',which);

                function initSliceFields()
                    % masked volume in native orientation
                    v  = volFcn();                    % maskedVolume('base'/'overlay')
                    % reorient to the chosen view
                    vp = reorientVolume(v, ddOrient.Value);
                    % IMPORTANT: account for current rotation before nz detection
                    vp = rotateVolumeZ(vp, edRot.Value);

                    % get non-zero range on the *actual* plotted stack
                    [s1,s2] = nzStartEnd_fromNonZero(vp);
                    Z = size(vp,3);

                    % keep limits consistent with current Z
                    edStart.Limits = [1 Z];
                    edEnd.Limits   = [1 Z];

                    % update values (guard if fields had stale values)
                    s1 = max(1, min(s1, Z));
                    s2 = max(s1, min(s2, Z));
                    edStart.Value  = s1;
                    edEnd.Value    = s2;
                end
            end

            % ====================== SINGLE PANEL GENERATE (helper) ======================

            function doGenerate(which, C, saveDir)
                % volume (masked) + orientation
                v  = maskedVolume(which);
                vp = reorientVolume(v, C.ddOrient.Value);

                % rotation about Z (in-plane)
                ang = C.edRot.Value;
                vp  = rotateVolumeZ(vp, ang);

                % colormap (per-panel + Flip switches)
                cmap = app.getCustomColormap(C.dd.Value);
                if strcmpi(which,'base')
                    flipF = strcmpi(app.FlipMapSwitch.Value,'yes');
                else
                    flipF = strcmpi(app.FlipMapSwitchOverlay.Value,'yes');
                end
                if flipF, cmap = flipud(cmap); end

                % scale (rounded to 2 d.p., clamp if user gave nonsense)
                vmin = round(C.edMin.Value,2);
                vmax = round(C.edMax.Value,2);
                if ~isfinite(vmin) || ~isfinite(vmax) || vmin==vmax
                    s = initScale(which); vmin = s(1); vmax = s(2);
                    C.edMin.Value = vmin; C.edMax.Value = vmax;
                end
                if vmax < vmin
                    [vmin,vmax] = deal(vmax,vmin);
                    C.edMin.Value = vmin; C.edMax.Value = vmax;
                end
                scale = [vmin vmax];

                % slices – clamp to valid 1..Z (use panel’s current entries)
                [s1, s2] = clampSliceRange(vp, C.edStart.Value, C.edEnd.Value);
                C.edStart.Value = s1; C.edEnd.Value = s2;

                % grid
                nR = max(1, round(C.edRows.Value));
                nC = max(1, round(C.edCols.Value));
                C.edRows.Value = nR; C.edCols.Value = nC;

                % run quickPlot directly
                quickPlot(vp, nR, nC, scale, cmap, s1:s2);

                % size window sensibly
                %autosizeQuickPlotFig(nR, nC, size(vp));

                % ensure output dir exists / pick one
                if ~isfolder(saveDir)
                    p = uigetdir(pwd,'Choose output folder');
                    if isequal(p,0), return; else, saveDir = p; edDir.Value = p; end
                end

                % filename
                fname = strtrim(C.edName.Value);
                if isempty(fname)
                    fname = lower([which '_quickplot']);
                    C.edName.Value = fname;
                end

                % export (PNG + SVG via your helper)
                fh = gcf;
                exportCurrentFig(saveDir, fname, fh);
            end

            % ====================== GENERATE ======================
            function doGenerateAll()
                % Ensure zero voxels -> NaN before plotting
                % (applied automatically via maskedVolume below)

                saveDir = edDir.Value;

                % Base settings
                doGenerate('base', CB, saveDir);

                % Overlay (if present)
                if hasOverlay
                    doGenerate('overlay', CO, saveDir);

                    % Combined
                    vBase = maskedVolume('base');
                    vBase = reorientVolume(vBase, CB.ddOrient.Value);
                    vBase = rotateVolumeZ(vBase, CB.edRot.Value);

                    vOverlay = maskedVolume('overlay');
                    vOverlay = reorientVolume(vOverlay, CB.ddOrient.Value);
                    vOverlay = rotateVolumeZ(vOverlay, CB.edRot.Value);

                    % Colormaps/scales
                    cmapB = app.getCustomColormap(CB.dd.Value);
                    if strcmpi(app.FlipMapSwitch.Value,'yes'), cmapB = flipud(cmapB); end
                    cmapO = app.getCustomColormap(CO.dd.Value);
                    if strcmpi(app.FlipMapSwitchOverlay.Value,'yes'), cmapO = flipud(cmapO); end
                    scaleB = [CB.edMin.Value CB.edMax.Value];
                    scaleO = [CO.edMin.Value CO.edMax.Value];
                    alphaO = max(0, min(1, CO.edAlpha.Value));

                    % Slice range (same as base)
                    [s1, s2] = clampSliceRange(vBase, CB.edStart.Value, CB.edEnd.Value);

                    % Run combined overlay quickPlot
                    quickPlot_overlay(vBase, vOverlay, CB.edRows.Value, CB.edCols.Value, ...
                        scaleB, cmapB, scaleO, cmapO, alphaO, s1:s2);
                    %autosizeQuickPlotFig(CB.edRows.Value, CB.edCols.Value, size(vBase));

                    % Save combined
                    fnameCombined = CO.edNameCombined.Value;
                    fh = gcf;
                    exportCurrentFig(saveDir, fnameCombined, fh);

                end

                uialert(dlg, 'QuickPlot images generated successfully - press Done to continue.', 'Done', 'Icon','info');
                figure(app.UIFigure);

            end

            % ====================== HELPERS ======================

            function scl = initScale(which)
                if strcmp(which,'base')
                    [vmin, vmax] = initialClimFromGUI('base');
                else
                    [vmin, vmax] = initialClimFromGUI('overlay');
                end
                scl = [round(vmin,2), round(vmax,2)];
                if scl(2) <= scl(1), scl(2) = scl(1)+eps; end
            end

            % masked volume BEFORE permutation (switches always exist)
            function vol = maskedVolume(which)
                switch lower(which)
                    case 'base'
                        vol = currentBaseFrame();
                        vol(vol == 0) = NaN;                          % <--- new line
                        vol(~logical(app.BaseVisibilityMask)) = NaN;
                    case 'overlay'
                        vol = app.overlayImage;
                        vol(vol == 0) = NaN;                          % <--- new line
                        if ~isempty(app.OverlayVisibilityMask)
                            m = app.OverlayVisibilityMask;
                        else
                            m = app.BaseVisibilityMask;
                        end
                        vol(~logical(m)) = NaN;
                    otherwise
                        error('maskedVolume: which must be ''base'' or ''overlay''.');
                end
            end

            % Nested helper: recompute base slice range for new orientation/rotation and mirror to overlay
            function onBaseOrientOrRotChanged(kind)
                % Mirror orientation/rotation values to overlay controls
                switch kind
                    case 'orient', set(CO.ddOrient,'Value', CB.ddOrient.Value);
                    case 'rot',    set(CO.edRot,   'Value', CB.edRot.Value);
                end

                % Recompute base non-zero slice range in the new view (like initSliceFields)
                vb = maskedVolume('base');
                vb = reorientVolume(vb, CB.ddOrient.Value);
                vb = rotateVolumeZ(vb, CB.edRot.Value);

                [s1,s2] = nzStartEnd_fromNonZero(vb);
                Z = size(vb,3);
                s1 = max(1, min(s1, Z));
                s2 = max(s1, min(s2, Z));

                % Update limits + values on BOTH panels so they stay consistent
                set(CB.edStart,'Limits',[1 Z],'Value',s1);
                set(CB.edEnd,  'Limits',[1 Z],'Value',s2);
                set(CO.edStart,'Limits',[1 Z],'Value',s1);
                set(CO.edEnd,  'Limits',[1 Z],'Value',s2);
            end
            function vol = currentBaseFrame()
                vol = app.sourceDataImage;
                if ndims(vol)==4
                    idx = max(1, app.currentBaseFrame);
                    vol = vol(:,:,:,idx);
                end
                % if app.TrimBaseCheckBox.Value
                %     vol = clipOutliers(vol, app.PctLowEditField_Base.Value, app.PctHighEditField_Base.Value);
                % end
            end

            function [vmin, vmax] = initialClimFromGUI(which)
                if strcmp(which,'base')
                    if isfinite(app.BaseLowVal.Value) && isfinite(app.BaseHighVal.Value) ...
                            && app.BaseLowVal.Value ~= app.BaseHighVal.Value
                        vmin = app.BaseLowVal.Value; vmax = app.BaseHighVal.Value;
                    else
                        [vmin, vmax] = slider2clim(app.BaseSlider, app.BaseSlider.UserData);
                    end
                else
                    if isfinite(app.OverlayLowVal.Value) && isfinite(app.OverlayHighVal.Value) ...
                            && app.OverlayLowVal.Value ~= app.OverlayHighVal.Value
                        vmin = app.OverlayLowVal.Value; vmax = app.OverlayHighVal.Value;
                    else
                        [vmin, vmax] = slider2clim(app.OverlaySlider, app.OverlaySlider.UserData);
                    end
                end
            end

            function [vmin, vmax] = slider2clim(sl, rng)
                s = sl.Value;  % normalized [0..1]
                vmin = rng(1) + s(1)*diff(rng);
                vmax = rng(1) + s(2)*diff(rng);
            end

            % orientation: make the chosen anatomical axis the 3rd dimension
            function vp = reorientVolume(v, orient)
                switch orient
                    case 'Orientation 1'   % was Axial (Z)
                        vp = v;
                    case 'Orientation 2'   % was Coronal (Y)
                        vp = permute(v, [1 3 2]);
                    case 'Orientation 3'   % was Sagittal (X)
                        vp = permute(v, [2 3 1]);
                    otherwise
                        vp = v;
                end
            end

            % EXACT non-zero logic (used on the permuted volume)
            function [firstSlice, lastSlice] = nzStartEnd_fromNonZero(vol, dlg)
                v = double(vol);

                % sum of absolute values per slice (ignoring zeros automatically)
                sliceSum = squeeze(sum(sum(abs(v),1),2));

                % non-zero slices
                nzSlices = find(sliceSum > 0);

                if isempty(nzSlices)
                    firstSlice = 1;
                    lastSlice  = size(v,3);
                    if nargin > 1
                        uialert(dlg, ...
                            'Volume contains no non-zero voxels. Using full slice range.', ...
                            'No data','Icon','warning');
                    end
                    return
                end

                firstSlice = nzSlices(1);
                lastSlice  = nzSlices(end);
            end

            % clamp [start,end] to 1..Z (like quickPlot "else" branch)
            function [s1, s2] = clampSliceRange(vol, startVal, endVal)
                Z = size(vol,3);
                userVec = round([startVal endVal]);
                if numel(userVec)==2
                    rngVec = userVec(1):userVec(2);
                else
                    rngVec = userVec(:).';
                end
                rngVec = unique(rngVec(rngVec >= 1 & rngVec <= Z));
                if isempty(rngVec)
                    [s1, s2] = nzStartEnd_fromNonZero(vol);
                else
                    s1 = rngVec(1); s2 = rngVec(end);
                end
            end

            % Rotate about array Z (in-plane)
            function vrot = rotateVolumeZ(v, ang)
                if isempty(ang) || ang == 0
                    vrot = v;
                    return
                end

                try
                    % 'loose' expands the array to fully contain the rotated image
                    % 'FillValues', NaN keeps the padded region invisible/non-contributing
                    vrot = imrotate3(v, ang, [0 0 1], 'linear', 'loose', 'FillValues', NaN);
                catch
                    % Fallback: rotate each slice with 2-D imrotate, also 'loose'
                    Z = size(v,3);
                    % Preallocate to the largest possible output by probing the middle slice
                    mid = max(1, round(Z/2));
                    probe = imrotate(v(:,:,mid), ang, 'bilinear', 'loose');
                    vrot  = NaN([size(probe,1) size(probe,2) Z], 'like', double(v));

                    for k = 1:Z
                        sl = imrotate(v(:,:,k), ang, 'bilinear', 'loose');
                        % Center-insert each rotated slice into vrot
                        r = size(vrot,1); c = size(vrot,2);
                        sr = size(sl,1);  sc = size(sl,2);
                        r0 = floor((r - sr)/2) + 1;
                        c0 = floor((c - sc)/2) + 1;
                        vrot(r0:r0+sr-1, c0:c0+sc-1, k) = sl;
                    end
                end
            end

            function autosizeQuickPlotFig(nRows, nCols, volSize)
                % volSize is size(vp) *after* reorient + rotate; use it to estimate aspect.
                H = volSize(1); W = volSize(2);
                if H<=0 || W<=0 || ~isfinite(H) || ~isfinite(W)
                    return
                end
                aspect = H / W;

                % Heuristic tile size (pixels). Adjust if you like.
                baseTileW = 280;                    % px per tile width
                tileW = max(180, min(480, baseTileW));
                tileH = tileW * aspect;

                % Add some space for the shared colorbar + margins.
                cbW          = 80;                  % colorbar + padding
                sideMargins  = 120;
                topBottom    = 160;

                figW = round(nCols * tileW + cbW + sideMargins);
                figH = round(nRows * tileH + topBottom);

                % Clamp to screen size.
                scr = get(0,'ScreenSize');          % [x y w h]
                maxW = scr(3) - 120;
                maxH = scr(4) - 160;
                scale = min([maxW/figW, maxH/figH, 1]);
                figW = max(800, round(figW * scale));
                figH = max(600, round(figH * scale));

                % Position near center-ish.
                x = max(50, round((scr(3) - figW)/2));
                y = max(50, round((scr(4) - figH)/2));
                set(gcf, 'Units','pixels', 'Position', [x y figW figH]);
            end

            figure(app.UIFigure);

        end

        function cmap = warpColormap(app, baseCmap, gamma)
            % Gamma-warp a colormap so contrast affects COLOR in Map mode.
            n  = size(baseCmap,1);
            t  = linspace(0,1,n)';                 % original index 0..1
            tt = t .^ max(1e-6, gamma);            % gamma warp (γ=1 -> identity)
            cmap = interp1(t, baseCmap, tt, 'linear', 'extrap');
        end

        function tf = askYesNoBlack(app, titleStr, questionStr)
            dlg = uifigure('Name',titleStr,'Color','k','WindowStyle','modal', ...
                'Position',[100 100 420 160]);
            gl  = uigridlayout(dlg,[3 2]);
            gl.RowHeight    = {30,'1x',40};
            gl.ColumnWidth  = {'1x','1x'};
            gl.Padding      = [16 12 16 12];
            gl.BackgroundColor = [0 0 0];

            lbl = uilabel(gl,'Text',questionStr,'FontColor',[1 1 1], ...
                'HorizontalAlignment','center');
            lbl.Layout.Row = 1;  lbl.Layout.Column = [1 2];

            btnNo  = uibutton(gl,'Text','No', ...
                'BackgroundColor',[0.20 0.20 0.20],'FontColor',[1 1 1], ...
                'ButtonPushedFcn',@(s,e) set(dlg,'UserData',false));
            btnNo.Layout.Row = 3;  btnNo.Layout.Column = 1;

            btnYes = uibutton(gl,'Text','Yes', ...
                'BackgroundColor',[0.25 0.45 0.95],'FontColor',[1 1 1], ...
                'ButtonPushedFcn',@(s,e) set(dlg,'UserData',true));
            btnYes.Layout.Row = 3; btnYes.Layout.Column = 2;

            waitfor(dlg,'UserData');   % blocks until a button sets UserData
            tf = logical(dlg.UserData);
            delete(dlg);
        end

        function updateBaseAlphaMap(app)
            % Builds and pushes the base Alphamap according to the FlattenAlphaSwitch.
            % - "On"  → constant (flat) alpha, ignores Contrast
            % - "Off" → contrast-shaped alpha (current behavior)

            n = 256;
            A = app.BaseAlphaSpinner.Value;

            % Switch value is typically 'On'/'Off' (string) in App Designer
            useFlat = strcmp(string(app.FlattenBaseSwitch.Value), "yes");

            if useFlat
                % --- Alternative: constant alpha across intensity ---
                a = A * ones(1, n);
            else
                % --- Current: contrast shapes opacity ---
                C = max(1e-6, app.BaseContrastSpinner.Value);
                ramp = linspace(0, 1, n) .^ C;
                a = A * ramp;
            end

            % Safety clamps
            a = min(1, max(0, a));
            a(1) = 0;  % keep true-zero bin hidden

            % Store & push (fast, no full redraw)
            app.alphaMap = a;
            app.volHandle.Alphamap = a;
        end

        function updateOverlayAlphaMap(app)
            if isempty(app.volHandle) || ~isvalid(app.volHandle) || ~app.overlay, return; end

            n = 256;
            A = app.OverlayAlphaSpinner.Value;
            useFlat = app.isOn(app.FlattenOverlaySwitch.Value);

            if useFlat
                % --- Flat (constant) alpha across the range
                a = A * ones(1, n);
            else
                % --- Contrast-shaped alpha using OverlayContrastSpinner
                C = max(1e-6, app.OverlayContrastSpinner.Value);
                ramp = linspace(0, 1, n) .^ C;
                a = A * ramp;
            end

            % Safety
            a = min(1, max(0, a));
            a(1) = 0;   % keep zero bin transparent

            try
                app.volHandle.OverlayAlphamap = a;
            catch
                % ok if volHandle not ready yet; next redraw will push
            end
        end


        function tf = isOn(app, val)
            % Normalizes App Designer switch values: supports 'On'/'Off' and 'yes'/'no'
            s = lower(string(val));
            tf = any(strcmp(s, ["on","yes","true","1"]));
        end


        function renderBaseColorbar(app)
            % Uses app.colmap and draws ONLY the gradient + (optional) histogram overlay.
            if isempty(app.ColorbarPanel) || isempty(app.hBaseCB_Image), return; end

            panel = app.ColorbarPanel;
            cmap  = app.colmap;                     % already contrast-warped & flipped
            barW  = max(1, round(panel.Position(3)));
            rgb   = uint8(255 * permute(cmap, [1 3 2]));
            app.hBaseCB_Image.CData = repmat(rgb, 1, barW);

            % Repaint histogram on top (if present)
            if ~isempty(app.hBaseHistAxes) && isgraphics(app.hBaseHistAxes) && ~isempty(app.baseHistCounts)
                % (re)draw quickly without touching viewer; your drawStaticHist is fine:
                cla(app.hBaseHistAxes);
                % local call to your existing drawStaticHist nested function isn't possible here,
                % so keep the small helper inline again (duplicated minimal version):
                counts256 = double(app.baseHistCounts);
                clip = prctile(counts256, 99);
                counts256 = min(counts256, clip);
                counts256 = sqrt(counts256);
                if max(counts256) > 0, counts256 = counts256 ./ max(counts256); end
                hold(app.hBaseHistAxes,'on');
                nRows = numel(counts256); barWn = 0.85;
                for r = 1:nRows
                    if counts256(r) > 0
                        line(app.hBaseHistAxes, [0 counts256(r)*barWn], [r r], 'Color','w','LineWidth',1,'HitTest','off');
                    end
                end
                hold(app.hBaseHistAxes,'off');
                uistack(app.hBaseHistAxes,'top');
            end
        end

        function renderOverlayColorbar(app)
            if ~app.overlay || isempty(app.ColorbarPanelOverlay) || isempty(app.hOvCB_Image), return; end

            panel = app.ColorbarPanelOverlay;
            cmap  = app.colmapOverlay;
            barW  = max(1, round(panel.Position(3)));
            rgb   = uint8(255 * permute(cmap, [1 3 2]));
            app.hOvCB_Image.CData = repmat(rgb, 1, barW);

            if ~isempty(app.hOvHistAxes) && isgraphics(app.hOvHistAxes) && ~isempty(app.overlayHistCounts)
                cla(app.hOvHistAxes);
                counts256 = double(app.overlayHistCounts);
                clip = prctile(counts256, 99);
                counts256 = min(counts256, clip);
                counts256 = sqrt(counts256);
                if max(counts256) > 0, counts256 = counts256 ./ max(counts256); end
                hold(app.hOvHistAxes,'on');
                nRows = numel(counts256); barWn = 0.85;
                for r = 1:nRows
                    if counts256(r) > 0
                        line(app.hOvHistAxes, [0 counts256(r)*barWn], [r r], 'Color','w','LineWidth',1,'HitTest','off');
                    end
                end
                hold(app.hOvHistAxes,'off');
                uistack(app.hOvHistAxes,'top');
            end
        end

        function applyBaseColormap(app)
            if isempty(app.volHandle) || ~isvalid(app.volHandle), return; end
            cmap = app.getCustomColormap(app.ColormapDropDown.Value);
            if any(strcmpi(string(app.FlipMapSwitch.Value), ["on","yes"])), cmap = flipud(cmap); end
            cmap = app.warpColormap(cmap, app.BaseContrastSpinner.Value);
            app.colmap = cmap;
            app.volHandle.Colormap = cmap;   % viewer color only
            app.renderBaseColorbar();            % gradient + histogram repaint
        end

        function applyOverlayColormap(app)
            if ~app.overlay || isempty(app.volHandle) || ~isvalid(app.volHandle), return; end
            cmap = app.getCustomColormap(app.ColormapDropDownOverlay.Value);
            if any(strcmpi(string(app.FlipMapSwitchOverlay.Value), ["on","yes"])), cmap = flipud(cmap); end
            cmap = app.warpColormap(cmap, app.OverlayContrastSpinner.Value);
            app.colmapOverlay = cmap;
            app.volHandle.OverlayColormap = cmap;
            app.renderOverlayColorbar();
        end

        function a = buildAlphaMap(app, A, contrast, flattenSwitchVal)
            % A = overall alpha (spinner), contrast = contrast spinner value
            % flattenSwitchVal is the UI switch value, e.g. "yes"/"no", "on"/"off"
            n = 256;
            useFlat = app.isOn(flattenSwitchVal);  % you already have isOn()
            if useFlat
                a = A * ones(1, n);
            else
                a = (linspace(0,1,n) .^ max(1e-6, contrast)) * A;
            end
            a = min(1, max(0, a));
            a(1) = 0;  % keep the zero bin transparent
        end

        function ov = makeOverlayEntry(app, fullFilePath)
            % Read candidate overlay
            movInfo = niftiinfo(fullFilePath);
            movVol  = niftiread(movInfo);

            % Mask check
            vals   = unique(movVol(isfinite(movVol)));
            isMask = numel(vals) <= 2 && all(ismember(vals,[0 1]));

            % Register to base using the SAME function as first overlay
            interpMethod = app.InterpolationDropDown.Value;
            if isMask, interpMethod = 'nearest'; end
            baseInfo = app.niftiInfoBase;
            baseVol  = app.dataImage;
            aligned  = overlay_to_base(baseVol, baseInfo, movVol, movInfo, interpMethod);
            aligned(aligned==0) = NaN;

            % Optional rescale (match your first overlay logic)
            rescaled = false;
            aMax = max(aligned(:),[],'omitnan'); aMin = min(aligned(:),[],'omitnan');
            if isfinite(aMax) && isfinite(aMin) && aMax > 1e5
                aligned  = 1000 * (aligned - aMin) / max(aMax - aMin, eps);
                rescaled = true;
            end

            % Histogram & limits
            [hc, he] = app.computeHistogram(aligned);
            vec = aligned(:); vec = vec(isfinite(vec));
            if isempty(vec), vec = 0; end
            oMinTrue = double(round(min(vec),2));
            oMaxTrue = double(round(max(vec),2));

            sliderVal = (oMaxTrue>oMinTrue) * [0.01 0.8] + (oMaxTrue<=oMinTrue) * [0 1];

            % Threshold defaults: symmetric for bipolar
            if oMinTrue < 0 && oMaxTrue > 0
                mA = max(abs(oMinTrue), abs(oMaxTrue));
                thrVal = [max(-mA,oMinTrue), min(mA,oMaxTrue)];
                if thrVal(2) <= thrVal(1), thrVal = [oMinTrue oMaxTrue]; end
            else
                thrVal = (oMaxTrue>oMinTrue) * [oMinTrue oMaxTrue] + (oMaxTrue<=oMinTrue) * [0 1];
            end

            % Fill template
            ov = app.getOverlayTemplate();
            [~,f,e] = fileparts(fullFilePath);
            ov.name           = [f e];
            ov.path           = fullFilePath;
            ov.rawData = movVol;    % unregistered data as loaded from disk
            ov.rawInfo = movInfo;   % niftiinfo of the raw overlay
            ov.interpMethod = app.InterpolationDropDown.Value;
            ov.data           = aligned;
            ov.source         = aligned;      % immutable copy
            ov.info           = movInfo;
            ov.isMask         = isMask;
            ov.rescaled       = rescaled;
            ov.histCounts     = hc;
            ov.histEdges      = he;
            ov.sliderUserData = [oMinTrue oMaxTrue];
            ov.sliderValue    = sliderVal;
            ov.thrLimits      = [oMinTrue oMaxTrue];
            ov.thrValue       = thrVal;

            % Seed UI state from current controls (or your preferred defaults)
            ov.uiState.cmap     = app.ColormapDropDownOverlay.Value;
            ov.uiState.flip     = app.FlipMapSwitchOverlay.Value;
            ov.uiState.alpha    = app.OverlayAlphaSpinner.Value;
            ov.uiState.contrast = app.OverlayContrastSpinner.Value;
            ov.uiState.flatten  = app.FlattenOverlaySwitch.Value;
            ov.uiState.trimOn   = false;
            ov.uiState.pctLow   = app.PctLowEditField_Overlay.Value;
            ov.uiState.pctHigh  = app.PctHighEditField_Overlay.Value;
        end


        function pushOverlayToUI(app, ov)
            % Load overlay entry into the live UI (including values/limits)
            app.overlay          = 1;
            app.overlayImage     = ov.data;
            app.sourceOverlayImage = ov.source;

            % Hist
            app.overlayHistCounts = ov.histCounts;
            app.overlayHistEdges  = ov.histEdges;

            % Slider for overlay colour scaling (normalized)
            app.OverlaySlider.UserData = ov.sliderUserData;
            app.OverlaySlider.Limits   = [0 1];
            app.OverlaySlider.Value    = ov.sliderValue;

            % Threshold (absolute)
            lims = ov.thrLimits;       v = ov.thrValue;
            if ~(lims(2)-lims(1))
                app.ThresholdSliderOverlay.Limits = [0 1];
                app.ThresholdSliderOverlay.Value  = [0 1];
                app.Overlay_low.Limits  = [0 1];
                app.Overlay_high.Limits = [0 1];
                app.Overlay_low.Value   = 0;
                app.Overlay_high.Value  = 1;
                app.OverlayLowVal.Value  = 0;
                app.OverlayHighVal.Value = 1;
            else
                app.ThresholdSliderOverlay.Limits = lims;
                app.ThresholdSliderOverlay.Value  = v;
                app.Overlay_low.Limits  = [lims(1), lims(2)-abs(0.001*lims(2))];
                app.Overlay_high.Limits = [lims(1)+abs(0.001*lims(1)), lims(2)];
                app.Overlay_low.Value   = round(v(1),2);
                app.Overlay_high.Value  = round(v(2),2);
                valsO = app.slider2val(app.OverlaySlider.Value, app.OverlaySlider.UserData);
                app.OverlayLowVal.Value  = round(valsO(1),2);
                app.OverlayHighVal.Value = round(valsO(2),2);
            end

            % UI cosmetics already handled by your first overlay loader; just ensure visible
            app.ColorbarPanelOverlay.Visible = 'on';
            app.OverlaySlider.Visible        = 'on';
            app.Overlay_low.Visible          = 'on';
            app.Overlay_high.Visible         = 'on';
            app.ThresholdSliderOverlay.Visible = 'on';
            app.OverlayAlphaSpinner.Visible  = 'on';
            app.OverlayAlphaSpinnerLabel.Visible = 'on';
            app.OverlayContrastSpinner.Visible = 'on';
            app.OverlayContrastSpinnerLabel.Visible = 'on';
            app.FlattenOverlaySwitch.Visible = 'on';
            app.FlattenOverlaySwitchLabel.Visible = 'on';
            app.ColormapDropDownOverlay.Visible = 'on';
            app.FlipMapSwitchOverlay.Visible = 'on';
            app.RenderingDropDownOverlay.Visible = 'on';
            app.RenderingDropDown_2Label.Visible = 'on';
            app.ApplySliceMaskDropDown.Items = {'All','Base','Overlay'};

            % Apply UI state (cmap/flip/contrast/alpha/flatten + trim)
            app.OverlayAlphaSpinner.Value       = ov.uiState.alpha;
            app.OverlayContrastSpinner.Value    = ov.uiState.contrast;
            if any(string(app.ColormapDropDownOverlay.Items) == string(ov.uiState.cmap))
                app.ColormapDropDownOverlay.Value = ov.uiState.cmap;
            end
            app.FlipMapSwitchOverlay.Value      = ov.uiState.flip;
            app.FlattenOverlaySwitch.Value      = ov.uiState.flatten;


            app.TrimOverlayCheckBox.Value       = logical(ov.uiState.trimOn);
            app.PctLowEditField_Overlay.Value   = ov.uiState.pctLow;
            app.PctHighEditField_Overlay.Value  = ov.uiState.pctHigh;
            if app.TrimOverlayCheckBox.Value
                app.PctLowEditField_Overlay.Visible  = "on";
                app.PctHighEditField_Overlay.Visible = "on";
                app.PctHighOverlay.Visible           = "on";
                app.PctLowOverlay.Visible            = "on";
                % Apply trim to working copy
                app.overlayImage = clipOutliers(app.sourceOverlayImage, ...
                    app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);
                [app.overlayHistCounts, app.overlayHistEdges] = app.computeHistogram(app.overlayImage);
            else
                app.PctLowEditField_Overlay.Visible  = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.PctHighOverlay.Visible           = "off";
                app.PctLowOverlay.Visible            = "off";
            end
            % After you apply other UI states...
            if any(string(app.InterpolationDropDown.Items) == string(ov.interpMethod))
                app.InterpolationDropDown.Value = ov.interpMethod;
            else
                % Fallback if user removed/renamed items
                app.InterpolationDropDown.Value = 'nearest';
            end
            % Push maps & redraw using your existing utilities
            app.updateVisualization();
            app.applyOverlayColormap();
            app.updateOverlayAlphaMap();
        end

        function ov = pullOverlayFromUI(app, ov)
            % Capture the current UI state back into the overlay entry
            ov.data            = app.overlayImage;
            ov.source          = app.sourceOverlayImage;   % unchanged unless you changed it elsewhere
            ov.histCounts      = app.overlayHistCounts;
            ov.histEdges       = app.overlayHistEdges;
            ov.sliderUserData  = app.OverlaySlider.UserData;
            ov.sliderValue     = app.OverlaySlider.Value;
            ov.thrLimits       = app.ThresholdSliderOverlay.Limits;
            ov.thrValue        = app.ThresholdSliderOverlay.Value;
            ov.uiState.alpha   = app.OverlayAlphaSpinner.Value;
            ov.uiState.contrast= app.OverlayContrastSpinner.Value;
            ov.uiState.cmap    = app.ColormapDropDownOverlay.Value;
            ov.uiState.flip    = app.FlipMapSwitchOverlay.Value;
            ov.uiState.flatten = app.FlattenOverlaySwitch.Value;
            ov.uiState.trimOn  = app.TrimOverlayCheckBox.Value;
            ov.uiState.pctLow  = app.PctLowEditField_Overlay.Value;
            ov.uiState.pctHigh = app.PctHighEditField_Overlay.Value;
        end

        function switchOverlayByName(app, name)
            % Save current, then activate requested
            if isempty(app.overlayBank), return; end
            if ~isempty(app.currentOverlayName)
                idx = find(strcmp({app.overlayBank.name}, app.currentOverlayName), 1);
                if ~isempty(idx)
                    app.overlayBank(idx) = pullOverlayFromUI(app, app.overlayBank(idx));
                end
            end
            idx = find(strcmp({app.overlayBank.name}, name), 1);
            if isempty(idx), return; end
            app.currentOverlayName = name;
            pushOverlayToUI(app, app.overlayBank(idx));
        end

        function ensureOverlayInDropdown(app, name, selectIt)
            % Keep Items unique and ordered; optionally select the item
            items = string(app.OverlayDropDown.Items);
            items = items(items ~= "");    % remove empties
            if ~any(items == string(name))
                items(end+1) = string(name);
                app.OverlayDropDown.Items = cellstr(items);
            end
            if nargin>2 && selectIt
                app.OverlayDropDown.Value = char(name);
            end
        end

        function registerOverlayFirstLoad(app, fullFilePath, movVol, movInfo)
            [~,f,e] = fileparts(fullFilePath);
            name = [f e];

            % Build from template using CURRENT UI & data (already aligned)
            ov = app.getOverlayTemplate();
            ov.name           = name;
            ov.path           = fullFilePath;
            ov.rawData = movVol;    % unregistered data as loaded from disk
            ov.rawInfo = movInfo;   % niftiinfo of the raw overlay
            ov.interpMethod = app.InterpolationDropDown.Value;
            ov.data           = app.overlayImage;
            ov.source         = app.sourceOverlayImage;
            ov.info           = [];  % optional (you can store your ovInfo if available)
            % Mask check
            vals = unique(ov.source(isfinite(ov.source)));
            ov.isMask         = numel(vals) <= 2 && all(ismember(vals,[0 1]));
            ov.rescaled       = false;

            ov.histCounts     = app.overlayHistCounts;
            ov.histEdges      = app.overlayHistEdges;
            ov.sliderUserData = app.OverlaySlider.UserData;
            ov.sliderValue    = app.OverlaySlider.Value;
            ov.thrLimits      = app.ThresholdSliderOverlay.Limits;
            ov.thrValue       = app.ThresholdSliderOverlay.Value;

            ov.uiState.alpha    = app.OverlayAlphaSpinner.Value;
            ov.uiState.contrast = app.OverlayContrastSpinner.Value;
            ov.uiState.cmap     = app.ColormapDropDownOverlay.Value;
            ov.uiState.flip     = app.FlipMapSwitchOverlay.Value;
            ov.uiState.flatten  = app.FlattenOverlaySwitch.Value;
            ov.uiState.trimOn   = app.TrimOverlayCheckBox.Value;
            ov.uiState.pctLow   = app.PctLowEditField_Overlay.Value;
            ov.uiState.pctHigh  = app.PctHighEditField_Overlay.Value;

            % Insert as first entry (no mismatch now)
            if isempty(app.overlayBank)
                app.overlayBank = ov;
            else
                app.overlayBank(end+1) = ov;
            end
            app.currentOverlayName = name;

            % Dropdown should only have THIS one item now
            app.OverlayDropDown.Items = {name};
            app.OverlayDropDown.Value = name;
        end


        function ov = getOverlayTemplate(app) %#ok<INUSD>
            ov = struct( ...
                'name',           '', ...
                'path',           '', ...
                'data',           [], ...
                'source',         [], ...
                'info',           [], ...
                'isMask',         false, ...
                'rescaled',       false, ...
                'histCounts',     [], ...
                'histEdges',      [], ...
                'sliderUserData', [0 1], ...
                'sliderValue',    [0 1], ...
                'thrLimits',      [0 1], ...
                'thrValue',       [0 1], ...
                'uiState', struct( ...
                'alpha',    1, ...
                'contrast', 1, ...
                'cmap',     '', ...
                'flip',     'off', ...
                'flatten',  'off', ...
                'trimOn',   false, ...
                'pctLow',   0, ...
                'pctHigh',  100));
        end

        function copyMainCameraToViewer(app, v)
            % Copy camera from app.viewer to viewer3d handle v (if valid).
            if isempty(app.viewer) || ~isvalid(app.viewer) || isempty(v) || ~isvalid(v)
                return;
            end

            liveV = app.viewer;

            if isprop(liveV,'Projection')     && isprop(v,'Projection'),      v.Projection      = liveV.Projection;      end
            if isprop(liveV,'CameraPosition') && isprop(v,'CameraPosition'),  v.CameraPosition  = liveV.CameraPosition;  end
            if isprop(liveV,'CameraTarget')   && isprop(v,'CameraTarget'),    v.CameraTarget    = liveV.CameraTarget;    end
            if isprop(liveV,'CameraUpVector') && isprop(v,'CameraUpVector'),  v.CameraUpVector  = liveV.CameraUpVector;  end
            if isprop(liveV,'CameraViewAngle')&& isprop(v,'CameraViewAngle'), v.CameraViewAngle = liveV.CameraViewAngle; end
            if isprop(liveV,'CameraZoom')     && isprop(v,'CameraZoom'),      v.CameraZoom      = liveV.CameraZoom;      end

            if isprop(v,'CameraPositionMode'),  v.CameraPositionMode  = 'manual'; end
            if isprop(v,'CameraTargetMode'),    v.CameraTargetMode    = 'manual'; end
            if isprop(v,'CameraUpVectorMode'),  v.CameraUpVectorMode  = 'manual'; end
            if isprop(v,'CameraViewAngleMode'), v.CameraViewAngleMode = 'manual'; end
            if isprop(v,'CameraZoomMode'),      v.CameraZoomMode      = 'manual'; end
        end

        function syncSpawnedViewerCamera(app)
            % Sync the spawned viewer's camera with the main viewer (if it exists).
            if isempty(app.spawnedViewers) || ~isvalid(app.spawnedViewers(1))
                return;
            end
            v = app.spawnedViewers(1);
            app.copyMainCameraToViewer(v);
        end

        function closeSpawnedViewer(app, fig)
            % Close the spawned viewer and clear all associated handles.

            if nargin < 2 || isempty(fig)
                % Fallback: try to use stored handle
                if ~isempty(app.spawnedViewerFigs) && isvalid(app.spawnedViewerFigs(1))
                    fig = app.spawnedViewerFigs(1);
                else
                    return;
                end
            end

            % Delete figure and viewer if valid
            try
                if ~isempty(app.spawnedViewers) && isvalid(app.spawnedViewers(1))
                    delete(app.spawnedViewers(1));
                end
            catch
            end

            try
                if isvalid(fig)
                    delete(fig);
                end
            catch
            end

            % Clear bookkeeping
            app.spawnedViewerFigs  = [];
            app.spawnedViewers     = [];
            app.spawnedVolHandles  = {};
        end

        function exportSpawnedFrame(app)
            if isempty(app.spawnedViewers) || ~isvalid(app.spawnedViewers(1)) || ...
                    isempty(app.spawnedVolHandles) || isempty(app.spawnedVolHandles{1}) || ~isvalid(app.spawnedVolHandles{1})
                uialert(app.UIFigure,'No spawned viewer is available to export from.','Export','Icon','warning');
                return;
            end

            oldViewer = app.viewer;
            oldVol    = app.volHandle;

            app.viewer    = app.spawnedViewers(1);
            app.volHandle = app.spawnedVolHandles{1};
            c = onCleanup(@()restoreMainHandles(app, oldViewer, oldVol));

            % Call the existing callback
            app.ExportFrameButtonPushed([]);
        end

        function exportSpawnedMovie(app)
            if isempty(app.spawnedViewers) || ~isvalid(app.spawnedViewers(1)) || ...
                    isempty(app.spawnedVolHandles) || isempty(app.spawnedVolHandles{1}) || ~isvalid(app.spawnedVolHandles{1})
                uialert(app.UIFigure,'No spawned viewer is available to export from.','Export','Icon','warning');
                return;
            end

            oldViewer = app.viewer;
            oldVol    = app.volHandle;

            app.viewer    = app.spawnedViewers(1);
            app.volHandle = app.spawnedVolHandles{1};
            c = onCleanup(@()restoreMainHandles(app, oldViewer, oldVol));

            % Call the existing callback
            app.ExportMovieButtonPushed([]);
        end

        function restoreMainHandles(app, oldViewer, oldVol)
            % Small helper so onCleanup can restore reliably
            app.viewer    = oldViewer;
            app.volHandle = oldVol;
        end

        function openLightingDialog(app)

            % Prevent duplicates
            if ~isempty(app.LightingDialog) && isvalid(app.LightingDialog)
                figure(app.LightingDialog);
                return
            end

            % ---- Compact dialog size (4 controls) ----
            dlgW = 360;
            dlgH = 185;

            % Anchor to main app window (reliable)
            app.UIFigure.Units = 'pixels';
            hostPos = app.UIFigure.Position;

            dlgX = hostPos(1) + hostPos(3) - dlgW - 12;
            dlgY = hostPos(2) + hostPos(4) - dlgH - 70;

            dlg = uifigure( ...
                'Name','Lighting', ...
                'Color','k', ...
                'Resize','off', ...
                'WindowStyle','normal', ...
                'Position',[dlgX dlgY dlgW dlgH], ...
                'CloseRequestFcn',@(s,e)app.forceLightingOff());

            app.LightingDialog = dlg;

            gl = uigridlayout(dlg,[4 2]);
            gl.RowHeight       = {'fit','fit','fit','fit'};
            gl.ColumnWidth     = {170,'1x'};
            gl.Padding         = [10 10 10 10];
            gl.RowSpacing      = 8;
            gl.ColumnSpacing   = 8;
            gl.BackgroundColor = 'k';

            % ---- Read current viewer values safely ----
            amb  = 0.35;
            diff = 0.65;

            if isprop(app.viewer,'AmbientLight')
                amb = double(app.viewer.AmbientLight);
                if ~isfinite(amb), amb = 0.35; end
                amb = min(max(amb,0),1);
            end

            if isprop(app.viewer,'DiffuseLight')
                diff = double(app.viewer.DiffuseLight);
                if ~isfinite(diff), diff = 0.65; end
                diff = min(max(diff,0),1);
            end

            col = [1 1 1];
            if isprop(app.viewer,'LightColor')
                col = app.viewer.LightColor;
                if ~isnumeric(col) || numel(col)~=3 || any(~isfinite(col))
                    col = [1 1 1];
                end
                col = double(col(:)');
            end

            % ---- LightPositionMode dropdown items (simple names) ----
            % We use EXACT underlying values for Viewer3D.LightPositionMode,
            % but present user-friendly labels.
            items = { ...
                'Auto (moves with camera)',            'auto'; ...
                'Headlight (at camera)',               'headlight'; ...
                'Right (above-right of camera)',       'right'; ...
                'Left (above-left of camera)',         'left' ...
                };

            % Determine current mode; if it isn't one of these, default to 'auto'
            curMode = 'auto';
            if isprop(app.viewer,'LightPositionMode')
                try
                    curMode = lower(char(app.viewer.LightPositionMode));
                catch
                    curMode = 'auto';
                end
            end
            allowed = items(:,2);
            if ~any(strcmp(allowed,curMode))
                curMode = 'auto';
            end

            % Select matching label as dropdown value
            idx = find(strcmp(allowed,curMode),1,'first');
            if isempty(idx), idx = 1; end
            curLabel = items{idx,1};

            % ---- Ambient ----
            uilabel(gl,'Text','Ambient (0–1)', 'FontColor','w','BackgroundColor','k');
            spAmb = uispinner(gl, ...
                'Limits',[0 1], 'Step',0.05, 'Value',amb, ...
                'FontColor','w', 'BackgroundColor',[0.15 0.15 0.15]);
            spAmb.ValueChangedFcn = @(s,e)set(app.viewer,'AmbientLight',min(max(double(s.Value),0),1));

            % ---- Diffuse ----
            uilabel(gl,'Text','Diffuse (0–1)', 'FontColor','w','BackgroundColor','k');
            spDiff = uispinner(gl, ...
                'Limits',[0 1], 'Step',0.05, 'Value',diff, ...
                'FontColor','w', 'BackgroundColor',[0.15 0.15 0.15]);
            spDiff.ValueChangedFcn = @(s,e)set(app.viewer,'DiffuseLight',min(max(double(s.Value),0),1));

            % ---- Color ----
            uilabel(gl,'Text','Color', 'FontColor','w','BackgroundColor','k');
            btnCol = uibutton(gl,'Text','Pick…', ...
                'FontColor','w', 'BackgroundColor',[0.15 0.15 0.15]);
            btnCol.ButtonPushedFcn = @(s,e)pickColor();

            % ---- Light position mode ----
            uilabel(gl,'Text','Position', 'FontColor','w','BackgroundColor','k');
            ddMode = uidropdown(gl, ...
                'Items',items(:,1), ...
                'Value',curLabel, ...
                'FontColor','w', ...
                'BackgroundColor',[0.15 0.15 0.15]);
            ddMode.ValueChangedFcn = @(s,e)setModeFromLabel(s.Value);

            % ---------------- Nested callbacks ----------------
            function pickColor()
                c = uisetcolor(col,'Select Light Color');
                if isnumeric(c) && numel(c)==3 && all(isfinite(c))
                    col = double(c(:)');
                    if isprop(app.viewer,'LightColor')
                        try
                            app.viewer.LightColor = col;
                        catch
                        end
                    end
                end
                figure(app.UIFigure);
            end

            function setModeFromLabel(lbl)
                % Map label -> underlying mode value
                j = find(strcmp(items(:,1),lbl),1,'first');
                if isempty(j), return; end
                modeVal = items{j,2};

                if isprop(app.viewer,'LightPositionMode')
                    try
                        app.viewer.LightPositionMode = modeVal;
                    catch
                        % If assignment fails, revert dropdown to current viewer mode
                        try
                            cur = lower(char(app.viewer.LightPositionMode));
                            jj = find(strcmp(allowed,cur),1,'first');
                            if isempty(jj), jj = 1; end
                            ddMode.Value = items{jj,1};
                        catch
                        end
                    end
                end
            end

        end

        function closeLightingDialog(app)
            if ~isempty(app.LightingDialog) && isvalid(app.LightingDialog)
                delete(app.LightingDialog);
            end
            app.LightingDialog = [];
        end

        function forceLightingOff(app)
            app.LightingSwitch.Value = 'off';
            app.viewer.Lighting = 'off';
            app.closeLightingDialog();
        end


        function openDenoiseDialog(app)

            % Prevent duplicates
            if ~isempty(app.DenoiseDialog) && isvalid(app.DenoiseDialog)
                figure(app.DenoiseDialog);
                return
            end

            dlgW = 300;
            dlgH = 130;

            % Anchor to main app window (reliable)
            app.UIFigure.Units = 'pixels';
            hostPos = app.UIFigure.Position;

            dlgX = hostPos(1) + hostPos(3) - dlgW - 12;
            dlgY = hostPos(2) + hostPos(4) - dlgH - 70;

            dlg = uifigure( ...
                'Name','Denoising', ...
                'Color','k', ...
                'Resize','off', ...
                'WindowStyle','normal', ...
                'Position',[dlgX dlgY dlgW dlgH], ...
                'CloseRequestFcn',@(s,e)app.forceDenoiseOff());

            app.DenoiseDialog = dlg;

            gl = uigridlayout(dlg,[2 2]);
            gl.RowHeight       = {'fit','fit'};
            gl.ColumnWidth     = {170,'1x'};
            gl.Padding         = [10 10 10 10];
            gl.RowSpacing      = 8;
            gl.ColumnSpacing   = 8;
            gl.BackgroundColor = 'k';

            % Read current values safely
            deg = double(app.viewer.DenoisingDegreeOfSmoothing);
            if ~isfinite(deg), deg = 0.5; end
            deg = min(max(deg,0),1);

            sig = double(app.viewer.DenoisingSigma);
            if ~isfinite(sig), sig = 1; end
            sig = min(max(sig,1),10);
            sig = round(sig);

            % ---- Degree of smoothing (0..1) ----
            uilabel(gl,'Text','Degree of smoothing (0–1)', ...
                'FontColor','w','BackgroundColor','k');

            spDeg = uispinner(gl, ...
                'Limits',[0 1], ...
                'Step',0.05, ...
                'Value',deg, ...
                'FontColor','w', ...
                'BackgroundColor',[0.15 0.15 0.15]);

            spDeg.ValueChangedFcn = @(s,e)set(app.viewer, ...
                'DenoisingDegreeOfSmoothing', min(max(double(s.Value),0),1));

            % ---- Sigma (1..10, integer) ----
            uilabel(gl,'Text','Sigma (1–10)', ...
                'FontColor','w','BackgroundColor','k');

            spSig = uispinner(gl, ...
                'Limits',[1 10], ...
                'Step',1, ...
                'Value',sig, ...
                'FontColor','w', ...
                'BackgroundColor',[0.15 0.15 0.15]);

            spSig.ValueChangedFcn = @(s,e)set(app.viewer, ...
                'DenoisingSigma', max(1, round(double(s.Value))));
        end

        function closeDenoiseDialog(app)
            if ~isempty(app.DenoiseDialog) && isvalid(app.DenoiseDialog)
                delete(app.DenoiseDialog);
            end
            app.DenoiseDialog = [];
        end

        function forceDenoiseOff(app)
            app.DenoisingSwitch.Value = 'off';
            app.viewer.Denoising = 'off';
            app.closeDenoiseDialog();
        end

        function applyAppearanceToViewer(app, targetV)
            % Apply Lighting + Denoising (and their tunables) from the main viewer/UI
            % to another viewer3d instance (spawn/temp export/movie viewer).

            if isempty(app.viewer) || ~isvalid(app.viewer) || isempty(targetV) || ~isvalid(targetV)
                return;
            end

            liveV = app.viewer;

            % ---- Lighting: respect switch, then copy tunables if available ----
            if isprop(targetV,'Lighting') && isprop(app,'LightingSwitch')
                targetV.Lighting = app.LightingSwitch.Value;   % 'on'/'off'
            end

            if strcmp(app.LightingSwitch.Value,'on')
                % copy “lighting tuning” only when lighting is enabled
                pList = {'AmbientLight','DiffuseLight','LightColor','LightPositionMode'};
                for i = 1:numel(pList)
                    p = pList{i};
                    if isprop(liveV,p) && isprop(targetV,p)
                        targetV.(p) = liveV.(p);
                    end
                end
            end

            % ---- Denoising: respect switch, then copy tunables if available ----
            if isprop(targetV,'Denoising') && isprop(app,'DenoisingSwitch')
                targetV.Denoising = app.DenoisingSwitch.Value; % 'on'/'off'
            end

            if strcmp(app.DenoisingSwitch.Value,'on')
                if isprop(liveV,'DenoisingSigma') && isprop(targetV,'DenoisingSigma')
                    targetV.DenoisingSigma = liveV.DenoisingSigma;
                end
                if isprop(liveV,'DenoisingDegreeOfSmoothing') && isprop(targetV,'DenoisingDegreeOfSmoothing')
                    targetV.DenoisingDegreeOfSmoothing = liveV.DenoisingDegreeOfSmoothing;
                end
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Suppress all warnings
            warning('off','all');
            app.UIFigure.Resize = 'off';
            app.UIFigure.AutoResizeChildren = 'off';
            app.UIFigure.SizeChangedFcn = [];   % remove the callback to silence the warning

            % Get the current MATLAB version
            v = ver('MATLAB');
            verStr = v.Version;  % e.g., '9.15'

            % Get the release name
            release = version('-release');  % e.g., '2024a'

            % Parse release year and letter
            releaseYear = str2double(regexp(release, '\d+', 'match', 'once'));
            releaseHalf = regexp(release, '[ab]', 'match', 'once');

            % Convert 'a'/'b' to numeric for comparison: 'a' = 0, 'b' = 1
            if strcmp(releaseHalf, 'a')
                releaseHalfNum = 0;
            elseif strcmp(releaseHalf, 'b')
                releaseHalfNum = 1;
            else
                error('Unrecognized MATLAB release format.');
            end

            % Define threshold version: 2024a
            targetYear = 2024;
            targetHalfNum = 0;

            % Check if current version is later than 2024a
            app.version = (releaseYear > targetYear) || ...
                (releaseYear == targetYear && releaseHalfNum > targetHalfNum);

            datadir = pwd;
            cd(datadir);

            % Window title
            app.UIFigure.Name = 'seeVieweR';
            % Lock the app window and disable auto-resize so no SizeChangedFcn is needed.

            % Create Colorbar Panel
            viewerPos = app.VisualizationPanel.Position;
            colorbarX = viewerPos(1) + viewerPos(3) + 10;
            colorbarY = viewerPos(2);
            colorbarW = 40;
            colorbarH = viewerPos(4);
            app.ColorbarPanel = uipanel(app.UIFigure);
            app.ColorbarPanel.Position = [colorbarX, colorbarY, colorbarW, colorbarH];

            % fix wrong CloseRequestFcn that Designer wrote accidentally
            app.UIFigure.CloseRequestFcn = @app.UIFigureCloseRequest;

            app.viewer = viewer3d(Parent=app.VisualizationPanel);
            app.viewer.RenderingQuality = 'high';
            app.viewer.ScaleBar = 'off';
            app.UIFigure.Resize = 'off';
            BackgroundDropDownValueChanged(app);
            BaseAlphaSpinnerValueChanged(app);

            % ------------ BASE colour-bar -------------------------------------------
            app.hBaseCB_Axes  = axes('Parent',app.ColorbarPanel, ...
                'Units','normalized','Position',[0 0 1 1], ...
                'Visible','off','YDir','normal');
            set(app.hBaseCB_Axes,'Tag','BaseCB');
            app.hBaseCB_Image = image(app.hBaseCB_Axes,'CData',[]);
            axis(app.hBaseCB_Axes,'tight');

            app.colmap = app.getCustomColormap(app.ColormapDropDown.Value);
            app.viewer.Denoising = app.DenoisingSwitch.Value;
            app.viewer.Lighting = app.LightingSwitch.Value;
            app.OverlayDropDown.Items = {'(no overlays yet)'};
            app.OverlayDropDown.Value = '(no overlays yet)';

            % ---- Lighting defaults (only once) ----
            %app.viewer.Lights            = 'on';
            app.viewer.AmbientLight      = 0.35;
            app.viewer.DiffuseLight      = 0.65;
            app.viewer.LightColor        = [1 1 1];
            app.viewer.LightPositionMode = 'auto';

            % ---- Smoothing defaults (only once) ----
            app.viewer.DenoisingDegreeOfSmoothing = 0.5;
            app.viewer.DenoisingSigma = 1.0;

        end

        % Button pushed function: LoadBaseButton
        function LoadBaseButtonPushed(app, event)

            if ~isempty(app.viewer); delete(app.viewer); end

            d = uiprogressdlg(app.UIFigure, ...
                'Title',   'Loading base volume', ...
                'Message', 'Initializing viewer...', ...
                'Indeterminate', 'off', ...
                'Cancelable',    'off', ...
                'Value', 0.02);

            % setup viewer
            app.viewer = viewer3d(Parent=app.VisualizationPanel);
            app.viewer.RenderingQuality = 'high';
            app.viewer.ScaleBar = 'off';
            app.viewer.Denoising = app.DenoisingSwitch.Value;
            app.viewer.Lighting = app.LightingSwitch.Value;
            app.UIFigure.Resize = 'off';
            app.WBmask = [];
            app.BaseVisibilityMask    = [];
            app.OverlayVisibilityMask = [];
            app.atlasImage = [];
            app.masks      = struct();   % or []
            app.existMasks = 0;

            if isprop(app,'TerritoryDropDown')
                app.TerritoryDropDown.Value   = 'ALL';
                app.TerritoryDropDown.Visible = 'off';
                app.TerritoryDropDownLabel.Visible = 'off';
            end

            % --- Reset viewer if overlay exists
            if app.overlay
                app.overlay = 0;
                app.OverlayMask = [];
                app.WBmask = [];
                % Reset multi-overlay state
                app.overlayBank = [];
                app.currentOverlayName = '';
                app.overlayPath = '';
                app.OverlayDropDown.Items = {'(no overlays yet)'};
                app.OverlayDropDown.Value = '(no overlays yet)';

                app.ColorbarPanelOverlay.Visible = 'off';
                app.FlipMapSwitchOverlay.Visible = 'off';
                app.ColormapDropDownOverlay.Visible = "off";
                app.FlipMapSwitch_2Label.Visible = 'off';
                app.OverlayMapLabel.Visible = 'off';
                app.ThresholdSliderOverlay.Visible = 'off';
                app.RenderingDropDownOverlay.Visible = "off";
                app.RenderingDropDown_2Label.Visible = "off";
                app.OverlaySlider.Visible = 'off';
                app.OverlayAlphaSpinner.Visible = 'off';
                app.OverlayAlphaSpinnerLabel.Visible = 'off';
                app.ThresholdSliderOverlay.Visible = "off";
                app.OverlayThresholdLabel.Visible = "off";
                app.Overlay_low.Visible = 'off';
                app.Overlay_high.Visible = 'off';
                app.ApplySliceMaskDropDown.Value = 'All';
                app.ApplySliceMaskDropDown.Items = {'All'};
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.TrimOverlayCheckBox.Visible = "off";
                app.OverlayHighVal.Visible = "off";
                app.OverlayLowVal.Visible = "off";
                app.BaseIsMask = 0;
                app.OverlayIsMask = 0;
                app.LoadBaseMaskButton.Visible = "off";
                app.GenerateFigureButton.Visible = "off";
                app.TerritoryDropDown.Visible = "off";
                app.ErodeBaseImageCheckBox.Visible = "off";
                app.ApplyBaseMaskSwitch.Visible = 'off';
                app.FlipMapSwitch_2Label_2.Visible = 'off';
                app.ApplyOverlayMaskSwitch.Visible = 'off';
                app.LoadOverlayMaskButton.Visible = "off";
                app.ApplyOverlayMaskSwitchLabel.Visible = "off";
                app.BaseVisibilityMask    = [];
                app.OverlayVisibilityMask = [];
                app.OverlayContrastSpinner.Visible = "off";
                app.OverlayContrastSpinnerLabel.Visible = "off";
                app.FlattenOverlaySwitch.Visible = "off";
                app.FlattenOverlaySwitchLabel.Visible = "off";
                app.OverlayDropDownLabel.Visible = "off";
                app.OverlayDropDown.Visible = "off";
                app.AddOverlayButton.Visible = 'off';
                app.InterpolationDropDown.Visible = "off";
                app.ExportNIfTIButton.Visible = "off";
                app.SpawnViewerButton.Visible = "off";
            end

            BackgroundDropDownValueChanged(app);

            %----------------------------------------------------------
            % Read NIfTI
            %----------------------------------------------------------

            d.Value   = 0.10;
            d.Message = 'Reading base NIfTI...';

            [file, path] = uigetfile({'*.nii;*.nii.gz'}, 'Select base image');
            if isequal(file,0),  return;  end

            niftiPath          = fullfile(path,file);
            baseInfo           = niftiinfo(niftiPath);
            rawBase            = double(niftiread(baseInfo));        % 3-D or 4-D
            rawBase(rawBase==0)= NaN;                                % keep NaNs
            app.niftiInfoBase  = baseInfo;
            app.voxelSize      = baseInfo.PixelDimensions;
            app.basePath       = niftiPath;
            sx = app.voxelSize(2);  sy = app.voxelSize(1);  sz = app.voxelSize(3);
            app.tform = affinetform3d([sx 0 0 0; 0 sy 0 0; 0 0 sz 0; 0 0 0 1]);

            %----------------------------------------------------------
            % Check mask / rescale
            %----------------------------------------------------------

            d.Value   = 0.22;
            d.Message = 'Checking base type & scaling...';

            vals = unique(rawBase(isfinite(rawBase)));   % strip NaN / Inf, get unique values
            isMask = numel(vals) <= 2 && all(ismember(vals,[0 1]));   % true ⇔ only 0/1
            app.BaseIsMask = isMask;

            rescaleBase = false;
            if max(rawBase(:)) > 4e4
                rescaleBase = true;
                disp('Input image has large intensity range. Rescaling to [0, 1000] for stability.');
                rawBase = 1000 * (rawBase - min(rawBase(:))) / ...
                    (max(rawBase(:)) - min(rawBase(:)));
                disp('... possibly anatomic image, setting visualization parameters');
                app.ColormapDropDown.Value = 'grayC';
            end

            %----------------------------------------------------------
            % 3D / 4D volume handling
            %----------------------------------------------------------
            d.Value   = 0.35;
            d.Message = 'Configuring 3D/4D volume...';

            if ndims(rawBase)==4 && size(rawBase,4) > 1          % ========== 4-D ==========
                app.nBaseFrames      = size(rawBase,4);
                app.currentBaseFrame = size(rawBase,4);

                app.sourceDataImage  = rawBase;          % keep full series
                app.dataImage        = rawBase(:,:,:,end); % show last frame

                % ----- slider set-up -------------------------------------------------
                app.VolumeSlider.Limits  = [1 app.nBaseFrames];
                app.VolumeSlider.Value   = 1;

                midIdx = ceil(app.nBaseFrames/2);        % first | middle | last ticks
                app.VolumeSlider.MajorTicks      = [1 app.nBaseFrames];
                app.VolumeSlider.MajorTickLabels = { '1', num2str(app.nBaseFrames) };

                app.VolumeSlider.Visible = 'on';
                app.VolumeVal.Visible    = 'on';
                app.VolumeSliderLabel.Visible = "on";
                app.VolumeSlider.Limits = [1 size(rawBase,4)];
                % ----- numeric read-out ---------------------------------------------
                app.VolumeVal.Limits  = [1 app.nBaseFrames];
                app.VolumeVal.Value   = 1;
                app.VolumeVal.Visible = 'on';
            else                                                % ========== 3-D ==========
                app.nBaseFrames      = 1;
                app.currentBaseFrame = 1;

                app.sourceDataImage  = rawBase;
                app.dataImage        = rawBase;

                app.VolumeSlider.Visible = 'off';
                app.VolumeVal.Visible    = 'off';
                app.VolumeSliderLabel.Visible = "off";
            end

            % ---------- initial histogram for the displayed frame ---------------
            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);

            % Binary vessel mask from original data
            vesselBinary = app.dataImage > 0;

            % Generate 3D skeleton - this is assuming input is MRA but can
            % be removed in future versions
            if size(vesselBinary, ndims(vesselBinary)) > 1
                vesselBinary = squeeze(vesselBinary(:,:,:,end));
                app.vesselSkeleton = bwskel(vesselBinary);
            end

            cd(path);
            app.path = path;

            %----------------------------------------------------------
            % Base slider + thresholds
            %----------------------------------------------------------
            d.Value   = 0.57;
            d.Message = 'Setting intensity & threshold sliders...';

            % Setup OverlaySlider (used for visual scaling of overlay colors)
            baseData = app.dataImage(:);
            baseData = baseData(~isnan(baseData) & ~isinf(baseData));  % remove NaN/Inf

            minBase = min(baseData);
            maxBase = max(baseData);

            if minBase == maxBase
                minBase = minBase - 1;
                %maxBase = maxBase + 1;
            end

            app.BaseSlider.UserData = [minBase, maxBase];
            app.BaseSlider.Limits = [0, 1];

            if rescaleBase
                rescaledValid = rawBase(~isnan(rawBase) & ~isinf(rawBase));
                nbins = 1000;
                [counts, edges] = histcounts(rescaledValid, nbins);
                % Step 3: Find high-density region using cumulative sum
                cdf = cumsum(counts) / sum(counts);
                % Define adaptive clipping thresholds (e.g., keep 99% of mass)
                lowerIdx = find(cdf >= 0.005, 1, 'first'); % first bin above 0.5%
                upperIdx = find(cdf >= 0.995, 1, 'first'); % first bin above 99.5%
                % Convert bin indices to intensity values
                lowerBound = edges(lowerIdx);
                upperBound = edges(min(upperIdx, length(edges)-1));  % avoid out-of-bounds
                % Set normalized initial values (0–1 range)
                app.BaseSlider.Value = [lowerBound/maxBase upperBound/maxBase];
            else
                % Set the actual min/max for slider (rescaled to 0–1)
                app.BaseSlider.Limits = [0 1];

                % Set initial overlay visualization range (10% to 80%)
                baseMinVal = minBase + 0.01 * (maxBase - minBase);
                baseMaxVal = minBase + 0.6 * (maxBase - minBase);

                % Set normalized initial values (0–1 range)
                app.BaseSlider.Value = [(baseMinVal - minBase) / (maxBase - minBase), ...
                    (baseMaxVal - minBase) / (maxBase - minBase)];
            end

            %% Threshold Slider
            validVox          = isfinite(app.dataImage);        % no NaN / Inf
            trueMin           = double(round(min(app.dataImage(validVox)),2));
            trueMax           = double(round(max(app.dataImage(validVox)),2));

            if ~(trueMax-trueMin)
                app.ThresholdSlider.Limits = [0, 1];
                app.ThresholdSlider.Value = [0, 1];
                app.Base_low.Limits = [0, 1];
                app.Base_high.Limits =  [0, 1];
            else
                app.ThresholdSlider.Limits = [trueMin , trueMax];
                app.ThresholdSlider.Value = [trueMin + abs(0.001*trueMin) , trueMax-0.001*trueMax];
                app.Base_low.Limits = [trueMin, trueMax-0.001*trueMax];
                app.Base_high.Limits = [trueMin+0.001*trueMin, trueMax];
            end

            app.ThresholdSlider.Visible = 'on';

            %set value boxes
            app.Base_low.Value = app.ThresholdSlider.Value(1);
            app.Base_high.Value = app.ThresholdSlider.Value(2);

            rng    = app.BaseSlider.UserData;               % [trueMin trueMax]
            vals   = slider2val(app, app.BaseSlider.Value, rng);
            app.BaseLowVal.Value = vals(1);
            app.BaseHighVal.Value = vals(2);

            %----------------------------------------------------------
            % Load masks
            %----------------------------------------------------------

            d.Value   = 0.70;
            d.Message = 'Loading masks (if available)...';
            %% Load arterial atlas mask - this was introduced specifically for data registered using the seeVR toolbox functions
            atlasPath = fullfile(path, 'structToMNI', 'Inverse', 'ArterialAtlas_level2_toInput.nii.gz');
            if exist(atlasPath, 'file')
                atlas = niftiread(atlasPath);
                app.atlasImage = double(atlas);

                territoryIndices = struct( ...
                    'Anterior_L', [1, 3], ...
                    'Anterior_R', [2, 4], ...
                    'PCA',        [5, 6], ...
                    'VB',         [7, 8], ...
                    'Ventricles', [9, 10] ...
                    );

                masks = struct();
                for name = fieldnames(territoryIndices)'
                    masks.(name{1}) = ismember(app.atlasImage, territoryIndices.(name{1}));
                end
                app.masks = masks;
                app.TerritoryDropDown.Visible = 'on';
                app.TerritoryDropDownLabel.Visible = 'on';
                app.existMasks = 1;
                % set slice mask options
            end

            %% Load brain mask
            try
                brainMaskFile = ls(fullfile(path, '*-brain_mask.nii*'));
            catch
            end
            try
                brainMaskFile = ls(fullfile(path, '*_brain_mask.nii*'));
            catch
            end
            try
                if exist(fullfile(path, brainMaskFile), 'file')
                    WBmask = niftiread(fullfile(path, brainMaskFile));
                    % --- check dimension consistency ---------------------------------
                    if isequal(size(WBmask), size(app.dataImage(:,:,:,1)))
                        % exact match
                        app.WBmask = double(WBmask);
                        app.ApplyBaseMaskSwitch.Visible = 'on';
                        app.FlipMapSwitch_2Label_2.Visible = 'on';
                        %initially set overlay mask to be the same as base
                        app.OverlayMask = app.WBmask;
                    else
                        % mismatch: handle gracefully
                        warning('Brain mask dimensions [%s] do not match base image [%s].',...
                            num2str(size(WBmask)), num2str(size(app.dataImage(:,:,:,1))));
                        app.WBmask = [];  % reset to empty so it won’t be applied
                        app.ApplyBaseMaskSwitch.Value = 'off';
                        app.ApplyBaseMaskSwitch.Visible = 'off';
                        app.ApplyOverlayMaskSwitch.Visible = 'off';
                    end
                end
            catch ME
                disp('Did not find a brain mask to load');
                disp('... if you want to add one, include a file with *_brain_mask.nii* in the same folder as the base image');
                disp('e.g. sourceData_brain_mask.nii or .nii.gz');
                disp('... alternatively, load using the load mask push button');
                disp(getReport(ME,'basic'));
            end

            app.LoadBaseMaskButton.Visible = "on";

            %----------------------------------------------------------
            % Slice sliders + UI visibility
            %----------------------------------------------------------
            d.Value   = 0.82;
            d.Message = 'Configuring slice sliders & UI...';

            volSize = size(app.dataImage);

            % Set slider limits to normalized range [0, 1]
            app.ORI1Slider.Limits = [1 volSize(2)];
            app.ORI2Slider.Limits = [1 volSize(1)];
            app.ORI3Slider.Limits = [1 volSize(3)];

            % Set full range (to initially show entire volume)
            app.ORI1Slider.Value = [1 volSize(2)];
            app.ORI2Slider.Value = [1 volSize(1)];
            app.ORI3Slider.Value = [1 volSize(3)];

            % Store volume size for later slicing use
            app.ORI1Slider.UserData = volSize(2);  % X = columns
            app.ORI2Slider.UserData = volSize(1);  % Y = rows
            app.ORI3Slider.UserData = volSize(3);  % Z = slices

            app.ORI1_low.Limits = [1 volSize(2)-1];
            app.ORI2_low.Limits = [1 volSize(1)-1];
            app.ORI3_low.Limits = [1 volSize(3)-1];

            app.ORI1_high.Limits = [2 volSize(2)];
            app.ORI2_high.Limits = [2 volSize(1)];
            app.ORI3_high.Limits = [2 volSize(3)];

            app.ORI1_low.Value = 1; app.ORI1_high.Value = volSize(2);
            app.ORI2_low.Value = 1; app.ORI2_high.Value = volSize(1);
            app.ORI3_low.Value = 1; app.ORI3_high.Value = volSize(3);

            %% UI Visibility
            app.BaseSlider.Visible = "on";
            app.BaseSlider.Position(2) = app.VisualizationPanel.Position(2);
            app.BaseSlider.Position(4) = app.VisualizationPanel.Position(4);
            app.ColorbarPanel.Visible = 'on';
            app.ThresholdSlider.Visible = 'on';
            app.BaseThresholdLabel.Visible = 'on';
            app.Base_low.Visible = "on";
            app.Base_high.Visible = "on";
            app.ExportFrameButton.Visible = 'on';
            app.LoadOverlayButton.Visible = 'on';
            app.ORI1Slider.Visible = 'on';
            app.ORI1SliderLabel.Visible = "on";
            app.ORI2Slider.Visible = 'on';
            app.ORI2SliderLabel.Visible = "on";
            app.ORI3Slider.Visible = 'on';
            app.ORI3SliderLabel.Visible = "on";
            app.BaseAlphaSpinner.Visible = 'on';
            app.BaseAlphaSpinnerLabel.Visible = 'on';
            app.TrimBaseCheckBox.Visible = "on";
            app.BaseHighVal.Visible = "on";
            app.BaseLowVal.Visible = "on";
            app.LoadStateButton.Visible = "on";
            app.SaveStateButton.Visible = "on";
            app.ExportFrameButton.Visible = "on";
            app.ExportMovieButton.Visible = "on";
            app.GenerateFigureButton.Visible = "on";
            app.ErodeBaseImageCheckBox.Visible = "on";
            app.ExportNIfTIButton.Visible = "on";
            app.SpawnViewerButton.Visible = "on";

            %----------------------------------------------------------
            % Create volshow & first draw
            %----------------------------------------------------------
            d.Value   = 0.92;
            d.Message = 'Rendering volume...';

            volSize = size(app.dataImage);
            centerVoxel = [volSize(2), volSize(1), volSize(3)] / 2;
            camTarget = centerVoxel .* [app.voxelSize(2), app.voxelSize(1), app.voxelSize(3)];

            app.viewer.CameraPosition = camTarget + [300 0 0];
            app.viewer.CameraTarget = camTarget;
            app.viewer.CameraUpVector = [0 0 1];
            app.viewer.CameraZoom = 1.5;
            dummyVol = zeros(size(rawBase,1:3));
            app.volHandle = volshow(dummyVol, ...
                Parent=app.viewer, ...
                Colormap=gray(256), ...
                Alphamap=linspace(0,1,256), ...
                RenderingStyle='CinematicRendering');
            app.updateVisualization();   % draw volume data/range/masks
            app.applyBaseColormap();     % set color (colormap + flip + contrast)
            app.updateBaseAlphaMap();    % set alpha per FlattenAlphaSwitch

            d.Value   = 1.0;
            d.Message = 'Done.';
            pause(0.05);   % tiny pause so user can see "Done"
            figure(app.UIFigure);
        end

        % Button pushed function: LoadOverlayButton
        function LoadOverlayButtonPushed(app, event)
            app.overlayImage = [];
            app.sourceOverlayImage = [];
            [file,path] = uigetfile('*.nii;*.nii.gz');
            if isequal(file,0)
                return;
            end
            d = [];
            d = uiprogressdlg(app.UIFigure, ...
                'Title',        'Loading overlay volume', ...
                'Message',      'Reading overlay header...', ...
                'Indeterminate','off', ...
                'Cancelable',   'off', ...
                'Value',        0.05);

            baseInfo = app.niftiInfoBase;
            baseVol  = app.dataImage;

            % --- Read overlay header + data --------------------------------
            d.Value   = 0.15;
            d.Message = 'Reading overlay NIfTI...';

            movInfo  = niftiinfo(fullfile(path,file)); app.overlayInfo = movInfo;
            movVol   = niftiread(movInfo);
            app.overlayPath = fullfile(path,file);

            % --- Check if mask / pick interpolation ------------------------
            d.Value   = 0.30;
            d.Message = 'Checking overlay type & interpolation...';

            vals = unique(movVol(isfinite(movVol)));   % strip NaN / Inf, get unique values
            isMask = numel(vals) <= 2 && all(ismember(vals,[0 1]));   % true ⇔ only 0/1
            app.OverlayIsMask = isMask;
            app.OverlayVisibilityMask = [];

            if  isMask
                interpMethod = 'nearest';
                app.RenderingDropDownOverlay.Value = 'LabelOverlay';
            else
                interpMethod = app.InterpolationDropDown.Value;
            end
            % can change interpolation: cubic, spline, linear, nearest
            % neighbor

            % --- Register overlay to base ----------------------------------
            d.Value   = 0.45;
            d.Message = 'Aligning overlay to base...';

            aligned = overlay_to_base(baseVol, baseInfo, movVol, movInfo, interpMethod);
            aligned(aligned == 0) = NaN;

            % --- Rescale if needed -----------------------------------------
            d.Value   = 0.55;
            d.Message = 'Adjusting overlay intensity range...';

            if max(aligned(:)) > 1e5
                disp('Input image has large intensity range. Rescaling to [0, 1000] for stability.');
                aligned = 1000 * (aligned - min(aligned(:))) / (max(aligned(:)) - min(aligned(:)));
                disp('... possibly anatomic image, setting visualization parameters')
                app.ColormapDropDownOverlay.Value = 'grayC';
                rescaleOverlay = 1;
            else
                rescaleOverlay = 0;
            end

            app.overlayImage = aligned;
            app.sourceOverlayImage = app.overlayImage; %save reference overlay

            % -------------- Calculate Histogram ----------------------------
            d.Value   = 0.65;
            d.Message = 'Computing overlay histogram...';

            [app.overlayHistCounts, app.overlayHistEdges] = computeHistogram(app, app.overlayImage);
            app.overlay = 1;

            %================================================================
            % UI + sliders + colorbar + registration bookkeeping
            %================================================================
            d.Value   = 0.75;
            d.Message = 'Configuring overlay UI...';

            try
                app.FlipMapSwitchOverlay.Visible = 'on';
                app.ColormapDropDownOverlay.Visible = "on";
                app.FlipMapSwitch_2Label.Visible = 'on';
                app.OverlayMapLabel.Visible = 'on';
                app.RenderingDropDownOverlay.Visible = "on";
                app.RenderingDropDown_2Label.Visible = "on";

                % Setup OverlaySlider (used for visual scaling of overlay colors)
                overlayData = app.overlayImage(:);
                overlayData = overlayData(~isnan(overlayData) & ~isinf(overlayData));  % remove NaN/Inf

                validVox          = isfinite(overlayData);        % no NaN / Inf
                minBase           = round(min(overlayData(validVox)),2);
                maxBase           = round(max(overlayData(validVox)),2);

                clear overlayData;

                % Store the true min/max for normalization later
                app.OverlaySlider.UserData = [minBase, maxBase];  % store for later use
                % Set the actual min/max for slider (rescaled to 0–1)
                app.OverlaySlider.Limits = [0 1];

                if rescaleOverlay
                    rescaledValid = app.overlayImage(~isnan(app.overlayImage) & ~isinf(app.overlayImage));
                    nbins = 1000;
                    [counts, edges] = histcounts(rescaledValid, nbins);
                    % Step 3: Find high-density region using cumulative sum
                    cdf = cumsum(counts) / sum(counts);
                    % Define adaptive clipping thresholds (e.g., keep 99% of mass)
                    lowerIdx = find(cdf >= 0.005, 1, 'first'); % first bin above 0.5%
                    upperIdx = find(cdf >= 0.995, 1, 'first'); % first bin above 99.5%
                    % Convert bin indices to intensity values
                    lowerBound = edges(lowerIdx);
                    upperBound = edges(min(upperIdx, length(edges)-1));  % avoid out-of-bounds
                    % Set normalized initial values (0–1 range)
                    app.OverlaySlider.Value = double([lowerBound/maxBase upperBound/maxBase]);

                else
                    % Set the actual min/max for slider (rescaled to 0–1)
                    app.OverlaySlider.Limits = [0 1];
                    % Set initial overlay visualization range (10% to 80%)
                    overlayMinVal = minBase + 0.01 * (maxBase - minBase);
                    overlayMaxVal = minBase + 0.6 * (maxBase - minBase);
                    % Set normalized initial values (0–1 range)
                    try
                        app.OverlaySlider.Value = [(overlayMinVal - minBase) / (maxBase - minBase), ...
                            (overlayMaxVal - minBase) / (maxBase - minBase)];
                    catch
                        disp('... overlay may be a mask')
                        app.OverlaySlider.Value = double([0 1]);
                    end
                end

                % Make the slider visible
                app.OverlaySlider.Visible = 'on';

                %------------ Threshold slider --------------------------------
                d.Value   = 0.80;
                d.Message = 'Setting overlay thresholds...';

                minLimit = double(round(min(app.overlayImage(:)),2));
                maxLimit = double(round(max(app.overlayImage(:)),2));
                if ~(minLimit - maxLimit)
                    app.ThresholdSliderOverlay.Limits = [0 1];
                    app.Overlay_low.Limits = [0 1];
                    app.Overlay_high.Limits = [0 1];
                else
                    app.ThresholdSliderOverlay.Limits = [minLimit, maxLimit];
                    app.Overlay_low.Limits = [minLimit, maxLimit-0.001*maxLimit];
                    app.Overlay_high.Limits = [minLimit+abs(0.001*minLimit), maxLimit];
                end
                % Set symmetric value range around zero if bipolar

                if min(app.overlayImage(:)) < 0 && max(app.overlayImage(:)) > 0
                    maxAbs = max(abs(min(app.overlayImage(:))), abs(max(app.overlayImage(:))));
                    valLow = double(max(-maxAbs, minLimit));  % Don't go below slider limit
                    valHigh = double(min(maxAbs, maxLimit));  % Don't go above slider limit
                    if ~(valHigh-valLow); valLow = 0; valHigh = 1; end
                    app.ThresholdSliderOverlay.Value = [valLow, valHigh];
                    app.ThresholdSliderOverlay.MajorTicks = [valLow, valHigh];
                    app.ThresholdSliderOverlay.MajorTickLabels = {num2str(valLow), num2str(valHigh)};
                else
                    if ~(minLimit - maxLimit)
                        app.ThresholdSliderOverlay.Value = [0, 1];
                        app.ThresholdSliderOverlay.MajorTicks = [0, 1];
                        app.ThresholdSliderOverlay.MajorTickLabels =  {num2str(0), num2str(1)};
                    else
                        app.ThresholdSliderOverlay.Value = [minLimit, maxLimit];
                        app.ThresholdSliderOverlay.MajorTicks = [minLimit, maxLimit];
                        app.ThresholdSliderOverlay.MajorTickLabels =  {num2str(minLimit), num2str(maxLimit)};
                    end
                end

                rng    = app.OverlaySlider.UserData;               % [trueMin trueMax]
                vals   = slider2val(app, app.OverlaySlider.Value, rng);

                %set value boxes
                if ~(minLimit - maxLimit)
                    app.Overlay_low.Value = 0;
                    app.Overlay_high.Value = 1;
                    app.OverlayLowVal.Value = 0;
                    app.OverlayHighVal.Value = 1;
                else
                    app.Overlay_low.Value = app.ThresholdSliderOverlay.Value(1);
                    app.Overlay_high.Value = app.ThresholdSliderOverlay.Value(2);
                    app.OverlayLowVal.Value = vals(1);
                    app.OverlayHighVal.Value = vals(2);
                end

                app.Overlay_low.Visible = 'on';
                app.Overlay_high.Visible = 'on';

                % Position of first colorbar (already defined)
                viewerPos = app.VisualizationPanel.Position;
                colorbarX = viewerPos(1) + viewerPos(3) + 10;
                colorbarY = viewerPos(2);
                colorbarW = 40;
                colorbarH = viewerPos(4);

                % Position of second colorbar (to the right of the first)
                gapBetweenBars = 20;
                overlayX = colorbarX + colorbarW + gapBetweenBars;

                if ~isempty(app.WBmask)
                    % Initial behaviour: if no overlay mask yet, mirror
                    % the base mask if it exists
                    app.OverlayMask = app.WBmask;
                    app.ApplyOverlayMaskSwitch.Visible = 'on';
                    app.ApplyOverlayMaskSwitchLabel.Visible = "on";
                end

                app.ColorbarPanelOverlay = uipanel(app.UIFigure);
                app.ColorbarPanelOverlay.Position = [overlayX, colorbarY, colorbarW, colorbarH];
                app.ColorbarPanelOverlay.BackgroundColor = [0 0 0];
                % Match OverlaySlider to the overlay colorbar position
                sliderW = 3;
                app.OverlaySlider.Position = [overlayX + colorbarW + 2, colorbarY, sliderW, colorbarH];
                app.OverlaySlider.Visible = 'on';
                app.ColorbarPanelOverlay.Visible = 'on';
                app.OverlayAlphaSpinner.Visible = 'on';
                app.OverlayAlphaSpinnerLabel.Visible = 'on';
                app.ThresholdSliderOverlay.Visible = "on";
                app.OverlayThresholdLabel.Visible = "on";
                app.RenderingDropDown_2Label.Visible = "on";
                app.ApplySliceMaskDropDown.Items = {'All','Base','Overlay'};
                app.TrimOverlayCheckBox.Visible = "on";
                app.OverlayHighVal.Visible = "on";
                app.OverlayLowVal.Visible = "on";
                app.LoadOverlayMaskButton.Visible = "on";
                app.OverlayContrastSpinner.Visible = "on";
                app.OverlayContrastSpinnerLabel.Visible = "on";
                app.FlattenOverlaySwitch.Visible = "on";
                app.FlattenOverlaySwitchLabel.Visible = "on";
                app.OverlayDropDownLabel.Visible = "on";
                app.OverlayDropDown.Visible = "on";
                app.AddOverlayButton.Visible = 'on';
                app.InterpolationDropDown.Visible = "on";

                % ------------ OVERLAY colour-bar ----------------------------------------
                app.hOvCB_Axes    = axes('Parent',app.ColorbarPanelOverlay, ...
                    'Units','normalized','Position',[0 0 1 1], ...
                    'Visible','off','YDir','normal');
                set(app.hOvCB_Axes,'Tag','OverlayCB');
                app.hOvCB_Image   = image(app.hOvCB_Axes,'CData',[]);
                axis(app.hOvCB_Axes,'tight');

                app.colmapOverlay = app.getCustomColormap(app.ColormapDropDownOverlay.Value);

                % --- Update visualization ------------------------------------
                d.Value   = 0.90;
                d.Message = 'Updating viewer...';

                app.updateVisualization();
                app.applyOverlayColormap();
                app.updateOverlayAlphaMap();
                figure(app.UIFigure);
                app.registerOverlayFirstLoad(app.overlayPath, movVol, movInfo);

                % === Build a clean display name (works for .nii and .nii.gz) ===
                [~, base, ext] = fileparts(app.overlayPath);
                overlayName = [base ext];                  % e.g. 'file.nii.gz' or 'file.nii'

                % === Update dropdown
                items = string(app.OverlayDropDown.Items);
                items = items(items ~= "(no overlays yet)");
                if ~any(items == overlayName)
                    items(end+1) = overlayName;
                end
                app.OverlayDropDown.Items = cellstr(items);
                app.OverlayDropDown.Value = overlayName;

                % Final progress ping
                d.Value   = 1.0;
                d.Message = 'Done.';
                pause(0.05);   % tiny pause so "Done" is visible

            catch
                app.ColorbarPanelOverlay.Visible = 'off';
                app.FlipMapSwitchOverlay.Visible = 'off';
                app.ColormapDropDownOverlay.Visible = "off";
                app.FlipMapSwitch_2Label.Visible = 'off';
                app.OverlayMapLabel.Visible = 'off';
                app.RenderingDropDown_2Label.Visible = "off";
                app.ThresholdSliderOverlay.Visible = 'off';
                app.RenderingDropDownOverlay.Visible = "off";
                app.OverlaySlider.Visible = 'off';
                app.OverlayAlphaSpinner.Visible = 'off';
                app.OverlayAlphaSpinnerLabel.Visible = 'off';
                app.ThresholdSliderOverlay.Visible = "off";
                app.OverlayThresholdLabel.Visible = "off";
                app.Overlay_low.Visible = 'off';
                app.Overlay_high.Visible = 'off';
                app.overlay = 0;
                app.ApplySliceMaskDropDown.Value = 'All';
                app.ApplySliceMaskDropDown.Items = {'All'};
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.TrimOverlayCheckBox.Visible = "off";
                app.OverlayHighVal.Visible = "off";
                app.OverlayLowVal.Visible = "off";
                app.LoadOverlayMaskButton.Visible = "off";
                app.ApplyOverlayMaskSwitch.Visible = "off";
                app.ApplyOverlayMaskSwitchLabel.Visible = "off";
                app.OverlayContrastSpinner.Visible = "off";
                app.OverlayContrastSpinnerLabel.Visible = "off";
                app.FlattenOverlaySwitch.Visible = "off";
                app.FlattenOverlaySwitchLabel.Visible = "off";
                app.OverlayDropDownLabel.Visible = "off";
                app.OverlayDropDown.Visible = "off";
                app.AddOverlayButton.Visible = 'off';
                app.InterpolationDropDown.Visible = "off";
                d.Value   = 1.0;
                d.Message = 'Something went wrong.';
                pause(0.05);
                error('... something went wrong!')
                close(d);
            end
            figure(app.UIFigure);

        end

        % Button pushed function: AddOverlayButton
        function AddOverlayButtonPushed(app, event)

            % ── 0) Guard: need a base volume to align overlays onto
            if isempty(app.dataImage) || isempty(app.niftiInfoBase)
                uialert(app.UIFigure,'Load a base image first.','No base','Icon','warning');
                return
            end

            % ── 1) Start directory preference:
            %     1) last overlay path, 2) base path, 3) app.path, 4) pwd
            if ~isempty(app.overlayPath)
                startDir = fileparts(app.overlayPath);
            elseif isprop(app,'basePath') && ~isempty(app.basePath)
                startDir = fileparts(app.basePath);
            elseif isprop(app,'path') && ~isempty(app.path)
                startDir = app.path;
            else
                startDir = pwd;
            end

            % ── 2) Select one or more overlays
            [files, path] = uigetfile({'*.nii;*.nii.gz'}, ...
                'Select additional overlay(s)', startDir, 'MultiSelect','on');
            if isequal(files,0), return; end
            if ischar(files), files = {files}; end

            d = [];
            try
                % ── Progress dialog for batch load ──────────────────────────────
                d = uiprogressdlg(app.UIFigure, ...
                    'Title',        'Loading additional overlays', ...
                    'Message',      'Preparing overlay list...', ...
                    'Indeterminate','off', ...
                    'Cancelable',   'off', ...
                    'Value',        0.02);

                nFiles   = numel(files);
                newNames = strings(0,1);

                % ── 3) Load + register each overlay to base (same method as first)
                for k = 1:nFiles
                    try
                        % update progress for this overlay
                        d.Value   = 0.02 + 0.90*(k-1)/max(1,(nFiles-1));
                        d.Message = sprintf('Loading overlay %d of %d...', k, nFiles);

                        app.overlayPath = fullfile(path, files{k});
                        ov = app.makeOverlayEntry(app.overlayPath);

                        % Insert into bank (schema already consistent)
                        if isempty(app.overlayBank)
                            app.overlayBank = ov;
                        else
                            app.overlayBank(end+1) = ov;
                        end

                        % === Clean display name ===
                        [~, baseName, ext] = fileparts(app.overlayPath);
                        overlayName = [baseName ext];

                        % record for later (section 4)
                        newNames(end+1,1) = overlayName;

                        % === Update dropdown (dedupe + remove placeholder) ===
                        items = string(app.OverlayDropDown.Items);
                        items = items(items ~= "(no overlays yet)");
                        if ~any(items == overlayName)
                            items(end+1) = overlayName;
                        end
                        app.OverlayDropDown.Items = cellstr(items);
                        app.OverlayDropDown.Value = overlayName;

                        % Switch to it (saves previous overlay state, restores this one)
                        app.switchOverlayByName(overlayName);

                        % Keep overlayPath synced to the ACTIVE overlay (optional)
                        idx = find(strcmp({app.overlayBank.name}, overlayName), 1);
                        if ~isempty(idx)
                            app.overlayPath = app.overlayBank(idx).path;
                        end

                    catch ME
                        uialert(app.UIFigure, ...
                            sprintf('Failed to add overlay "%s":\n%s', files{k}, ME.message), ...
                            'Overlay load error','Icon','warning');
                    end
                end

                figure(app.UIFigure);

                % ── 4) Update dropdown: filenames only, unique, growing list
                if ~isempty(newNames)
                    items = string(app.OverlayDropDown.Items);
                    items = items(items ~= "");   % strip empties/placeholders
                    for n = newNames'
                        if ~any(items == n)
                            items(end+1) = n;
                        end
                    end
                    app.OverlayDropDown.Items = cellstr(items);

                    % Select the last-added overlay and switch to it
                    selName = char(newNames(end));
                    app.OverlayDropDown.Value = selName;
                    app.switchOverlayByName(selName);      % saves current overlay state, loads selected

                    % ── 5) OPTIONAL path fix: keep overlayPath synced to the ACTIVE overlay
                    idx = find(strcmp({app.overlayBank.name}, selName), 1);
                    if ~isempty(idx)
                        app.overlayPath = app.overlayBank(idx).path;
                    end
                end

                d.Value   = 1.0;
                d.Message = 'Done.';
                pause(0.05);  % brief so "Done" is visible

            catch ME
                if ~isempty(d) && isvalid(d)
                    close(d);
                end
                rethrow(ME);
            end

            if ~isempty(d) && isvalid(d)
                close(d);
            end

        end

        % Button pushed function: LoadBaseMaskButton
        function LoadBaseMaskButtonPushed(app, event)

            % Need a base image first
            if isempty(app.dataImage) || isempty(app.niftiInfoBase)
                uialert(app.UIFigure, ...
                    'Load a base image before loading base mask.', ...
                    'No base image','Icon','warning');
                return
            end

            % Start where base was loaded (or last path)
            if isprop(app,'basePath') && ~isempty(app.basePath)
                startDir = fileparts(app.basePath);
            elseif isprop(app,'path') && ~isempty(app.path)
                startDir = app.path;
            else
                startDir = pwd;
            end

            [file, path] = uigetfile({'*.nii;*.nii.gz'}, 'Select base mask (3D)', startDir);
            if isequal(file,0), return; end
            maskFile = fullfile(path,file);

            d = [];
            try
                %--------------------------------------------------------------
                % Progress dialog
                %--------------------------------------------------------------
                d = uiprogressdlg(app.UIFigure, ...
                    'Title',        'Loading base mask', ...
                    'Message',      'Reading mask header...', ...
                    'Indeterminate','off', ...
                    'Cancelable',   'off', ...
                    'Value',        0.05);

                % Read mask (info + data)
                d.Value   = 0.15;
                d.Message = 'Reading mask volume...';

                mInfo = niftiinfo(maskFile);
                mVol  = niftiread(mInfo);

                % If 4D by accident, take first volume
                if ndims(mVol) == 4
                    mVol = mVol(:,:,:,1);
                end

                d.Value   = 0.35;
                d.Message = 'Checking geometry...';

                baseVol  = app.dataImage(:,:,:,1);       % single frame for geometry
                baseInfo = app.niftiInfoBase;
                baseSize = size(baseVol);

                if isequal(size(mVol), baseSize)
                    % Dimensions already match — just binarize
                    WB        = double(mVol > 0);
                    resampled = false;
                else
                    % --- Resample mask to base grid with nearest neighbor
                    d.Value   = 0.55;
                    d.Message = 'Resampling mask into base space...';

                    aligned = overlay_to_base(baseVol, baseInfo, mVol, mInfo, 'nearest');
                    aligned(~isfinite(aligned)) = 0;
                    WB        = double(aligned > 0.5);
                    resampled = true;
                end

                d.Value   = 0.75;
                d.Message = 'Applying mask to viewer...';

                app.WBmask = WB;

                % Show the "apply base mask" control (switch or checkbox)
                app.ApplyBaseMaskSwitch.Visible   = 'on';
                app.FlipMapSwitch_2Label_2.Visible = 'on';

                % ---- Keep OverlayMask in sync by default (or ask if overlay loaded)
                if isempty(app.OverlayMask)
                    % No overlay mask yet → just copy base mask
                    app.OverlayMask = app.WBmask;
                else
                    if isprop(app,'overlay') && app.overlay
                        % Ask user, but center dialog over the UIFigure (i.e. over viewer)
                        choice = uiconfirm(app.UIFigure, ...
                            'An overlay is loaded. Use the NEW BASE mask as the OVERLAY mask too?', ...
                            'Sync overlay mask?', ...
                            'Options',       {'Yes','No'}, ...
                            'DefaultOption', 'Yes', ...
                            'CancelOption',  'No', ...
                            'Icon',          'question');

                        if strcmp(choice,'Yes')
                            app.OverlayMask = app.WBmask;
                        end
                    end
                end

                app.updateVisualization();

                % Final progress + console info instead of popup
                d.Value   = 1.0;
                d.Message = 'Done.';
                pause(0.05);   % tiny pause so "Done" is visible

                if resampled
                    disp(sprintf('Mask was resampled from %s to base size %s using nearest-neighbor.', ...
                        mat2str(size(mVol)), mat2str(baseSize)));
                else
                    disp('Brain mask loaded successfully.');
                end

            catch ME
                if ~isempty(d) && isvalid(d)
                    close(d);
                end
                uialert(app.UIFigure, sprintf('Could not load mask:\n%s', ME.message), ...
                    'Load mask error','Icon','warning');
                return
            end

            if ~isempty(d) && isvalid(d)
                close(d);
            end

            figure(app.UIFigure);

        end

        % Button pushed function: LoadOverlayMaskButton
        function LoadOverlayMaskButtonPushed(app, event)

            % start folder like base
            if isprop(app,'basePath') && ~isempty(app.basePath)
                startDir = fileparts(app.basePath);
            elseif isprop(app,'path') && ~isempty(app.path)
                startDir = app.path;
            else
                startDir = pwd;
            end

            [file, path] = uigetfile({'*.nii;*.nii.gz'}, 'Select overlay mask (3D)', startDir);
            if isequal(file,0), return; end
            maskFile = fullfile(path,file);

            d = [];
            try
                %--------------------------------------------------------------
                % Progress dialog
                %--------------------------------------------------------------
                d = uiprogressdlg(app.UIFigure, ...
                    'Title',        'Loading overlay mask', ...
                    'Message',      'Reading mask header...', ...
                    'Indeterminate','off', ...
                    'Cancelable',   'off', ...
                    'Value',        0.05);

                % Read mask
                d.Value   = 0.15;
                d.Message = 'Reading mask volume...';

                mInfo = niftiinfo(maskFile);
                mVol  = niftiread(mInfo);

                % If 4D, take first volume
                if ndims(mVol) == 4
                    mVol = mVol(:,:,:,1);
                end

                d.Value   = 0.35;
                d.Message = 'Checking geometry...';

                % overlay is aligned to base
                baseVol  = app.dataImage(:,:,:,1);
                baseInfo = app.niftiInfoBase;
                baseSize = size(baseVol);

                if isequal(size(mVol), baseSize)
                    OM        = double(mVol > 0);
                    resampled = false;
                else
                    % --- Resample mask to base grid
                    d.Value   = 0.55;
                    d.Message = 'Resampling mask into base space...';

                    aligned = overlay_to_base(baseVol, baseInfo, mVol, mInfo, 'nearest');
                    aligned(~isfinite(aligned)) = 0;
                    OM        = double(aligned > 0.5);
                    resampled = true;
                end

                d.Value   = 0.75;
                d.Message = 'Applying mask to viewer...';

                app.OverlayMask = OM;
                app.ApplyOverlayMaskSwitch.Visible      = 'on';
                app.ApplyOverlayMaskSwitchLabel.Visible = "on";

                app.updateVisualization();

                % Final progress + console info instead of popup
                d.Value   = 1.0;
                d.Message = 'Done.';
                pause(0.05);   % tiny pause so "Done" is visible

                if resampled
                    disp(sprintf('Overlay mask was resampled from %s to base size %s using nearest-neighbor.', ...
                        mat2str(size(mVol)), mat2str(baseSize)));
                else
                    disp('Overlay mask loaded successfully.');
                end

            catch ME
                if ~isempty(d) && isvalid(d)
                    close(d);
                end
                uialert(app.UIFigure, sprintf('Could not load mask:\n%s', ME.message), ...
                    'Load mask error','Icon','warning');
                return
            end

            if ~isempty(d) && isvalid(d)
                close(d);
            end

            figure(app.UIFigure);
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            % Commit final range atomically and clamp to limits
            v   = event.Value;
            lim = app.ThresholdSlider.Limits;
            v   = [max(lim(1), min(v(1), lim(2))), max(lim(1), min(v(2), lim(2)))];
            if v(2) < v(1), v = sort(v); end
            app.ThresholdSlider.Value = v;

            % Sync numeric readouts (display rounding only)
            app.Base_low.Value  = round(v(1), 2);
            app.Base_high.Value = round(v(2), 2);

            app.updateVisualization();
        end

        % Value changed function: ThresholdSliderOverlay
        function ThresholdSliderOverlayValueChanged(app, event)
            % Commit final range atomically and clamp to limits
            v   = event.Value;
            lim = app.ThresholdSliderOverlay.Limits;
            v   = [max(lim(1), min(v(1), lim(2))), max(lim(1), min(v(2), lim(2)))];
            if v(2) < v(1), v = sort(v); end
            app.ThresholdSliderOverlay.Value = v;

            % Sync numeric readouts (display rounding only)
            app.Overlay_low.Value  = round(v(1), 2);
            app.Overlay_high.Value = round(v(2), 2);

            app.updateVisualization();
        end

        % Size changed function: VisualizationPanel
        function VisualizationPanelSizeChanged(app, event)
            app.viewer = viewer3d('Parent', app.VisualizationPanel);
        end

        % Value changed function: TerritoryDropDown
        function TerritoryDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: RenderingDropDown
        function RenderingDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            switch app.BackgroundDropDown.Value
                case 'Dark'          % keep dark with subtle vignette
                    bgColor   = [0 0 0];
                    gradColor = [0.5 0.5 0.5];     % vignette stays
                case 'Light'         % light grey with vignette
                    bgColor   = [0.90 0.90 0.90];
                    gradColor = [0.50 0.50 0.50];
                case 'Black'         % pure black, NO gradient
                    bgColor   = [0 0 0];
                    gradColor = bgColor;           % same ⇒ no gradient
                case 'White'         % pure white, NO gradient
                    bgColor   = [1 1 1];
                    gradColor = bgColor;
            end

            app.viewer.BackgroundColor = bgColor;
            app.viewer.GradientColor   = gradColor;
        end

        % Value changed function: ErodeBaseImageCheckBox
        function ErodeBaseImageCheckBoxValueChanged(app, event)
            value = app.ErodeBaseImageCheckBox.Value
            if value
                app.ErosionStrengthSpinner.Visible = 'on';
                app.ErosionStrengthSpinnerLabel.Visible = 'on';
            else
                app.ErosionStrengthSpinner.Visible = 'off';
                app.ErosionStrengthSpinnerLabel.Visible = 'off';
            end

            if value
                app.ProtectionThresholdSpinner.Visible = 'on';
                app.ProtectionThresholdSpinnerLabel.Visible = 'on';
            else
                app.ProtectionThresholdSpinner.Visible = 'off';
                app.ProtectionThresholdSpinnerLabel.Visible = 'on';
            end
            app.updateVisualization();
        end

        % Value changed function: ErosionStrengthSpinner
        function ErosionStrengthSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ProtectionThresholdSpinner
        function ProtectionThresholdSpinnerValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: RenderingDropDownOverlay
        function RenderingDropDownOverlayValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changing function: ThresholdSliderOverlay
        function ThresholdSliderOverlayValueChanging(app, event)
            app.updateVisualization();
        end

        % Value changed function: ORI1Slider
        function ORI1SliderValueChanged(app, event)
            app.updateVisualization();
            app.ORI1_low.Value = round(app.ORI1Slider.Value(1));
            app.ORI1_high.Value = round(app.ORI1Slider.Value(2));
        end

        % Value changed function: ORI2Slider
        function ORI2SliderValueChanged(app, event)
            app.updateVisualization();
            app.ORI2_low.Value = round(app.ORI2Slider.Value(1));
            app.ORI2_high.Value = round(app.ORI2Slider.Value(2));
        end

        % Value changed function: ORI3Slider
        function ORI3SliderValueChanged(app, event)
            app.updateVisualization();
            app.ORI3_low.Value = round(app.ORI3Slider.Value(1));
            app.ORI3_high.Value = round(app.ORI3Slider.Value(2));
        end

        % Value changed function: ORI1_low
        function ORI1_lowValueChanged(app, event)
            value = app.ORI1_low.Value;
            if value > app.ORI1_high.Value
                app.ORI1_low.Value = floor(app.ORI1_high.Value-1);
                app.ORI1Slider.Value(1) = app.ORI1_low.Value;
            else
                app.ORI1Slider.Value(1) = app.ORI1_low.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI2_low
        function ORI2_lowValueChanged(app, event)
            value = app.ORI2_low.Value;
            if value > app.ORI2_high.Value
                app.ORI2_low.Value = floor(app.ORI2_high.Value-1);
                app.ORI2Slider.Value(1) = app.ORI2_low.Value;
            else
                app.ORI2Slider.Value(1) = app.ORI2_low.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI3_low
        function ORI3_lowValueChanged(app, event)
            value = app.ORI3_low.Value;
            if value > app.ORI3_high.Value
                app.ORI3_low.Value = floor(app.ORI3_high.Value-1);
                app.ORI3Slider.Value(1) = app.ORI3_low.Value;
            else
                app.ORI3Slider.Value(1) = app.ORI3_low.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI1_high
        function ORI1_highValueChanged(app, event)
            value = app.ORI1_high.Value;
            if value < app.ORI1_low.Value
                app.ORI1_high.Value = floor(app.ORI1_low.Value+1);
                app.ORI1Slider.Value(2) = app.ORI1_high.Value;
            else
                app.ORI1Slider.Value(2) = app.ORI1_high.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI2_high
        function ORI2_highValueChanged(app, event)
            value = app.ORI2_high.Value;
            if value < app.ORI2_low.Value
                app.ORI2_high.Value = floor(app.ORI2_low.Value+1);
                app.ORI2Slider.Value(2) = app.ORI2_high.Value;
            else
                app.ORI2Slider.Value(2) = app.ORI2_high.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: ORI3_high
        function ORI3_highValueChanged(app, event)
            value = app.ORI3_high.Value;
            if value < app.ORI3_low.Value
                app.ORI3_high.Value = floor(app.ORI3_low.Value+1);
                app.ORI3Slider.Value(2) = app.ORI3_high.Value;
            else
                app.ORI3Slider.Value(2) = app.ORI3_high.Value;
            end
            app.updateVisualization();
        end

        % Value changed function: Base_low
        function Base_lowValueChanged(app, event)
            value = app.Base_low.Value;
            app.ThresholdSlider.Value(1) = value;
            app.updateVisualization();
        end

        % Value changed function: Base_high
        function Base_highValueChanged(app, event)
            value = app.Base_high.Value;
            app.ThresholdSlider.Value(2) = value;
            app.updateVisualization();
        end

        % Value changed function: Overlay_low
        function Overlay_lowValueChanged(app, event)
            value = app.Overlay_low.Value;
            app.ThresholdSliderOverlay.Value(1) = value;
            app.updateVisualization();
        end

        % Value changed function: Overlay_high
        function Overlay_highValueChanged(app, event)
            value = app.Overlay_high.Value;
            app.ThresholdSliderOverlay.Value(2) = value;
            app.updateVisualization();
        end

        % Button pushed function: ResetSlidersButton
        function ResetSlidersButtonPushed(app, event)
            app.ORI1Slider.Value(1) = app.ORI1Slider.Limits(1); app.ORI1Slider.Value(2) = app.ORI1Slider.Limits(2);
            app.ORI2Slider.Value(1) = app.ORI2Slider.Limits(1); app.ORI2Slider.Value(2) = app.ORI2Slider.Limits(2);
            app.ORI3Slider.Value(1) = app.ORI3Slider.Limits(1); app.ORI3Slider.Value(2) = app.ORI3Slider.Limits(2);

            app.ORI1_low.Value = app.ORI1Slider.Value(1); app.ORI1_high.Value = app.ORI1Slider.Value(2);
            app.ORI2_low.Value = app.ORI2Slider.Value(1); app.ORI2_high.Value = app.ORI2Slider.Value(2);
            app.ORI3_low.Value = app.ORI3Slider.Value(1); app.ORI3_high.Value = app.ORI3Slider.Value(2);

            app.updateVisualization();

        end

        % Value changed function: ApplySliceMaskDropDown
        function ApplySliceMaskDropDownValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: TrimBaseCheckBox
        function TrimBaseCheckBoxValueChanged(app, event)
            value = app.TrimBaseCheckBox.Value;

            % get the *original* 3-D frame
            origFrame = app.sourceDataImage;
            if ndims(origFrame)==4
                origFrame = origFrame(:,:,:,app.currentBaseFrame);
            end

            if value          % -------- TRIM ON --------
                app.PctLowEditField_Base.Visible  = "on";
                app.PctHighEditField_Base.Visible = "on";
                app.PctHighBase.Visible           = "on";
                app.PctLowBase.Visible            = "on";

                % apply trim to the working base volume
                app.dataImage = clipOutliers(origFrame, ...
                    app.PctLowEditField_Base.Value, app.PctHighEditField_Base.Value);

            else              % -------- TRIM OFF -------
                app.PctLowEditField_Base.Visible  = "off";
                app.PctHighEditField_Base.Visible = "off";
                app.PctHighBase.Visible           = "off";
                app.PctLowBase.Visible            = "off";

                % restore untrimmed data
                app.dataImage = origFrame;
            end

            % refresh histogram & view
            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);
            app.updateVisualization();
        end

        % Value changed function: TrimOverlayCheckBox
        function TrimOverlayCheckBoxValueChanged(app, event)
            value = app.TrimOverlayCheckBox.Value;

            if value          % -------- TRIM ON --------
                app.PctLowEditField_Overlay.Visible = "on";
                app.PctHighEditField_Overlay.Visible = "on";
                app.PctHighOverlay.Visible = "on";
                app.PctLowOverlay.Visible = "on";

                % apply trim to the working overlay
                app.overlayImage = clipOutliers(app.sourceOverlayImage, ...
                    app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);

            else              % -------- TRIM OFF -------
                app.PctLowEditField_Overlay.Visible = "off";
                app.PctHighEditField_Overlay.Visible = "off";
                app.PctHighOverlay.Visible = "off";
                app.PctLowOverlay.Visible = "off";

                % restore untrimmed overlay
                app.overlayImage = app.sourceOverlayImage;
            end

            [app.overlayHistCounts, app.overlayHistEdges] = computeHistogram(app, app.overlayImage);
            app.updateVisualization();
        end

        % Value changed function: PctLowEditField_Base
        function PctLowEditField_BaseValueChanged(app, event)
            if ~app.TrimBaseCheckBox.Value,  return;  end

            origFrame = app.sourceDataImage;
            if ndims(origFrame)==4
                origFrame = origFrame(:,:,:,app.currentBaseFrame);
            end

            app.dataImage = clipOutliers(origFrame, ...
                app.PctLowEditField_Base.Value, app.PctHighEditField_Base.Value);

            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);
            app.updateVisualization();
        end

        % Value changed function: PctHighEditField_Base
        function PctHighEditField_BaseValueChanged(app, event)
            if ~app.TrimBaseCheckBox.Value,  return;  end

            origFrame = app.sourceDataImage;
            if ndims(origFrame)==4
                origFrame = origFrame(:,:,:,app.currentBaseFrame);
            end

            app.dataImage = clipOutliers(origFrame, ...
                app.PctLowEditField_Base.Value, app.PctHighEditField_Base.Value);

            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);
            app.updateVisualization();
        end

        % Value changed function: PctLowEditField_Overlay
        function PctLowEditField_OverlayValueChanged(app, event)
            if ~app.TrimOverlayCheckBox.Value,  return;  end

            app.overlayImage = clipOutliers(app.sourceOverlayImage, ...
                app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);

            [app.overlayHistCounts, app.overlayHistEdges] = computeHistogram(app, app.overlayImage);
            app.updateVisualization();
        end

        % Value changed function: PctHighEditField_Overlay
        function PctHighEditField_OverlayValueChanged(app, event)
            if ~app.TrimOverlayCheckBox.Value,  return;  end

            app.overlayImage = clipOutliers(app.sourceOverlayImage, ...
                app.PctLowEditField_Overlay.Value, app.PctHighEditField_Overlay.Value);

            [app.overlayHistCounts, app.overlayHistEdges] = computeHistogram(app, app.overlayImage);
            app.updateVisualization();
        end

        % Button pushed function: SaveStateButton
        function SaveStateButtonPushed(app, event)

            [saveFile,saveDir] = uiputfile( ...
                'viewer_state.mat', ...
                'Save viewer state as');       % pops up a dialog
            if isequal(saveFile,0),  return; end   % user hit <Cancel>

            stateFile = fullfile(saveDir,saveFile);

            app.saveViewerState(stateFile);

        end

        % Button pushed function: LoadStateButton
        function LoadStateButtonPushed(app, event)
            app.loadViewerState();
            figure(app.UIFigure);
        end

        % Button pushed function: ExportMovieButton
        function ExportMovieButtonPushed(app, event)
            app.makeBaseMovieHiRes();
            figure(app.UIFigure);
        end

        % Button pushed function: ExportFrameButton
        function ExportFrameButtonPushed(app, event)
            % ── 1) Where to save (dark dialog, same style as movie/figure) ───────────
            if isprop(app,'basePath') && ~isempty(app.basePath)
                try
                    defaultDir = fileparts(app.basePath);
                    if isempty(defaultDir) || ~isfolder(defaultDir), defaultDir = pwd; end
                catch
                    defaultDir = pwd;
                end
            else
                defaultDir = pwd;
            end
            defaultName = 'frame.png';

            dlg = uifigure('Name','Export Frame','Color','k', ...
                'WindowStyle','modal','Resize','off');

            dlg.Position(3:4) = [720 180];

            % --- NEW: place dialog centered over app.UIFigure and bring to front ---
            try
                mainPos = app.UIFigure.Position;     % [x y w h]
                dlgPos  = dlg.Position;              % [x y w h]
                dlg.Position(1) = mainPos(1) + (mainPos(3) - dlgPos(3))/2;
                dlg.Position(2) = mainPos(2) + (mainPos(4) - dlgPos(4))/2;
            catch
                % if anything goes wrong, keep MATLAB default placement
            end

            drawnow;
            figure(dlg);  % bring dialog to front

            % Ensure closing the window acts like Cancel (no delete here!)
            dlg.CloseRequestFcn = @(h,~)onCancel();

            gl = uigridlayout(dlg,[4 6]);
            gl.RowHeight   = {36, 36, 12, 46};
            gl.ColumnWidth = {90, '1x', 90, 110, 100, 100};
            gl.BackgroundColor = [0 0 0];

            % Path row
            uilabel(gl,'Text','Save to','FontColor','w','HorizontalAlignment','right');
            pathField = uieditfield(gl,'text','Value',defaultDir);
            pathField.Layout.Column = [2 5];
            pathField.BackgroundColor = [0 0 0];
            pathField.FontColor       = [1 1 1];

            browseBtn = uibutton(gl,'Text','Browse', ...
                'ButtonPushedFcn',@(b,~)browseFolder());
            browseBtn.BackgroundColor = [0.85 0.45 0.05];  % orange
            browseBtn.FontColor       = [1 1 1];

            % Filename row
            uilabel(gl,'Text','Filename','FontColor','w','HorizontalAlignment','right');
            nameField = uieditfield(gl,'text','Value',defaultName);
            nameField.Layout.Column = [2 3];
            nameField.BackgroundColor = [0 0 0];
            nameField.FontColor       = [1 1 1];

            uilabel(gl,'Text','.png','FontColor',[.9 .9 .9]); % visual hint
            uilabel(gl,'Text','','FontColor','w'); % spacer
            uilabel(gl,'Text','','FontColor','w'); % spacer

            % Buttons
            cancelBtn = uibutton(gl,'Text','Cancel', ...
                'ButtonPushedFcn',@(b,~)onCancel());
            cancelBtn.Layout.Row = 4; cancelBtn.Layout.Column = 5;
            cancelBtn.BackgroundColor = [0.70 0.15 0.15];  % red
            cancelBtn.FontColor       = [1 1 1];

            saveBtn = uibutton(gl,'Text','Save', ...
                'ButtonPushedFcn',@(b,~)onSave());
            saveBtn.Layout.Row = 4; saveBtn.Layout.Column = 6;
            saveBtn.BackgroundColor = [0.20 0.60 0.20]; % green
            saveBtn.FontColor       = [1 1 1];

            function browseFolder()

                if isvalid(dlg)
                    drawnow;
                    figure(dlg);
                end

                d = uigetdir(pathField.Value,'Select folder to save frame');

                if isvalid(dlg)
                    drawnow;
                    figure(dlg);
                    try
                        uistack(dlg,'top');
                    catch
                    end
                end

                if isequal(d,0), return; end
                pathField.Value = char(d);
            end

            function onCancel()
                dlg.UserData = 'cancel';
                uiresume(dlg);
            end

            function onSave()
                dlg.UserData = 'save';
                uiresume(dlg);
            end

            % Wait for user action
            uiwait(dlg);
            action = [];
            if isvalid(dlg)
                action = dlg.UserData;
            end
            if isempty(action) || strcmp(action,'cancel')
                if isvalid(dlg), delete(dlg); end
                return;
            end

            % Read values then close dialog
            saveDir  = strtrim(pathField.Value);
            fileName = strtrim(nameField.Value);
            if isvalid(dlg), delete(dlg); end

            if ~isfolder(saveDir)
                uialert(app.UIFigure,'Selected folder does not exist.','Export Frame','Icon','warning');
                return;
            end

            [~, baseName, ext] = fileparts(fileName);
            if isempty(ext) || ~strcmpi(ext,'.png')
                ext = '.png';
            end
            fileName = [baseName ext];

            pngFile = fullfile(saveDir,fileName);
            if exist(pngFile,'file'), delete(pngFile); end

            % ── 2) Grab live handles (single source of truth) ────────────────────────
            liveV = app.viewer;
            H     = app.volHandle;

            % ── 3) Build a temporary viewer and clone scene props ────────────────────
            tmpFig  = uifigure('Color', liveV.BackgroundColor, 'Visible', 'on');
            tmpFig.Name = 'Snapshot (temporary)';
            tmpView = viewer3d('Parent', tmpFig);

            % Copy common scene props only if present on this release
            sceneProps = {'BackgroundColor','GradientColor','RenderingQuality','ScaleBar'};
            for k = 1:numel(sceneProps)
                p = sceneProps{k};
                if isprop(liveV,p) && isprop(tmpView,p)
                    tmpView.(p) = liveV.(p);
                end
            end

            % Apply Lighting and Denoising
            app.applyAppearanceToViewer(tmpView);

            % Prefer high quality for export; mirror extra denoising knobs if available
            if isprop(tmpView,'RenderingQuality'), tmpView.RenderingQuality = "high"; end

            % ── 4) Clone the exact volshow properties (WYSIWYG) ──────────────────────
            % Build args WITHOUT duplicating 'Data' (passed positionally)
            baseArgs = { ...
                'Parent',         tmpView, ...
                'Colormap',       H.Colormap, ...
                'Alphamap',       H.Alphamap, ...      % alphamap always present
                'Transformation', H.Transformation, ...
                'RenderingStyle', H.RenderingStyle};

            % Overlay: copy 1:1 if actively used (Data present)
            hasOverlay = app.overlay && isprop(H,'OverlayData') && ~isempty(H.OverlayData);
            if hasOverlay
                baseArgs = [baseArgs, ...
                    {'OverlayData',           H.OverlayData, ...
                    'OverlayColormap',       H.OverlayColormap, ...
                    'OverlayAlphamap',       H.OverlayAlphamap, ...
                    'OverlayRenderingStyle', H.OverlayRenderingStyle}];
                % NOTE: OverlayDisplayRangeMode handled *after* volshow via app.version guard
            end

            % Create the cloned volume in temp viewer
            tmpH = volshow(H.Data, baseArgs{:});
            % ── 5) Re-apply live camera AFTER volshow and lock modes ─────────────────
            % Guard each prop (some releases don’t expose all of them)
            if isprop(liveV,'Projection')     && isprop(tmpView,'Projection'),      tmpView.Projection      = liveV.Projection;      end
            if isprop(liveV,'CameraPosition') && isprop(tmpView,'CameraPosition'),  tmpView.CameraPosition  = liveV.CameraPosition;  end
            if isprop(liveV,'CameraTarget')   && isprop(tmpView,'CameraTarget'),    tmpView.CameraTarget    = liveV.CameraTarget;    end
            if isprop(liveV,'CameraUpVector') && isprop(tmpView,'CameraUpVector'),  tmpView.CameraUpVector  = liveV.CameraUpVector;  end
            if isprop(liveV,'CameraViewAngle')&& isprop(tmpView,'CameraViewAngle'), tmpView.CameraViewAngle = liveV.CameraViewAngle; end
            if isprop(liveV,'CameraZoom')     && isprop(tmpView,'CameraZoom'),      tmpView.CameraZoom      = liveV.CameraZoom;      end

            if isprop(tmpView,'CameraPositionMode'),  tmpView.CameraPositionMode  = 'manual'; end
            if isprop(tmpView,'CameraTargetMode'),    tmpView.CameraTargetMode    = 'manual'; end
            if isprop(tmpView,'CameraUpVectorMode'),  tmpView.CameraUpVectorMode  = 'manual'; end
            if isprop(tmpView,'CameraViewAngleMode'), tmpView.CameraViewAngleMode = 'manual'; end
            if isprop(tmpView,'CameraZoomMode'),      tmpView.CameraZoomMode      = 'manual'; end

            %drawnow; pause(0.05);  % ensure render complete

            % Overlay display-range default behavior changed in newer releases.
            % Use your app.version flag (true => R2024a+ compatible overlay API).
            if hasOverlay
                if app.version && isprop(tmpH,'OverlayDisplayRangeMode')
                    % Prefer live setting if present; else default to 'data-range' on new API
                    if isprop(H,'OverlayDisplayRangeMode') && ~isempty(H.OverlayDisplayRangeMode)
                        tmpH.OverlayDisplayRangeMode = H.OverlayDisplayRangeMode;
                    else
                        tmpH.OverlayDisplayRangeMode = 'data-range';
                    end
                end
                % For older releases (app.version == false), no action: legacy implicit behavior.
            end

            % ── 6) Snapshot to PNG ───────────────────────────────────────────────────
            pixelScale = 1;                 % upscale the captured bitmap
            rgb   = getframe(tmpFig).cdata;
            %rgbHi = imresize(rgb, pixelScale);
            imwrite(rgb, pngFile, 'png');

            % ── 7) Export stand-alone colorbar PNGs using *live* colormaps ───────────
            [savePath, baseName, ~] = fileparts(pngFile);
            scaleCB = pixelScale;

            cbPNG = fullfile(savePath, sprintf('%s_colorbar.png', baseName));
            writeColorbarPNG(H.Colormap, cbPNG, scaleCB);

            if hasOverlay && isprop(H,'OverlayColormap') && ~isempty(H.OverlayColormap)
                cbOvPNG = fullfile(savePath, sprintf('%s_overlay_colorbar.png', baseName));
                writeColorbarPNG(H.OverlayColormap, cbOvPNG, scaleCB);
            end

            % Persist viewer state (updated to save <basename>_state.mat next to PNG)
            stateFile = fullfile(savePath, sprintf('%s_state.mat', baseName));
            app.saveViewerState(stateFile);

            delete(tmpFig);
            drawnow;
            figure(app.UIFigure);
            drawnow;

            % ── helper: write a pure vertical gradient (lowest value at bottom) ─────
            function writeColorbarPNG(cmap, outFile, scaleFactor)
                n     = size(cmap,1);
                width = 24;
                cbImg = reshape(cmap,[n 1 3]);
                cbImg = repmat(cbImg,[1 width 1]);
                cbImg = flipud(cbImg);
                cbHi  = imresize(cbImg, scaleFactor, 'nearest');
                if exist(outFile,'file'), delete(outFile); end
                imwrite(cbHi, outFile, 'png');
            end
        end

        % Button pushed function: GenerateFigureButton
        function GenerateFigureButtonPushed(app, event)
            if isempty(app.dataImage)
                uialert(app.UIFigure,'Load a base image first.','Nothing to plot','Icon','warning');
                return
            end
            app.openQuickPlotDialog();   % modal dialog with live params

            figure(app.UIFigure);
        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            s   = event.Value;
            lim = app.BaseSlider.Limits;
            s   = [max(lim(1), min(s(1), lim(2))), max(lim(1), min(s(2), lim(2)))];
            if s(2) < s(1), s = sort(s); end
            app.BaseSlider.Value = s;

            rng  = app.BaseSlider.UserData;
            vals = slider2val(app, s, rng);
            app.BaseLowVal.Value  = round(vals(1), 2);
            app.BaseHighVal.Value = round(vals(2), 2);

            app.updateVisualization();   % NO colormap work here
            app.updateBaseAlphaMap();
        end

        % Value changed function: BaseHighVal
        function BaseHighValValueChanged(app, event)
            rng  = app.BaseSlider.UserData;
            low  = app.BaseLowVal.Value;
            high = app.BaseHighVal.Value;

            low  = max(rng(1), min(low,  high - eps));
            high = min(rng(2), max(high, low  + eps));

            app.BaseLowVal.Value  = round(low,  2);
            app.BaseHighVal.Value = round(high, 2);
            app.BaseSlider.Value  = val2slider(app, [low high], rng);

            app.updateVisualization();   % NO colormap work here
            app.updateBaseAlphaMap();    % re-assert alpha mode
        end

        % Value changed function: BaseLowVal
        function BaseLowValValueChanged(app, event)
            rng  = app.BaseSlider.UserData;
            low  = app.BaseLowVal.Value;
            high = app.BaseHighVal.Value;

            low  = max(rng(1), min(low,  high - eps));
            high = min(rng(2), max(high, low  + eps));

            app.BaseLowVal.Value  = round(low,  2);
            app.BaseHighVal.Value = round(high, 2);
            app.BaseSlider.Value  = val2slider(app, [low high], rng);

            app.updateVisualization();   % NO colormap work here
            app.updateBaseAlphaMap();    % re-assert alpha mode
        end

        % Value changed function: OverlaySlider
        function OverlaySliderValueChanged(app, event)
            % Commit final normalized range atomically
            s   = event.Value;                  % normalized [0..1]
            lim = app.OverlaySlider.Limits;     % usually [0 1]
            s   = [max(lim(1), min(s(1), lim(2))), max(lim(1), min(s(2), lim(2)))];
            if s(2) < s(1), s = sort(s); end
            app.OverlaySlider.Value = s;

            % Map to real values and sync numeric readouts
            rng  = app.OverlaySlider.UserData;  % [trueMin trueMax]
            vals = slider2val(app, s, rng);
            app.OverlayLowVal.Value  = round(vals(1), 2);
            app.OverlayHighVal.Value = round(vals(2), 2);

            % Heavy path: recompute normalized overlay volume + masks
            app.updateVisualization();

            % Light path: re-assert overlay alpha (flat vs contrast-shaped)
            app.updateOverlayAlphaMap();
        end

        % Value changed function: OverlayLowVal
        function OverlayLowValValueChanged(app, event)
            rng  = app.OverlaySlider.UserData;
            low  = app.OverlayLowVal.Value;
            high = app.OverlayHighVal.Value;

            % Sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high - eps));
            high = min(rng(2), max(high, low  + eps));

            app.OverlayLowVal.Value  = round(low,  2);   % (may have been clipped)
            app.OverlayHighVal.Value = round(high, 2);

            % Sync slider from numeric boxes
            app.OverlaySlider.Value = val2slider(app, [low high], rng);

            % Heavy path: recompute overlay normalization + masks
            app.updateVisualization();

            % Light path: re-assert overlay alpha
            app.updateOverlayAlphaMap();
        end

        % Value changed function: OverlayHighVal
        function OverlayHighValValueChanged(app, event)
            rng  = app.OverlaySlider.UserData;
            low  = app.OverlayLowVal.Value;
            high = app.OverlayHighVal.Value;

            % Sanity – keep inside range and ordered
            low  = max(rng(1), min(low,  high - eps));
            high = min(rng(2), max(high, low  + eps));

            app.OverlayLowVal.Value  = round(low,  2);   % (may have been clipped)
            app.OverlayHighVal.Value = round(high, 2);

            % Sync slider from numeric boxes
            app.OverlaySlider.Value = val2slider(app, [low high], rng);

            % Heavy path: recompute overlay normalization + masks
            app.updateVisualization();

            % Light path: re-assert overlay alpha
            app.updateOverlayAlphaMap();
        end

        % Callback function: UIFigure, VolumeSlider
        function VolumeSliderValueChanged(app, event)
            app.currentBaseFrame = round(app.VolumeSlider.Value);
            app.VolumeVal.Value  = app.currentBaseFrame;
            % current raw frame
            origFrame = app.sourceDataImage;
            if ndims(origFrame)==4
                origFrame = origFrame(:,:,:,app.currentBaseFrame);
            end

            % apply trim if active
            if app.TrimBaseCheckBox.Value
                app.dataImage = clipOutliers(origFrame, ...
                    app.PctLowEditField_Base.Value , ...
                    app.PctHighEditField_Base.Value);
            else
                app.dataImage = origFrame;
            end

            [app.baseHistCounts, app.baseHistEdges] = computeHistogram(app, app.dataImage);
            app.updateVisualization();
        end

        % Value changed function: VolumeVal
        function VolumeValValueChanged(app, event)
            value = app.VolumeVal.Value;
        end

        % Value changed function: FlipMapSwitch
        function FlipMapSwitchValueChanged(app, event)
            app.applyBaseColormap();
        end

        % Value changed function: LightingSwitch
        function LightingSwitchValueChanged(app, event)
            app.viewer.Lighting = app.LightingSwitch.Value;

            if strcmp(app.LightingSwitch.Value,'on')
                app.openLightingDialog();
            else
                app.closeLightingDialog();
            end
        end

        % Value changed function: DenoisingSwitch
        function DenoisingSwitchValueChanged(app, event)
            app.viewer.Denoising = app.DenoisingSwitch.Value;

            if strcmp(app.DenoisingSwitch.Value,'on')
                app.openDenoiseDialog();
            else
                app.closeDenoiseDialog();
            end
        end

        % Value changed function: FlipMapSwitchOverlay
        function FlipMapSwitchOverlayValueChanged(app, event)
            app.applyOverlayColormap();
        end

        % Value changed function: BaseAlphaSpinner
        function BaseAlphaSpinnerValueChanged(app, event)
            app.updateBaseAlphaMap();
        end

        % Value changed function: OverlayAlphaSpinner
        function OverlayAlphaSpinnerValueChanged(app, event)
            app.updateOverlayAlphaMap();
        end

        % Value changed function: BaseContrastSpinner
        function BaseContrastSpinnerValueChanged(app, event)
            app.applyBaseColormap();
            app.updateBaseAlphaMap();   % respects FlattenAlphaSwitch (flat vs shaped)
        end

        % Value changed function: ApplyBaseMaskSwitch
        function ApplyBaseMaskSwitchValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: ApplyOverlayMaskSwitch
        function ApplyOverlayMaskSwitchValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: FlattenBaseSwitch
        function FlattenBaseSwitchValueChanged(app, event)
            app.updateBaseAlphaMap();
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.applyBaseColormap();
        end

        % Value changed function: ColormapDropDownOverlay
        function ColormapDropDownOverlayValueChanged(app, event)
            app.applyOverlayColormap();    % repaint overlay colorbar as well
        end

        % Value changed function: OverlayContrastSpinner
        function OverlayContrastSpinnerValueChanged(app, event)
            app.applyOverlayColormap();    % color warp
            app.updateOverlayAlphaMap();   % alpha shaping if FlattenOverlaySwitch = Off
        end

        % Value changed function: FlattenOverlaySwitch
        function FlattenOverlaySwitchValueChanged(app, event)
            app.updateOverlayAlphaMap();   % <- this is the correct one
        end

        % Value changed function: QualityDropDown
        function QualityDropDownValueChanged(app, event)
            value = app.QualityDropDown.Value;
            if any(strcmp(value, ["high","medium","low"]))
                app.viewer.RenderingQuality = value;
            else
                % Fallback: default to high if unexpected value
                app.viewer.RenderingQuality = "high";
            end
        end

        % Value changed function: FlipORI1Switch
        function FlipORI1SwitchValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: FlipORI3Switch
        function FlipORI3SwitchValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: FlipORI2Switch
        function FlipORI2SwitchValueChanged(app, event)
            app.updateVisualization();
        end

        % Value changed function: OverlayDropDown
        function OverlayDropDownValueChanged(app, event)
            val = app.OverlayDropDown.Value;
            if isempty(val) || (iscell(val) && isempty(val{1}))
                return; % nothing selected yet
            end
            if iscell(val), val = val{1}; end
            app.switchOverlayByName(char(val));
        end

        % Value changed function: InterpolationDropDown
        function InterpolationDropDownValueChanged(app, event)
            method = app.InterpolationDropDown.Value;
            if isempty(app.currentOverlayName) || isempty(app.overlayBank), return; end
            idx = find(strcmp({app.overlayBank.name}, app.currentOverlayName), 1);
            if isempty(idx), return; end

            % --- Preserve current UI state (including current *absolute* display range)
            keepCmap     = app.ColormapDropDownOverlay.Value;
            keepFlip     = app.FlipMapSwitchOverlay.Value;
            keepAlpha    = app.OverlayAlphaSpinner.Value;
            keepContrast = app.OverlayContrastSpinner.Value;
            keepTrimOn   = app.TrimOverlayCheckBox.Value;
            keepPlo      = app.PctLowEditField_Overlay.Value;
            keepPhi      = app.PctHighEditField_Overlay.Value;

            % 🔴 Capture current *absolute* color scaling before re-interp
            prevAbsRange = app.slider2val(app.OverlaySlider.Value, app.OverlaySlider.UserData);   % [absMin absMax]
            prevThrAbs   = app.ThresholdSliderOverlay.Value;                                       % [absMin absMax]

            try
                ov = app.overlayBank(idx);
                if isfield(ov,'interpMethod') && strcmp(ov.interpMethod, method), return; end

                % --- Re-interpolate from RAW (or disk fallback)
                movVol  = ov.rawData; movInfo = ov.rawInfo;
                if isempty(movVol) || isempty(movInfo)
                    movInfo = niftiinfo(ov.path);
                    movVol  = niftiread(movInfo);
                    if ndims(movVol)==4, movVol = movVol(:,:,:,1); end
                end
                baseVol  = app.dataImage(:,:,:,1);
                baseInfo = app.niftiInfoBase;

                aligned = overlay_to_base(baseVol, baseInfo, movVol, movInfo, method);
                aligned(~isfinite(aligned)) = NaN;
                if keepTrimOn
                    aTrim = clipOutliers(aligned, keepPlo, keepPhi);
                else
                    aTrim = aligned;
                end
                % --- Recompute statistics for the new data
                [hc, he] = app.computeHistogram(aTrim);
                vec = aTrim(:); vec = vec(isfinite(vec)); if isempty(vec), vec = 0; end
                oMinTrue = double(round(min(vec),2));
                oMaxTrue = double(round(max(vec),2));
                newUserData = [oMinTrue oMaxTrue];

                % --- Re-map the *previous absolute* display range to the new normalized slider
                denom = max(oMaxTrue - oMinTrue, eps);
                newSlider = [(prevAbsRange(1)-oMinTrue)/denom, (prevAbsRange(2)-oMinTrue)/denom];
                % Clamp and ensure order
                newSlider = max(0, min(1, newSlider));
                if newSlider(2) < newSlider(1), newSlider = sort(newSlider); end
                % If degenerate range, fall back
                if denom <= eps, newSlider = [0 1]; end

                % --- Keep threshold *absolute values*; clamp to new limits
                newThr = [ ...
                    max(oMinTrue, min(prevThrAbs(1), oMaxTrue)), ...
                    max(oMinTrue, min(prevThrAbs(2), oMaxTrue))];
                if newThr(2) < newThr(1), newThr = [oMinTrue oMaxTrue]; end

                % --- Write back to overlay entry (keep other UI as-is)
                ov.data           = aTrim;
                ov.source         = aligned;
                ov.histCounts     = hc;
                ov.histEdges      = he;
                ov.sliderUserData = newUserData;   % new true range
                ov.sliderValue    = newSlider;     % remapped normalized range
                ov.thrLimits      = newUserData;
                ov.thrValue       = newThr;
                ov.interpMethod   = method;

                ov.uiState.cmap     = keepCmap;
                ov.uiState.flip     = keepFlip;
                ov.uiState.alpha    = keepAlpha;
                ov.uiState.contrast = keepContrast;
                ov.uiState.trimOn   = keepTrimOn;
                ov.uiState.pctLow   = keepPlo;
                ov.uiState.pctHigh  = keepPhi;

                % Commit to bank and push to UI
                app.overlayBank(idx) = ov;

                % Reapply preserved UI selections before redrawing
                if any(string(app.ColormapDropDownOverlay.Items) == string(ov.uiState.cmap))
                    app.ColormapDropDownOverlay.Value = ov.uiState.cmap;
                end
                app.FlipMapSwitchOverlay.Value   = ov.uiState.flip;
                app.OverlayAlphaSpinner.Value    = ov.uiState.alpha;
                app.OverlayContrastSpinner.Value = ov.uiState.contrast;

                app.pushOverlayToUI(ov);  % updates sliders/thresholds and redraws

                % Keep numeric readouts in sync (if you have them)
                valsO = app.slider2val(ov.sliderValue, ov.sliderUserData);
                app.OverlayLowVal.Value  = round(valsO(1),2);
                app.OverlayHighVal.Value = round(valsO(2),2);
            catch ME
                uialert(app.UIFigure, sprintf('Failed to re-interpolate overlay:\n%s', ME.message), ...
                    'Interpolation error','Icon','warning');
                % Revert dropdown on failure
                if exist('ov','var') && isfield(ov,'interpMethod') && ~isempty(ov.interpMethod)
                    app.InterpolationDropDown.Value = ov.interpMethod;
                end
            end
        end

        % Button pushed function: ExportNIfTIButton
        function ExportNIfTIButtonPushed(app, event)
            % Save current 3D view of base and overlays to NIfTI.
            % Keeps qform/sform but fixes non-orthonormal direction cosines (ITK-SNAP error).

            try
                if ~isprop(app,'niftiInfoBase') || isempty(app.niftiInfoBase)
                    uialert(app.UIFigure,'No base NIfTI info found.','Save error'); return;
                end
                if ~isprop(app,'dataImage') || isempty(app.dataImage)
                    uialert(app.UIFigure,'No base image loaded.','Save error'); return;
                end

                [outDir,~,~] = fileparts(app.basePath);
                if isempty(outDir), outDir = pwd; end

                baseInfo = app.niftiInfoBase;
                V        = app.dataImage;

                % Match datatype
                if ~strcmp(class(V), baseInfo.Datatype)
                    V = cast(V, baseInfo.Datatype);
                end

                % Apply visibility mask if available
                visBase = true(size(V));
                if isprop(app,'BaseVisibilityMask') && ~isempty(app.BaseVisibilityMask)
                    visBase = logical(app.BaseVisibilityMask);
                end
                V(~visBase) = 0;
                V(isnan(V)) = 0;

                % Update shape
                % baseInfo.ImageSize = size(V);

                % Write base
                basePath = fullfile(outDir, 'base_image');
                niftiwrite(V, basePath, baseInfo, 'Compressed', true);

                % --- Overlays ---
                wroteOverlays = false;
                if isprop(app,'overlayBank') && ~isempty(app.overlayBank)
                    ovCount = 0;
                    for k = 1:numel(app.overlayBank)
                        if isempty(app.overlayBank(k).data), continue; end
                        Ov = app.overlayBank(k).data;
                        if ~isequal(size(Ov), size(V)), continue; end
                        infoO = baseInfo;
                        if ~strcmp(class(Ov), app.overlayInfo.Datatype)
                            infoO.Datatype = app.overlayInfo.Datatype;
                            Ov = cast(Ov,app.overlayInfo.Datatype);
                        end
                        infoO.MultiplicativeScaling = 1;
                        visOv = true(size(Ov));
                        if isprop(app,'OverlayVisibilityMask') && ~isempty(app.OverlayVisibilityMask)
                            visOv = logical(app.OverlayVisibilityMask);
                        end
                        Ov(~visOv) = 0; Ov(isnan(Ov)) = 0;
                        infoO.ImageSize = size(Ov);
                        ovCount = ovCount + 1;
                        ovPath = fullfile(outDir, sprintf('overlay_image_%d', ovCount));
                        niftiwrite(Ov, ovPath, infoO, 'Compressed', true);
                        wroteOverlays = true;
                    end
                end
                figure(app.UIFigure);

                % Done
                if wroteOverlays
                    uialert(app.UIFigure, ['Saved base and overlay NIfTIs to: ', outDir], 'Saved');
                else
                    uialert(app.UIFigure, ['Saved base NIfTI to: ', outDir], 'Saved');
                end

            catch ME
                uialert(app.UIFigure, sprintf('Export failed:\n%s', ME.message), 'Save error', 'Icon','error');
            end

            figure(app.UIFigure);

        end

        % Button pushed function: SpawnViewerButton
        function SpawnViewerButtonPushed(app, event)
            % Spawn a single extra viewer that mirrors the current main viewer
            % (base + overlay). Only ONE spawned viewer is allowed at a time.
            % If one is already open, show a message and do nothing.
            %
            % Camera is synced:
            %   - once on spawn
            %   - on demand via "Sync with main viewer" button (camera only)

            % --- 1) Check if a spawned viewer already exists -----------------------
            if ~isempty(app.spawnedViewerFigs)
                % Remove any invalid handles
                app.spawnedViewerFigs(~isvalid(app.spawnedViewerFigs)) = [];
            end

            % If there is still a valid spawned viewer, block new spawn
            if ~isempty(app.spawnedViewerFigs)
                uialert(app.UIFigure, ...
                    'A spawned viewer is already open. Please close it before opening a new one.', ...
                    'Spawned viewer exists', ...
                    'Icon','info');
                return;
            end

            % --- 2) Guard: need a main viewer & volume handle ----------------------
            if isempty(app.viewer) || isempty(app.volHandle) || ~isvalid(app.viewer)
                uialert(app.UIFigure,'No active viewer to spawn from.','No viewer','Icon','warning');
                return;
            end

            % Update main view to ensure we clone the latest state
            app.updateVisualization();
            app.applyBaseColormap();
            if app.overlay
                app.applyOverlayColormap();
            end
            app.updateBaseAlphaMap();
            if app.overlay
                app.updateOverlayAlphaMap();
            end

            liveV = app.viewer;
            H     = app.volHandle;

            % --- 3) Compute spawned window size/position from VisualizationPanel ---
            mainFigPos = app.UIFigure.Position;          % [x y w h]
            panelPos   = app.VisualizationPanel.Position; % [x y w h] in figure coords

            spawnW = panelPos(3);
            spawnH = panelPos(4) + 90;     % + space for buttons

            offsetX = 40;
            offsetY = 40;
            spawnX  = mainFigPos(1) + panelPos(1) + offsetX;
            spawnY  = mainFigPos(2) + panelPos(2) + offsetY;

            figColor = liveV.BackgroundColor;

            % --- 4) Create spawned window & layout ---------------------------------
            fig = uifigure('Name','seeVieweR – Comparison Viewer', ...
                'Color',figColor, ...
                'Visible','on');
            fig.Position = [spawnX spawnY spawnW spawnH];

            % Grid layout: row 1 = viewer, row 2 = two buttons
            grid = uigridlayout(fig,[3 2]);
            grid.RowHeight       = {'1x', 40, 40};
            grid.ColumnWidth     = {'1x','1x'};
            grid.BackgroundColor = figColor;

            % viewer3d directly in the grid (fills entire top row)
            v = viewer3d(grid);
            v.Layout.Row    = 1;
            v.Layout.Column = [1 2];

            % Export Frame button (row 2, col 1)
            expFrameBtn = uibutton(grid, 'push', ...
                'Text','Export Frame', ...
                'ButtonPushedFcn',@(src,evt)app.exportSpawnedFrame());
            expFrameBtn.Layout.Row    = 2;
            expFrameBtn.Layout.Column = 1;
            expFrameBtn.BackgroundColor = [0.5373 0.7765 0.9412]; % same style as main export
            expFrameBtn.FontColor       = [1 1 1];

            % Export Movie button (row 2, col 2)
            expMovieBtn = uibutton(grid, 'push', ...
                'Text','Export Movie', ...
                'ButtonPushedFcn',@(src,evt)app.exportSpawnedMovie());
            expMovieBtn.Layout.Row    = 2;
            expMovieBtn.Layout.Column = 2;
            expMovieBtn.BackgroundColor = [0.5373 0.7765 0.9412];
            expMovieBtn.FontColor       = [1 1 1];

            % Sync button (blue) bottom-left
            syncBtn = uibutton(grid, 'push', ...
                'Text','Sync with main viewer', ...
                'ButtonPushedFcn',@(src,evt)app.syncSpawnedViewerCamera());
            syncBtn.Layout.Row    = 3;
            syncBtn.Layout.Column = 1;
            syncBtn.BackgroundColor = [0.0 0.45 0.74];   % MATLAB blue
            syncBtn.FontColor       = [1 1 1];

            % Close button (red) bottom-right
            closeBtn = uibutton(grid, 'push', ...
                'Text','Close this viewer', ...
                'ButtonPushedFcn',@(src,evt)app.closeSpawnedViewer(fig));
            closeBtn.Layout.Row    = 3;
            closeBtn.Layout.Column = 2;
            closeBtn.BackgroundColor = [0.85 0.0 0.0];
            closeBtn.FontColor       = [1 1 1];

            % Ensure window close (X) also goes through cleanup
            fig.CloseRequestFcn = @(src,evt)app.closeSpawnedViewer(src);

            % --- 5) Copy scene properties from main viewer -------------------------
            sceneProps = {'BackgroundColor','GradientColor','RenderingQuality', ...
                'ScaleBar'};
            for k = 1:numel(sceneProps)
                p = sceneProps{k};
                if isprop(liveV,p) && isprop(v,p)
                    v.(p) = liveV.(p);
                end
            end

            %apply lighting and denoising settings
            app.applyAppearanceToViewer(v);

            % --- 6) Clone the current volume (base + overlay) ----------------------
            baseArgs = { ...
                'Parent',   v, ...
                'Colormap', H.Colormap, ...
                'Alphamap', H.Alphamap};

            if isprop(H,'Transformation')
                baseArgs = [baseArgs, {'Transformation', H.Transformation}];
            end
            if isprop(H,'RenderingStyle')
                baseArgs = [baseArgs, {'RenderingStyle', H.RenderingStyle}];
            end

            hasOverlay = app.overlay && isprop(H,'OverlayData') && ~isempty(H.OverlayData);
            if hasOverlay
                baseArgs = [baseArgs, ...
                    {'OverlayData',     H.OverlayData, ...
                    'OverlayColormap', H.OverlayColormap, ...
                    'OverlayAlphamap', H.OverlayAlphamap}];
                if isprop(H,'OverlayRenderingStyle')
                    baseArgs = [baseArgs, {'OverlayRenderingStyle', H.OverlayRenderingStyle}];
                end
            end

            vh = volshow(H.Data, baseArgs{:});

            if hasOverlay && isprop(vh,'OverlayDisplayRangeMode')
                if isprop(H,'OverlayDisplayRangeMode') && ~isempty(H.OverlayDisplayRangeMode)
                    vh.OverlayDisplayRangeMode = H.OverlayDisplayRangeMode;
                else
                    vh.OverlayDisplayRangeMode = 'data-range';
                end
            end

            % --- 7) Initial camera match to main viewer (camera only) --------------
            app.copyMainCameraToViewer(v);

            % --- 8) Bookkeeping: store as the ONE spawned viewer -------------------
            app.spawnedViewerFigs  = fig;
            app.spawnedViewers     = v;
            app.spawnedVolHandles  = {vh};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.LoadBaseButton = uibutton(app.UIFigure, 'push');
            app.LoadBaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBaseButtonPushed, true);
            app.LoadBaseButton.Tooltip = {'Load base image. If there is a mask present in the same folder it will also be loaded but it must contain ''*_brain_mask.nii*'' - the extension can also be .nii.gz. Note, base image can be 3D OR 4D. '};
            app.LoadBaseButton.Text = 'Load Base ';
            app.ThresholdSlider = uislider(app.UIFigure, 'range');
            app.ThresholdSlider.Limits = [1 2];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Visible = 'off';
            app.ThresholdSlider.Tooltip = {'The slider determines which data from the base image is made visible. Any values outside the high and low range are not visible. This slider has no effect on color scaling. '};
            app.ThresholdSlider.Value = [1 2];
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.SizeChangedFcn = createCallbackFcn(app, @VisualizationPanelSizeChanged, true);
            app.ColorbarPanel = uipanel(app.UIFigure);
            app.ColorbarPanel.Visible = 'off';
            app.ErodeBaseImageCheckBox = uicheckbox(app.UIFigure);
            app.ErodeBaseImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @ErodeBaseImageCheckBoxValueChanged, true);
            app.ErodeBaseImageCheckBox.Visible = 'off';
            app.ErodeBaseImageCheckBox.Tooltip = {'The base image can be eroded considering a protection threshold to preserve structures of specified sizes. This was intended for MRA data.'};
            app.ErodeBaseImageCheckBox.Text = 'Erode Base Image';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo3.png');
            app.LoadOverlayButton = uibutton(app.UIFigure, 'push');
            app.LoadOverlayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOverlayButtonPushed, true);
            app.LoadOverlayButton.Visible = 'off';
            app.LoadOverlayButton.Tooltip = {'Loads an overlay image. The overlay will be resampled and interpolated to the dimensions of the base image. Overlay can only be 3D.'};
            app.LoadOverlayButton.Text = 'Load Overlay ';
            app.ColorbarPanelOverlay = uipanel(app.UIFigure);
            app.ColorbarPanelOverlay.Visible = 'off';
            app.ThresholdSliderOverlay = uislider(app.UIFigure, 'range');
            app.ThresholdSliderOverlay.Limits = [1 2];
            app.ThresholdSliderOverlay.MajorTicks = [1 1.2 1.4 1.6 1.8 2];
            app.ThresholdSliderOverlay.MajorTickLabels = {'1', '1.2', '1.4', '1.6', '1.8', '2'};
            app.ThresholdSliderOverlay.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderOverlayValueChanged, true);
            app.ThresholdSliderOverlay.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderOverlayValueChanging, true);
            app.ThresholdSliderOverlay.MinorTicks = [];
            app.ThresholdSliderOverlay.Visible = 'off';
            app.ThresholdSliderOverlay.Tooltip = {'The slider determines which data from the base image is made visible. Any values outside the high and low range are not visible. This slider has no effect on color scaling. '};
            app.ThresholdSliderOverlay.Value = [1 2];
            app.BaseThresholdLabel = uilabel(app.UIFigure);
            app.BaseThresholdLabel.Visible = 'off';
            app.BaseThresholdLabel.Text = 'Base Threshold';
            app.BaseSlider = uislider(app.UIFigure, 'range');
            app.BaseSlider.Limits = [0 1];
            app.BaseSlider.MajorTicks = [];
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.BaseSlider.MinorTicks = [];
            app.BaseSlider.Visible = 'off';
            app.BaseSlider.Tooltip = {'This slider can be used to scale the colormap. The high and low slider values do not currently align with the histogram shown in the colorbar - this should only bse used as a reference.'};
            app.BaseSlider.Value = [0 1];
            app.OverlaySlider = uislider(app.UIFigure, 'range');
            app.OverlaySlider.Limits = [0 1];
            app.OverlaySlider.MajorTicks = [];
            app.OverlaySlider.ValueChangedFcn = createCallbackFcn(app, @OverlaySliderValueChanged, true);
            app.OverlaySlider.MinorTicks = [];
            app.OverlaySlider.Visible = 'off';
            app.OverlaySlider.Tooltip = {'This slider can be used to scale the colormap. The high and low slider values do not currently align with the histogram shown in the colorbar - this should only bse used as a reference.'};
            app.OverlaySlider.Value = [0 1];
            app.OverlayThresholdLabel = uilabel(app.UIFigure);
            app.OverlayThresholdLabel.Visible = 'off';
            app.OverlayThresholdLabel.Text = 'Overlay Threshold';
            app.ORI1_low = uieditfield(app.UIFigure, 'numeric');
            app.ORI1_low.ValueChangedFcn = createCallbackFcn(app, @ORI1_lowValueChanged, true);
            app.ORI2_low = uieditfield(app.UIFigure, 'numeric');
            app.ORI2_low.ValueChangedFcn = createCallbackFcn(app, @ORI2_lowValueChanged, true);
            app.ORI3_low = uieditfield(app.UIFigure, 'numeric');
            app.ORI3_low.ValueChangedFcn = createCallbackFcn(app, @ORI3_lowValueChanged, true);
            app.ORI1_high = uieditfield(app.UIFigure, 'numeric');
            app.ORI1_high.ValueChangedFcn = createCallbackFcn(app, @ORI1_highValueChanged, true);
            app.ORI2_high = uieditfield(app.UIFigure, 'numeric');
            app.ORI2_high.ValueChangedFcn = createCallbackFcn(app, @ORI2_highValueChanged, true);
            app.ORI3_high = uieditfield(app.UIFigure, 'numeric');
            app.ORI3_high.ValueChangedFcn = createCallbackFcn(app, @ORI3_highValueChanged, true);
            app.Base_low = uieditfield(app.UIFigure, 'numeric');
            app.Base_low.ValueChangedFcn = createCallbackFcn(app, @Base_lowValueChanged, true);
            app.Base_low.Visible = 'off';
            app.Overlay_low = uieditfield(app.UIFigure, 'numeric');
            app.Overlay_low.ValueChangedFcn = createCallbackFcn(app, @Overlay_lowValueChanged, true);
            app.Overlay_low.Visible = 'off';
            app.Base_high = uieditfield(app.UIFigure, 'numeric');
            app.Base_high.ValueChangedFcn = createCallbackFcn(app, @Base_highValueChanged, true);
            app.Base_high.Visible = 'off';
            app.Overlay_high = uieditfield(app.UIFigure, 'numeric');
            app.Overlay_high.ValueChangedFcn = createCallbackFcn(app, @Overlay_highValueChanged, true);
            app.Overlay_high.Visible = 'off';
            app.TrimBaseCheckBox = uicheckbox(app.UIFigure);
            app.TrimBaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimBaseCheckBoxValueChanged, true);
            app.TrimBaseCheckBox.Visible = 'off';
            app.TrimBaseCheckBox.Tooltip = {'This will remove the high and low percentile of data values as specified in the Pct Low and Pct High edit fields.'};
            app.TrimBaseCheckBox.Text = 'Trim Base';
            app.TrimOverlayCheckBox = uicheckbox(app.UIFigure);
            app.TrimOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimOverlayCheckBoxValueChanged, true);
            app.TrimOverlayCheckBox.Visible = 'off';
            app.TrimOverlayCheckBox.Tooltip = {'This will remove the high and low percentile of data values as specified in the Pct Low and Pct High edit fields.'};
            app.TrimOverlayCheckBox.Text = 'Trim Overlay';
            app.PctLowEditField_Base = uieditfield(app.UIFigure, 'numeric');
            app.PctLowEditField_Base.Limits = [0 100];
            app.PctLowEditField_Base.ValueChangedFcn = createCallbackFcn(app, @PctLowEditField_BaseValueChanged, true);
            app.PctLowEditField_Base.Visible = 'off';
            app.PctLowEditField_Base.Value = 0.01;
            app.PctLowEditField_Overlay = uieditfield(app.UIFigure, 'numeric');
            app.PctLowEditField_Overlay.Limits = [0 100];
            app.PctLowEditField_Overlay.ValueChangedFcn = createCallbackFcn(app, @PctLowEditField_OverlayValueChanged, true);
            app.PctLowEditField_Overlay.Visible = 'off';
            app.PctLowEditField_Overlay.Value = 0.01;
            app.PctHighBase = uilabel(app.UIFigure);
            app.PctHighBase.Visible = 'off';
            app.PctHighBase.Text = 'Pct High';
            app.PctHighEditField_Base = uieditfield(app.UIFigure, 'numeric');
            app.PctHighEditField_Base.Limits = [0 100];
            app.PctHighEditField_Base.ValueChangedFcn = createCallbackFcn(app, @PctHighEditField_BaseValueChanged, true);
            app.PctHighEditField_Base.Visible = 'off';
            app.PctHighEditField_Base.Value = 99.99;
            app.PctHighEditField_Overlay = uieditfield(app.UIFigure, 'numeric');
            app.PctHighEditField_Overlay.Limits = [0 100];
            app.PctHighEditField_Overlay.ValueChangedFcn = createCallbackFcn(app, @PctHighEditField_OverlayValueChanged, true);
            app.PctHighEditField_Overlay.Visible = 'off';
            app.PctHighEditField_Overlay.Value = 99.99;
            app.PctLowOverlay = uilabel(app.UIFigure);
            app.PctLowOverlay.Visible = 'off';
            app.PctLowOverlay.Text = 'Pct Low';
            app.PctLowBase = uilabel(app.UIFigure);
            app.PctLowBase.Visible = 'off';
            app.PctLowBase.Text = 'Pct Low';
            app.PctHighOverlay = uilabel(app.UIFigure);
            app.PctHighOverlay.Visible = 'off';
            app.PctHighOverlay.Text = 'Pct High';
            app.BaseHighVal = uieditfield(app.UIFigure, 'numeric');
            app.BaseHighVal.ValueChangedFcn = createCallbackFcn(app, @BaseHighValValueChanged, true);
            app.BaseHighVal.Visible = 'off';
            app.BaseLowVal = uieditfield(app.UIFigure, 'numeric');
            app.BaseLowVal.ValueChangedFcn = createCallbackFcn(app, @BaseLowValValueChanged, true);
            app.BaseLowVal.Visible = 'off';
            app.OverlayLowVal = uieditfield(app.UIFigure, 'numeric');
            app.OverlayLowVal.ValueChangedFcn = createCallbackFcn(app, @OverlayLowValValueChanged, true);
            app.OverlayLowVal.Visible = 'off';
            app.OverlayHighVal = uieditfield(app.UIFigure, 'numeric');
            app.OverlayHighVal.ValueChangedFcn = createCallbackFcn(app, @OverlayHighValValueChanged, true);
            app.OverlayHighVal.Visible = 'off';
            app.VolumeVal = uieditfield(app.UIFigure, 'numeric');
            app.VolumeVal.ValueChangedFcn = createCallbackFcn(app, @VolumeValValueChanged, true);
            app.VolumeVal.Editable = 'off';
            app.VolumeVal.Visible = 'off';
            app.ResetSlidersButton = uibutton(app.UIFigure, 'push');
            app.ResetSlidersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSlidersButtonPushed, true);
            app.ResetSlidersButton.Text = 'Reset Sliders';
            app.LoadBaseMaskButton = uibutton(app.UIFigure, 'push');
            app.LoadBaseMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBaseMaskButtonPushed, true);
            app.LoadBaseMaskButton.Visible = 'off';
            app.LoadBaseMaskButton.Tooltip = {'Load base image. If there is a mask present in the same folder it will also be loaded but it must contain ''*_brain_mask.nii*'' - the extension can also be .nii.gz. Note, base image can be 3D OR 4D. '};
            app.LoadBaseMaskButton.Text = 'Load Base Mask ';
            app.LoadOverlayMaskButton = uibutton(app.UIFigure, 'push');
            app.LoadOverlayMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOverlayMaskButtonPushed, true);
            app.LoadOverlayMaskButton.Visible = 'off';
            app.LoadOverlayMaskButton.Tooltip = {'Load base image. If there is a mask present in the same folder it will also be loaded but it must contain ''*_brain_mask.nii*'' - the extension can also be .nii.gz. Note, base image can be 3D OR 4D. '};
            app.LoadOverlayMaskButton.Text = 'Load Overlay Mask ';
            app.BaseRENDERINGOverlayPanel = uipanel(app.UIFigure);
            app.BaseRENDERINGOverlayPanel.Title = 'Base                         RENDERING                        Overlay';
            app.BackgroundDropDownLabel = uilabel(app.BaseRENDERINGOverlayPanel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.BaseRENDERINGOverlayPanel);
            app.BackgroundDropDown.Items = {'Black', 'Dark', 'Light', 'White'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Tooltip = {'Switch between predefined background presets.'};
            app.BackgroundDropDown.Value = 'Black';
            app.RenderingDropDownLabel = uilabel(app.BaseRENDERINGOverlayPanel);
            app.RenderingDropDownLabel.Text = 'Rendering';
            app.RenderingDropDown = uidropdown(app.BaseRENDERINGOverlayPanel);
            app.RenderingDropDown.Items = {'CinematicRendering', 'SlicePlanes', 'VolumeRendering', 'LightScattering', 'GradientOpacity', 'Isosurface', 'MaximumIntensityProjection', 'MinimumIntensityProjection'};
            app.RenderingDropDown.ValueChangedFcn = createCallbackFcn(app, @RenderingDropDownValueChanged, true);
            app.RenderingDropDown.Tooltip = {'good options are cinematic and light scattering'};
            app.RenderingDropDown.Value = 'CinematicRendering';
            app.RenderingDropDown_2Label = uilabel(app.BaseRENDERINGOverlayPanel);
            app.RenderingDropDown_2Label.Visible = 'off';
            app.RenderingDropDown_2Label.Text = 'Rendering';
            app.RenderingDropDownOverlay = uidropdown(app.BaseRENDERINGOverlayPanel);
            app.RenderingDropDownOverlay.Items = {'VolumeOverlay', 'GradientOverlay', 'LabelOverlay'};
            app.RenderingDropDownOverlay.ValueChangedFcn = createCallbackFcn(app, @RenderingDropDownOverlayValueChanged, true);
            app.RenderingDropDownOverlay.Visible = 'off';
            app.RenderingDropDownOverlay.Tooltip = {''};
            app.RenderingDropDownOverlay.Value = 'VolumeOverlay';
            app.QualityDropDownLabel = uilabel(app.BaseRENDERINGOverlayPanel);
            app.QualityDropDownLabel.Text = 'Quality';
            app.QualityDropDown = uidropdown(app.BaseRENDERINGOverlayPanel);
            app.QualityDropDown.Items = {'high', 'medium', 'low'};
            app.QualityDropDown.ValueChangedFcn = createCallbackFcn(app, @QualityDropDownValueChanged, true);
            app.QualityDropDown.Tooltip = {'Switch between predefined background presets.'};
            app.QualityDropDown.Value = 'high';
            app.FlipORI3Switch = uiswitch(app.BaseRENDERINGOverlayPanel, 'slider');
            app.FlipORI3Switch.Items = {'off', 'on'};
            app.FlipORI3Switch.ValueChangedFcn = createCallbackFcn(app, @FlipORI3SwitchValueChanged, true);
            app.FlipORI3Switch.Value = 'off';
            app.FlipORI2Switch = uiswitch(app.BaseRENDERINGOverlayPanel, 'slider');
            app.FlipORI2Switch.Items = {'off', 'on'};
            app.FlipORI2Switch.ValueChangedFcn = createCallbackFcn(app, @FlipORI2SwitchValueChanged, true);
            app.FlipORI2Switch.Value = 'off';
            app.FlipDimSwitchLabel = uilabel(app.BaseRENDERINGOverlayPanel);
            app.FlipDimSwitchLabel.Text = 'Flip Dim';
            app.FlipORI1Switch = uiswitch(app.BaseRENDERINGOverlayPanel, 'slider');
            app.FlipORI1Switch.Items = {'off', 'on'};
            app.FlipORI1Switch.ValueChangedFcn = createCallbackFcn(app, @FlipORI1SwitchValueChanged, true);
            app.FlipORI1Switch.Value = 'off';
            app.RenderingDropDown_2Label_2 = uilabel(app.BaseRENDERINGOverlayPanel);
            app.RenderingDropDown_2Label_2.Visible = 'off';
            app.RenderingDropDown_2Label_2.Text = 'Interpolation';
            app.InterpolationDropDown = uidropdown(app.BaseRENDERINGOverlayPanel);
            app.InterpolationDropDown.Items = {'nearest', 'linear', 'spline', 'cubic', 'makima'};
            app.InterpolationDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationDropDownValueChanged, true);
            app.InterpolationDropDown.Visible = 'off';
            app.InterpolationDropDown.Tooltip = {''};
            app.InterpolationDropDown.Value = 'nearest';
            app.BaseCOLORMAPALPHAOverlayPanel = uipanel(app.UIFigure);
            app.BaseCOLORMAPALPHAOverlayPanel.Title = 'Base                 COLORMAP & ALPHA                 Overlay';
            app.BaseMapLabel = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.BaseMapLabel.Text = 'Base Map';
            app.ColormapDropDown = uidropdown(app.BaseCOLORMAPALPHAOverlayPanel);
            app.ColormapDropDown.Items = {'RedYellowlBlue', 'RedYellowlGreen', 'BlueWhiteRed', 'YellowGreenBlue', 'RedGray', 'Spectral', 'Jet', 'Turbo', 'Bright', 'Whed', 'Devon', 'grayC', 'Bone', 'Sepia', 'Copper', 'Viridis', 'Inferno', 'Magma', 'Plasma', 'Thermal', 'Lajolla', 'Buda', 'Nuuk', 'Roma', 'Hawaii', 'Bukavu', 'Imola', 'Parula'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Inferno';
            app.FlipMapSwitchLabel = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.FlipMapSwitchLabel.Text = 'Flip Map';
            app.FlipMapSwitch = uiswitch(app.BaseCOLORMAPALPHAOverlayPanel, 'slider');
            app.FlipMapSwitch.Items = {'yes', 'no'};
            app.FlipMapSwitch.ValueChangedFcn = createCallbackFcn(app, @FlipMapSwitchValueChanged, true);
            app.FlipMapSwitch.Value = 'yes';
            app.FlipMapSwitch_2Label = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.FlipMapSwitch_2Label.Visible = 'off';
            app.FlipMapSwitch_2Label.Text = 'Flip Map';
            app.FlipMapSwitchOverlay = uiswitch(app.BaseCOLORMAPALPHAOverlayPanel, 'slider');
            app.FlipMapSwitchOverlay.Items = {'yes', 'no'};
            app.FlipMapSwitchOverlay.ValueChangedFcn = createCallbackFcn(app, @FlipMapSwitchOverlayValueChanged, true);
            app.FlipMapSwitchOverlay.Visible = 'off';
            app.FlipMapSwitchOverlay.Value = 'no';
            app.FlattenBaseSwitchLabel = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.FlattenBaseSwitchLabel.Text = 'Flatten Base';
            app.FlattenBaseSwitch = uiswitch(app.BaseCOLORMAPALPHAOverlayPanel, 'slider');
            app.FlattenBaseSwitch.Items = {'no', 'yes'};
            app.FlattenBaseSwitch.ValueChangedFcn = createCallbackFcn(app, @FlattenBaseSwitchValueChanged, true);
            app.FlattenBaseSwitch.Value = 'no';
            app.FlattenOverlaySwitchLabel = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.FlattenOverlaySwitchLabel.Visible = 'off';
            app.FlattenOverlaySwitchLabel.Text = 'Flatten Overlay';
            app.FlattenOverlaySwitch = uiswitch(app.BaseCOLORMAPALPHAOverlayPanel, 'slider');
            app.FlattenOverlaySwitch.Items = {'no', 'yes'};
            app.FlattenOverlaySwitch.ValueChangedFcn = createCallbackFcn(app, @FlattenOverlaySwitchValueChanged, true);
            app.FlattenOverlaySwitch.Visible = 'off';
            app.FlattenOverlaySwitch.Value = 'no';
            app.BaseAlphaSpinnerLabel = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.BaseAlphaSpinnerLabel.Text = 'Base Alpha';
            app.BaseAlphaSpinner = uispinner(app.BaseCOLORMAPALPHAOverlayPanel);
            app.BaseAlphaSpinner.Step = 0.1;
            app.BaseAlphaSpinner.Limits = [0 1];
            app.BaseAlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseAlphaSpinnerValueChanged, true);
            app.BaseAlphaSpinner.Tooltip = {'Sets the transparency of the base image.'};
            app.BaseAlphaSpinner.Value = 0.9;
            app.OverlayAlphaSpinnerLabel = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.OverlayAlphaSpinnerLabel.Visible = 'off';
            app.OverlayAlphaSpinnerLabel.Text = 'Overlay Alpha';
            app.OverlayAlphaSpinner = uispinner(app.BaseCOLORMAPALPHAOverlayPanel);
            app.OverlayAlphaSpinner.Step = 0.1;
            app.OverlayAlphaSpinner.Limits = [0 1];
            app.OverlayAlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @OverlayAlphaSpinnerValueChanged, true);
            app.OverlayAlphaSpinner.Visible = 'off';
            app.OverlayAlphaSpinner.Tooltip = {'Sets the transparency of the overlay image.'};
            app.OverlayAlphaSpinner.Value = 0.5;
            app.BaseContrastLabel = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.BaseContrastLabel.Text = 'Base Contrast';
            app.BaseContrastSpinner = uispinner(app.BaseCOLORMAPALPHAOverlayPanel);
            app.BaseContrastSpinner.Step = 0.2;
            app.BaseContrastSpinner.Limits = [0 3];
            app.BaseContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @BaseContrastSpinnerValueChanged, true);
            app.BaseContrastSpinner.Tooltip = {'Modifies contrast - lower values give less contrast'};
            app.BaseContrastSpinner.Value = 1;
            app.OverlayContrastSpinnerLabel = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.OverlayContrastSpinnerLabel.Visible = 'off';
            app.OverlayContrastSpinnerLabel.Text = 'Overlay Contrast';
            app.OverlayContrastSpinner = uispinner(app.BaseCOLORMAPALPHAOverlayPanel);
            app.OverlayContrastSpinner.Step = 0.2;
            app.OverlayContrastSpinner.Limits = [0 3];
            app.OverlayContrastSpinner.ValueChangedFcn = createCallbackFcn(app, @OverlayContrastSpinnerValueChanged, true);
            app.OverlayContrastSpinner.Visible = 'off';
            app.OverlayContrastSpinner.Tooltip = {'Modifies contrast - lower values give less contrast'};
            app.OverlayContrastSpinner.Value = 1;
            app.OverlayMapLabel = uilabel(app.BaseCOLORMAPALPHAOverlayPanel);
            app.OverlayMapLabel.Visible = 'off';
            app.OverlayMapLabel.Text = 'Overlay Map';
            app.ColormapDropDownOverlay = uidropdown(app.BaseCOLORMAPALPHAOverlayPanel);
            app.ColormapDropDownOverlay.Items = {'RedYellowlBlue', 'RedYellowlGreen', 'BlueWhiteRed', 'YellowGreenBlue', 'RedGray', 'Spectral', 'Jet', 'Turbo', 'Bright', 'Whed', 'Devon', 'grayC', 'Bone', 'Sepia', 'Copper', 'Viridis', 'Inferno', 'Magma', 'Plasma', 'Thermal', 'Lajolla', 'Buda', 'Nuuk', 'Roma', 'Hawaii', 'Bukavu', 'Imola', 'Parula'};
            app.ColormapDropDownOverlay.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownOverlayValueChanged, true);
            app.ColormapDropDownOverlay.Visible = 'off';
            app.ColormapDropDownOverlay.Value = 'Viridis';
            app.TerritoryDropDownLabel = uilabel(app.UIFigure);
            app.TerritoryDropDownLabel.Visible = 'off';
            app.TerritoryDropDownLabel.Text = 'Territory';
            app.TerritoryDropDown = uidropdown(app.UIFigure);
            app.TerritoryDropDown.Items = {'ALL', 'Anterior_L', 'Anterior_R', 'PCA', 'VB', 'Ventricles'};
            app.TerritoryDropDown.ValueChangedFcn = createCallbackFcn(app, @TerritoryDropDownValueChanged, true);
            app.TerritoryDropDown.Visible = 'off';
            app.TerritoryDropDown.Value = 'ALL';
            app.ErosionStrengthSpinnerLabel = uilabel(app.UIFigure);
            app.ErosionStrengthSpinnerLabel.Visible = 'off';
            app.ErosionStrengthSpinnerLabel.Text = 'Erosion Strength';
            app.ErosionStrengthSpinner = uispinner(app.UIFigure);
            app.ErosionStrengthSpinner.Step = 0.025;
            app.ErosionStrengthSpinner.Limits = [0.025 1];
            app.ErosionStrengthSpinner.ValueChangedFcn = createCallbackFcn(app, @ErosionStrengthSpinnerValueChanged, true);
            app.ErosionStrengthSpinner.Visible = 'off';
            app.ErosionStrengthSpinner.Tooltip = {'controls the amount of erosion - this can then be tuned with the protection threshold'};
            app.ErosionStrengthSpinner.Value = 0.1;
            app.ProtectionThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.ProtectionThresholdSpinnerLabel.Visible = 'off';
            app.ProtectionThresholdSpinnerLabel.Text = 'Protection Threshold';
            app.ProtectionThresholdSpinner = uispinner(app.UIFigure);
            app.ProtectionThresholdSpinner.Limits = [1 5];
            app.ProtectionThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ProtectionThresholdSpinnerValueChanged, true);
            app.ProtectionThresholdSpinner.Visible = 'off';
            app.ProtectionThresholdSpinner.Tooltip = {'Protective erosion will maintain vessels - larger value means more vessels'};
            app.ProtectionThresholdSpinner.Value = 1;
            app.ORI1SliderLabel = uilabel(app.UIFigure);
            app.ORI1SliderLabel.Visible = 'off';
            app.ORI1SliderLabel.Text = 'ORI1';
            app.ORI1Slider = uislider(app.UIFigure, 'range');
            app.ORI1Slider.Limits = [0 1];
            app.ORI1Slider.MajorTicks = [];
            app.ORI1Slider.ValueChangedFcn = createCallbackFcn(app, @ORI1SliderValueChanged, true);
            app.ORI1Slider.MinorTicks = [];
            app.ORI1Slider.Visible = 'off';
            app.ORI1Slider.Value = [0 1];
            app.ORI2SliderLabel = uilabel(app.UIFigure);
            app.ORI2SliderLabel.Visible = 'off';
            app.ORI2SliderLabel.Text = 'ORI2';
            app.ORI2Slider = uislider(app.UIFigure, 'range');
            app.ORI2Slider.Limits = [0 1];
            app.ORI2Slider.MajorTicks = [];
            app.ORI2Slider.ValueChangedFcn = createCallbackFcn(app, @ORI2SliderValueChanged, true);
            app.ORI2Slider.MinorTicks = [];
            app.ORI2Slider.Visible = 'off';
            app.ORI2Slider.Value = [0 1];
            app.ORI3SliderLabel = uilabel(app.UIFigure);
            app.ORI3SliderLabel.Visible = 'off';
            app.ORI3SliderLabel.Text = 'ORI3';
            app.ORI3Slider = uislider(app.UIFigure, 'range');
            app.ORI3Slider.Limits = [0 1];
            app.ORI3Slider.MajorTicks = [];
            app.ORI3Slider.ValueChangedFcn = createCallbackFcn(app, @ORI3SliderValueChanged, true);
            app.ORI3Slider.MinorTicks = [];
            app.ORI3Slider.Visible = 'off';
            app.ORI3Slider.Value = [0 1];
            app.ApplySliceMaskDropDownLabel = uilabel(app.UIFigure);
            app.ApplySliceMaskDropDownLabel.Text = 'Apply Slice Mask';
            app.ApplySliceMaskDropDown = uidropdown(app.UIFigure);
            app.ApplySliceMaskDropDown.Items = {'All'};
            app.ApplySliceMaskDropDown.ValueChangedFcn = createCallbackFcn(app, @ApplySliceMaskDropDownValueChanged, true);
            app.ApplySliceMaskDropDown.Tooltip = {'When loading a base and overlay image, the user can specify to which images the slice masks are applied. '};
            app.ApplySliceMaskDropDown.Value = 'All';
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Visible = 'off';
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.Limits = [1 2];
            app.VolumeSlider.MajorTicks = [1 2];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Visible = 'off';
            app.VolumeSlider.Value = 1;
            app.FlipMapSwitch_2Label_2 = uilabel(app.UIFigure);
            app.FlipMapSwitch_2Label_2.Visible = 'off';
            app.FlipMapSwitch_2Label_2.Text = 'Apply Base Mask';
            app.ApplyBaseMaskSwitch = uiswitch(app.UIFigure, 'slider');
            app.ApplyBaseMaskSwitch.ValueChangedFcn = createCallbackFcn(app, @ApplyBaseMaskSwitchValueChanged, true);
            app.ApplyBaseMaskSwitch.Visible = 'off';
            app.ApplyOverlayMaskSwitchLabel = uilabel(app.UIFigure);
            app.ApplyOverlayMaskSwitchLabel.Visible = 'off';
            app.ApplyOverlayMaskSwitchLabel.Text = 'Apply Overlay Mask';
            app.ApplyOverlayMaskSwitch = uiswitch(app.UIFigure, 'slider');
            app.ApplyOverlayMaskSwitch.ValueChangedFcn = createCallbackFcn(app, @ApplyOverlayMaskSwitchValueChanged, true);
            app.ApplyOverlayMaskSwitch.Visible = 'off';
            app.APPEARANCEPanel = uipanel(app.UIFigure);
            app.APPEARANCEPanel.Title = 'APPEARANCE';
            app.DenoisingSwitchLabel = uilabel(app.APPEARANCEPanel);
            app.DenoisingSwitchLabel.Text = 'Denoising';
            app.DenoisingSwitch = uiswitch(app.APPEARANCEPanel, 'slider');
            app.DenoisingSwitch.Items = {'off', 'on'};
            app.DenoisingSwitch.ValueChangedFcn = createCallbackFcn(app, @DenoisingSwitchValueChanged, true);
            app.DenoisingSwitch.Value = 'off';
            app.LightingSwitchLabel = uilabel(app.APPEARANCEPanel);
            app.LightingSwitchLabel.Text = 'Lighting';
            app.LightingSwitch = uiswitch(app.APPEARANCEPanel, 'slider');
            app.LightingSwitch.Items = {'off', 'on'};
            app.LightingSwitch.ValueChangedFcn = createCallbackFcn(app, @LightingSwitchValueChanged, true);
            app.LightingSwitch.Value = 'off';
            app.AddOverlayButton = uibutton(app.UIFigure, 'push');
            app.AddOverlayButton.ButtonPushedFcn = createCallbackFcn(app, @AddOverlayButtonPushed, true);
            app.AddOverlayButton.Visible = 'off';
            app.AddOverlayButton.Tooltip = {'Loads an overlay image. The overlay will be resampled and interpolated to the dimensions of the base image. Overlay can only be 3D.'};
            app.AddOverlayButton.Text = 'Add Overlay';
            app.OverlayDropDownLabel = uilabel(app.UIFigure);
            app.OverlayDropDownLabel.Text = 'Overlay';
            app.OverlayDropDown = uidropdown(app.UIFigure);
            app.OverlayDropDown.ValueChangedFcn = createCallbackFcn(app, @OverlayDropDownValueChanged, true);
            app.EXPORTPanel = uipanel(app.UIFigure);
            app.EXPORTPanel.Title = 'EXPORT';
            app.GenerateFigureButton = uibutton(app.EXPORTPanel, 'push');
            app.GenerateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFigureButtonPushed, true);
            app.GenerateFigureButton.Visible = 'off';
            app.GenerateFigureButton.Text = 'Generate Figure';
            app.ExportFrameButton = uibutton(app.EXPORTPanel, 'push');
            app.ExportFrameButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFrameButtonPushed, true);
            app.ExportFrameButton.Visible = 'off';
            app.ExportFrameButton.Text = 'Export Frame';
            app.ExportMovieButton = uibutton(app.EXPORTPanel, 'push');
            app.ExportMovieButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMovieButtonPushed, true);
            app.ExportMovieButton.Visible = 'off';
            app.ExportMovieButton.Text = 'Export Movie';
            app.LoadStateButton = uibutton(app.EXPORTPanel, 'push');
            app.LoadStateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStateButtonPushed, true);
            app.LoadStateButton.Visible = 'off';
            app.LoadStateButton.Text = 'Load State';
            app.SaveStateButton = uibutton(app.EXPORTPanel, 'push');
            app.SaveStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateButtonPushed, true);
            app.SaveStateButton.Visible = 'off';
            app.SaveStateButton.Text = 'Save State';
            app.ExportNIfTIButton = uibutton(app.EXPORTPanel, 'push');
            app.ExportNIfTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNIfTIButtonPushed, true);
            app.ExportNIfTIButton.Visible = 'off';
            app.ExportNIfTIButton.Tooltip = {'Exports nifti file as shown in viewer - including interpolation (if overlay) and masking'};
            app.ExportNIfTIButton.Text = 'Export NIfTI ';
            app.SpawnViewerButton = uibutton(app.UIFigure, 'push');
            app.SpawnViewerButton.ButtonPushedFcn = createCallbackFcn(app, @SpawnViewerButtonPushed, true);
            app.SpawnViewerButton.Visible = 'off';
            app.SpawnViewerButton.Tooltip = {'Load base image. If there is a mask present in the same folder it will also be loaded but it must contain ''*_brain_mask.nii*'' - the extension can also be .nii.gz. Note, base image can be 3D OR 4D. '};
            app.SpawnViewerButton.Text = 'Spawn Viewer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seeVieweR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end